classdef McMRSGUI_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadFilesMenu                   matlab.ui.container.Menu
        PreprocessingDataMenu           matlab.ui.container.Menu
        MatlabDataMenu                  matlab.ui.container.Menu
        VarianDataMenu                  matlab.ui.container.Menu
        TextDataMenu                    matlab.ui.container.Menu
        MultichannelCombinationMenu     matlab.ui.container.Menu
        XAxisControlsMenu               matlab.ui.container.Menu
        XAxisinMHzMenu                  matlab.ui.container.Menu
        XAxisinppmMenu                  matlab.ui.container.Menu
        Userdefined0ppmMenu             matlab.ui.container.Menu
        ppmcenteredMenu                 matlab.ui.container.Menu
        MathematicalOperationsMenu      matlab.ui.container.Menu
        FileSubtractionMenu             matlab.ui.container.Menu
        CombinationTypeChangeMenu       matlab.ui.container.Menu
        BatchProcessMenu                matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        SpectroscopyTab                 matlab.ui.container.Tab
        ExportClearDataPanel            matlab.ui.container.Panel
        ExportNoiseCovarianceMatrixWSVDButton  matlab.ui.control.Button
        ExportjMRUIDataButton           matlab.ui.control.Button
        ClearDataButton                 matlab.ui.control.Button
        ExportDataButton                matlab.ui.control.Button
        SpectralEvaluationPanel         matlab.ui.container.Panel
        XlimHighMHzEditField            matlab.ui.control.NumericEditField
        XlimHighMHzEditFieldLabel       matlab.ui.control.Label
        XlimLowMHzEditField             matlab.ui.control.NumericEditField
        XlimLowMHzEditFieldLabel        matlab.ui.control.Label
        NoiseRegionSelectionSNRPanel    matlab.ui.container.Panel
        Noise_Display                   matlab.ui.control.NumericEditField
        NoiseLabel                      matlab.ui.control.Label
        Signal_Display                  matlab.ui.control.NumericEditField
        SignalLabel                     matlab.ui.control.Label
        SignaltoNoiseRatio              matlab.ui.control.NumericEditField
        SignaltoNoiseRatioEditFieldLabel  matlab.ui.control.Label
        RightNoiseLimit                 matlab.ui.control.NumericEditField
        HighFrequencyNoiseLimitMHzLabel  matlab.ui.control.Label
        LeftNoiseLimit                  matlab.ui.control.NumericEditField
        LowFrequencyNoiseLimitMHzLabel  matlab.ui.control.Label
        Peak_degree                     matlab.ui.control.NumericEditField
        LinewidthHz_peak                matlab.ui.control.NumericEditField
        LinewidthHzLabel                matlab.ui.control.Label
        Check_Linewidth_checkbox        matlab.ui.control.CheckBox
        PeakphaseselectionButton        matlab.ui.control.Button
        PreprocessingPanel              matlab.ui.container.Panel
        ChannelSelection                matlab.ui.control.NumericEditField
        ChannelSelectionEditFieldLabel  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        First_order_slider_value        matlab.ui.control.NumericEditField
        Zero_order_slider_value         matlab.ui.control.NumericEditField
        Fig_slider_value                matlab.ui.control.NumericEditField
        Slider_3                        matlab.ui.control.Slider
        stOrderValuedegLabel            matlab.ui.control.Label
        Slider_2                        matlab.ui.control.Slider
        OrderValuedegLabel              matlab.ui.control.Label
        AcquisitionSelectionLabel       matlab.ui.control.Label
        SlideValueText                  matlab.ui.control.Label
        Slider_1                        matlab.ui.control.Slider
        OnlyControlsOneSpectraPanel     matlab.ui.container.Panel
        ViewFIDCheckBox                 matlab.ui.control.CheckBox
        Automatic0OrderPhaseCheckBox    matlab.ui.control.CheckBox
        ControlsAllLoadedSpectraPanel   matlab.ui.container.Panel
        CascadePlotCheckBox             matlab.ui.control.CheckBox
        BaselineCorrectionCheckBox      matlab.ui.control.CheckBox
        SetPhasingforAllCheckBox        matlab.ui.control.CheckBox
        ZeroAllPhasesCheckBox           matlab.ui.control.CheckBox
        ZeropaddingFactor               matlab.ui.control.NumericEditField
        ZeropaddingFactorEditFieldLabel  matlab.ui.control.Label
        LineBroadeningHz                matlab.ui.control.NumericEditField
        LineBroadeningHzEditFieldLabel  matlab.ui.control.Label
        NumberofAverages                matlab.ui.control.NumericEditField
        NumberofAveragesLabel           matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        
        % Spectroscopy Code Section
        Time_domain = [];
        Spectrum = [];
        Averaged_Time_domain = [];
        Time_domain_adjusted = 0;
        Matrix_Time_domain = [];
        Matrix_Spectrum = [];
        Time_domain_column_list = 0;
        Time_domain_row_list = 0;
        Columns = [];
        Rows_per_load = [];
        BW_per_load = [];
        BW = 0;
        F0_per_load = [];
        F0 = 0;
        A0 = [];
        A1 = [];
        Slider_0_order = 0;
        Slider_1st_order = 0;
        Faxis = 0;
        Boundary_zone = [];
        Bounds_corr = [];
        Boundary2 = [];
        
        Linewidth = [];
        SNR = 0;
        Noise = [];
        Peak_maxes = [];
        Peak_loc = [];
        
        % Controls All Loaded Spectra
        LineBroadening = 0;
        Zero_padding = 0;
        Acquisition_select = 1;
        Baseline_correction = 0;
        
        % Loading associated variables
        Prev_filenames = string([]);
        Prev_zeropad = 0;
        A0_initial = [];
        A1_initial = [];
        Filepath = string([]);
        Channels = [];
        Filename_spectroscopy = string([]);
        Channel_selections = [];
        Filepath_subtraction = string([]);
        Operations = string([]);
        
        % Viewing or viewing changes variables
        Avg_name = [];
        X_axis_state = 0;
        Columns1 = 0;
        Previous_Channel_select = 1;
        File_loc = [];
        Z = [];
        Zero_ppm = 0;
        Cascade_state = 0;
        Count = 0;
        PPM_axis = 0;
    end
    
    methods (Access = private)
        
        %% Loading in Varian Data Sets
        
        % Loads in the .fid folder and its parameters for imaging
        
        % Reads the procpar data and retrieves imaging parameters
        function procpar = readprocpar2(app)
            procdir = app.File_loc;
            
            % argument check
            [~,~,ext] = fileparts(procdir);
            if strcmp(ext,'.fid') ~= 1
                errordlg('Please choose a .fid file type','File Error');
            else
                
                %                 verbose = 0;
                
                if (nargin >= 1)
                    if (procdir(length(procdir)) ~= '/')
                        procdir(length(procdir)+1) = '/';
                    end
                end
                
                procpar = [];
                
                % open the procpar
                fprocpar = fopen([procdir 'procpar'],'r');
                if (fprocpar == -1)
                    fprintf('(readprocpar) ERROR: Could not open %s\n',[procdir 'procpar']);
                    return
                end
                
                % scan through procpar looking for parameters needed for parsing
                line = fgets(fprocpar);
                while (line ~= -1)
                    % get the first token of the line
                    token = strtok(line);
                    % if it is a character and not doublequote then we have an array name
                    if (~((token(1) >= '0') & (token(1) <= '9')) & (token(1) ~= '"'))
                        fieldname = token;
                        % get next line
                        line = fgets(fprocpar);
                        % first number is just length of array
                        [len line] = strtok(line);
                        len = str2num(len);
                        % just an array of numbers
                        if ((length(line) > 2) & (line(2) ~= '"'))
                            eval(sprintf('procpar.%s = str2num(line);',fieldname));
                            % confirm length
                            arraylen = eval(sprintf('length(procpar.%s)',fieldname));
                            if (arraylen ~= len)
                                sprintf('WARNING: %s should be %i elements, but found only %i',fieldname,len,arraylen);
                            end
                            % character arrays
                        else
                            for j = 1:len
                                % strip leading white space
                                while (length(line) & (line(1) == ' '))
                                    line = line(2:length(line));
                                end
                                token = line;
                                % make sure we have a valid string token
                                if (isempty(token) | (token(1) ~= '"'))
                                    sprintf('WARNING: %s missing string element %i/%i',fieldname,j,len);
                                    eval(sprintf('procpar.%s{%i} = '''';',fieldname,j));
                                    j = len;
                                else
                                    str = strtok(line,sprintf('"\n'));
                                    % convert single to double quotes
                                    str(findstr(str,'''')) = '"';
                                    eval(sprintf('procpar.%s{%i} = ''%s'';',fieldname,j,str));
                                end
                                line = fgets(fprocpar);
                            end
                        end
                    end
                    line = fgets(fprocpar);
                end
                
                fclose(fprocpar);
                
                % these fields will get set from the petable name if available
                if ~isfield(procpar,'accfactor'),procpar.accfactor = 1;end
                if ~isfield(procpar,'tSesnseAccfactor'),procpar.tSenseAccfactor = 1;end
                if ~isfield(procpar,'numshots'),procpar.numshots = 1;end
                
                % for epi images, there are navigator echos, which
                % should be subtracted from the number of lines.
                % this can be known from the name of the petable
                if (isfield(procpar,'petable'))
                    token = procpar.petable{1};
                    % the petable name should be something like
                    % "epi132alt8k". We want the second number
                    if (strncmp(token,'epi',3))
                        j = 1;
                        % go past any intial characters
                        while((j < length(token)) && (isempty(strfind('0123456789',token(j))))),j=j+1;
                        end
                        % then skip the numbers
                        while((j < length(token)) && (~isempty(strfind('0123456789',token(j))))),j=j+1;
                        end
                        % and skip the next characters
                        while((j < length(token)) && (isempty(strfind('0123456789',token(j))))),j=j+1;
                        end
                        % find end of numbers
                        k = j;
                        while((k < length(token)) && (~isempty(strfind('0123456789',token(k))))),k=k+1;
                        end
                        procpar.numshots = str2num(token(j:k-1));
                        % then skip some more numbers
                        while((j < length(token)) && (~isempty(strfind('0123456789',token(j))))),j=j+1;
                        end
                        % go past any other charcters (should be k)
                        while((j < length(token)) && (isempty(strfind('0123456789',token(j))))),j=j+1;
                        end
                        % and if we have anything left then get the number, that is the acceleration factor
                        k = j;
                        while((k < length(token)) && (~isempty(strfind('0123456789',token(k))))),k=k+1;
                        end
                        if j < length(token)
                            % r means that it is a sense petable
                            if token(j+1:k) == 'r'
                                procpar.accfactor = str2num(token(j:k-1));
                                % t means t-sense acc factor
                            elseif token(j+1:k) == 't'
                                procpar.tSenseAccfactor = str2num(token(j:k-1));
                            end
                        end
                        % before we always had 1 navecho for epi, but now there is a field procpar.navecho that should
                        % be set by Ken's prep program that reads the petable and gets the t3 field.
                        if ~isfield(procpar,'navecho'),procpar.navecho = 1;end
                    end
                end
                
                % the number of shots should now be read from the field nseg rather than from the petable
                if isfield(procpar,'nseg') procpar.numshots = procpar.nseg;end
                
                % if navecho is not set by now, then there were no navecho's
                if ~isfield(procpar,'navecho'),procpar.navecho = 0;end
                
                % compute the number of echoes
                procpar.navechoes = procpar.navecho*procpar.numshots/procpar.accfactor;
            end
        end
        
        % Loads in the .fid folder and its parameters for spectroscopy
        function [s_fid,params_fid] = loadfid_Spectroscopy(app)
            
            
            %% Description
            %Usage [RE,IM,np,nb] = loadfid(name);
            
            %Input:
            
            %name   = name of FID directory without the .fid extension
            
            %Output:
            
            %RE  = real part of data, blocks are ordered columnwise
            %IM  = imaginary part
            %np  = number of points (rows in RE/IM); optional
            %nb  = number of blocks (columns); optional
            %nt  = number of traces; optional
            
            %% Examples:
            
            %[RE IM] = loadfid('filename');
            %[RE IM np nb] = loadfid('filename');
            
            %----------------------------------------
            %       Xiaojun Chen Sept. 2007
            %----------------------------------------
            
            %% Format input name
            %             C = file_loc;
            fullname = sprintf('%s%cfid',app.File_loc,'/');
            fid = fopen(fullname,'r','ieee-be');
            % disp(fullname)
            % fullname=sprintf(name);
            % Compatibility with UNIX file system
            % You don't need this step if you are given just one fid file without any
            % extension
            % Put fullname to that file location with .fid extension
            fid = fopen(fullname,'r','ieee-be');
            if fid == -1
                str = sprintf('Can not open file %s',fullname);
                error(str);
            end
            
            %% Read data file header
            nblocks   = fread(fid,1,'int32'); %?
            ntraces   = fread(fid,1,'int32');
            np        = fread(fid,1,'int32'); %?
            ebytes    = fread(fid,1,'int32');
            tbytes    = fread(fid,1,'int32');
            bbytes    = fread(fid,1,'int32');
            vers_id   = fread(fid,1,'int16');
            status    = fread(fid,1,'int16');
            nbheaders = fread(fid,1,'int32');
            s_data    = bitget(status,1); %bitget function returns the value of the bit at position BIT in A bitget(A,BIT)
            s_spec    = bitget(status,2);
            s_32      = bitget(status,3);
            s_float   = bitget(status,4);
            s_complex = bitget(status,5);
            s_hyper   = bitget(status,6);
            
            %% Reset output structures
            % RE = [];
            % IM = [];
            RE=zeros(np/2,nblocks);
            IM=zeros(np/2,nblocks);
            
            if exist('blocks','var') == 0
                blocks = 1:nblocks;
            end
            outblocks   = max(size(blocks));
            
            if exist('traces','var') == 0
                traces = 1:ntraces;
            end
            outtraces   = max(size(traces));
            
            inx = 1;
            B   = 1;
            
            for b = 1:nblocks
                sprintf('read block %d\n',b);
                % Read a block header
                scale     = fread(fid,1,'int16');
                bstatus   = fread(fid,1,'int16');
                index     = fread(fid,1,'int16');
                mode      = fread(fid,1,'int16');
                ctcount   = fread(fid,1,'int32');
                lpval     = fread(fid,1,'float32');
                rpval     = fread(fid,1,'float32');
                lvl       = fread(fid,1,'float32');
                tlt       = fread(fid,1,'float32');
                T = 1;
                update_B = 0;
                for t = 1:ntraces
                    if s_float == 1
                        data = fread(fid,np,'float32');
                        str='reading floats';
                    elseif s_32 == 1
                        data = fread(fid,np,'int32');
                        str='reading 32bit';
                    else
                        data = fread(fid,np,'int16');
                        str='reading 16bit';
                    end
                    if (blocks(B) == b)
                        if T <= outtraces
                            if (traces(T) == t)
                                sprintf('Reading block %d, trace %d, inx = %d\n',b,T,inx);
                                % Read k-space data in real and imaginary part
                                RE(:,inx) = data(1:2:np);
                                IM(:,inx) = data(2:2:np);
                                inx = inx + 1;
                                T = T + 1;
                                update_B = 1;
                            end
                        end
                    end
                end
                if update_B, B = B + 1; end
                if B > outblocks
                    break
                end
            end
            %             if nargout > 2
            %                 NP = np/2;
            %             end
            %             if nargout > 3
            %                 NB = nblocks;
            %             end
            %             if nargout > 4
            %                 NT = ntraces;
            %             end
            
            fclose(fid);
            
            %
            % K-space data
            s = complex(RE,IM);
            % DC correction
            % 10/20/2007
            % corr=mean(mean(s(1:5,1:5)));
            
            
            corr=mean(mean(s(end-(1/5)*length(s):end,1))); % remove the DC offset
            s_fid = s - corr;
            
            [params_fid] = readprocpar2(app);
        end


        %% Spectroscopy Section
        
        % Main Function that performs preprocessing for spectroscopy
        function Main_spectroscopy_processing(app,loop,use_stored_phases)
            % Loop = a double of the number of times for the Main program to run. If there are 20 acquisitions, Loop == 20
            % use_stored_phases isn't explicitly used but the number of input arguments is used to determine if previously stored phases should be used
            % The remainder of the variables will be stored in the structure app
            
            if exist('loop','var') == 0 % If loop doesn't exist as an input
                Acquisition_selector = app.Acquisition_select;
                No_plot = 0;
            else
                Acquisition_selector = loop;
                No_plot = 1;
            end
            
            % Set constants for your configuration
            if app.NumberofAverages.Value ~= 1
                time_domain_1 = app.Averaged_Time_domain(Acquisition_selector,:);
            elseif app.Columns1 ~= -1
                time_domain_1 = app.Time_domain(Acquisition_selector,:);
            else
                time_domain_1 = app.Matrix_Time_domain{Acquisition_selector,1};
            end
            Complex_points = length(time_domain_1);
            at = Complex_points/(app.BW(Acquisition_selector));
            
            %% Zero-padding
            if app.Zero_padding > 0
                zero_length = length(time_domain_1)*app.Zero_padding;
                zero_vec = zeros(1,zero_length);
                time_domain_1 = horzcat(time_domain_1,zero_vec);
            else
            end
            
            %% Finds the length and sets the appropriate x-axis
            x_len = length(time_domain_1)/2;
            if app.PPM_axis == 0  % Case where axis will be shown in MHz
                app.Faxis = (linspace(-app.BW(Acquisition_selector)/2,app.BW(Acquisition_selector)/2,x_len*2)+app.F0(Acquisition_selector)*10^6)/(10^6);
            elseif app.PPM_axis == 1 % Case where axis will be shown in ppm
                app.Faxis = linspace(-app.BW(Acquisition_selector)/2,app.BW(Acquisition_selector)/2,x_len*2)/(app.F0(Acquisition_selector));
            else % case where it is user-defined 0 ppm
                app.Faxis = (linspace(-app.BW(Acquisition_selector)/2,app.BW(Acquisition_selector)/2,x_len*2)/(app.F0(Acquisition_selector)))-app.Zero_ppm;
            end
            
            Var_pts = x_len*2;
            
            %% Line Broadening -
            if app.LineBroadening ~= 0
                Lbpoint = pi*(app.LineBroadening);
                Lbaxis = (1:Var_pts)*(at/Var_pts);
                time_domain_1 = exp(-Lbpoint.*Lbaxis).*time_domain_1;
            else
            end
            
            % Performs FFT
            spectrum_1 = fftshift(fft(time_domain_1));
            
            %% Zero-order phase correction
            if app.Automatic0OrderPhaseCheckBox.Value == 1
                % Uses Fast MinMax Method to correct for phase
                Input1 = 0.99;
                Input2 = 100;
                Input3 = -1;
                [a,Xc] = MinMaxPhaseCorr(app,(spectrum_1),Input1,Input2,Input3);
                
                a0 = a(1)*180/pi;
                
                if a0 > 180 % Constrain the output of the automatic phase correction to between -180 and 180 degrees
                    a0 = 180;
                elseif a0 < -180
                    a0 = -180;
                else
                end
                
                a1 = a(2)*180/pi;
            elseif nargin == 3 % Uses the stored values for the 0-order and 1st-order phases
                a0 = app.A0(Acquisition_selector);
                a1 = app.A1(Acquisition_selector);
            else % Uses the slider and/or input box values.
                a0 = app.Slider_0_order;
                a1 = app.Slider_1st_order;
            end
            
            % Grab the value for the sliders
            app.A0(Acquisition_selector) = a0; % 0-order phase correction with any auto 0-order phase
            app.A1(Acquisition_selector) = a1; % 1st-order phase correction with any auto 1st-order phase
            
            % Apply the 0-order and 1st-order correction
            Axis_for_phasing = 0:1:length(app.Faxis)-1;
            Final_spectrum = spectrum_1.*exp(1j*((a1*pi/180)*Axis_for_phasing/length(app.Faxis)+(a0*pi/180)));
            
            %% Section for baseline correction - Xi and Roche
            if app.Baseline_correction ~= 0
                NM = length(Final_spectrum);
                
                step_size = 30;
                iter = floor(NM/step_size);
                for bin_size_iter = 1:iter
                    if bin_size_iter == 1
                        bin_section = Final_spectrum(1:step_size);
                    else
                        bin_section = Final_spectrum((bin_size_iter-1)*step_size:(bin_size_iter)*step_size);
                    end
                    MEAN(bin_size_iter) = mean(real(bin_section));
                    Variance(bin_size_iter) = var(real(bin_section));
                end
                
                Together = [MEAN;Variance];
                [~,idx] = sort(Together(1,:));
                sortedmat = Together(:,idx);
                P = smooth(sortedmat(1,:),sortedmat(2,:),0.3,'loess');
                [~,I] = min(abs(sortedmat(1,:)));
                vari = P(I);
                
                st_deviation = sqrt(vari);
                A_prime = (5e-9)*(NM^4);
                B_prime = 1.25;
                f = Baseline_xi(app,Final_spectrum',A_prime,B_prime,st_deviation);
                Final_spectrum = Final_spectrum-f.';
            else
            end
            
            
            % Find the maximum and minimum
            [maxvalue,~]=max(real(Final_spectrum));
            [minvalue,~]=min(real(Final_spectrum));
            
            % Calculate the standard deviation of the noise
            [~,closestIndex] = min(abs(app.LeftNoiseLimit.Value-app.Faxis));
            [~,closestIndex2] = min(abs(app.RightNoiseLimit.Value-app.Faxis));
            app.Noise(Acquisition_selector,1) = std(real(Final_spectrum(closestIndex:closestIndex2)));
            Low_freq_noise_boundary = app.Faxis(closestIndex);
            High_freq_noise_boundary = app.Faxis(closestIndex2);
            
            if No_plot == 0
                
                Flipped_spectrum = (real((Final_spectrum)));
                plot(app.UIAxes,(app.Faxis), Flipped_spectrum);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,app.Faxis(closestIndex),Flipped_spectrum(closestIndex),'r.','MarkerSize',12)
                plot(app.UIAxes,app.Faxis(closestIndex2),Flipped_spectrum(closestIndex2),'r.','MarkerSize',12)
                hold(app.UIAxes,'off');
                set (app.UIAxes, 'xdir', 'reverse' )
                if app.PPM_axis == 0
                    xlabel(app.UIAxes,'Frequency (MHz)');
                else
                    xlabel(app.UIAxes,'Frequency (ppm)');
                end
                ylabel(app.UIAxes,'Spectral Intensity (A.u.)');
                axis(app.UIAxes,"tight")
                
                xlim(app.UIAxes,[app.XlimLowMHzEditField.Value app.XlimHighMHzEditField.Value]);
                
                if minvalue == 0 && maxvalue == 0
                    ylim(app.UIAxes,[0, 1]);
                else
                    ylim(app.UIAxes,[minvalue - 0.05*maxvalue, maxvalue+0.05*maxvalue]);
                end
                
                if app.NumberofAverages.Value == 1
                    title(app.UIAxes,app.Filename_spectroscopy(Acquisition_selector,1),'Interpreter','none');
                else
                    title(app.UIAxes,app.Avg_name(Acquisition_selector,1),'Interpreter','none');
                end
                
            else
            end
            
            %%
            if (app.Check_Linewidth_checkbox.Value == 1 && app.Columns1 ~= -1) || (app.Check_Linewidth_checkbox.Value == 1 && app.Columns1 == -1 && app.Acquisition_select == Acquisition_selector)
                [Linewidth,Peak_indexes,Peak_maxes] = Linewidth_calculator(app,(real(Final_spectrum)));
                app.Peak_maxes(Acquisition_selector,:) = [Peak_maxes(1)];%,Peak_maxes(2)];
            else
                [peak_max,peak_pos] = max(real(Final_spectrum));
                [Linewidth,Peak_indexes,Peak_maxes] = Linewidth_calculator(app,(real(Final_spectrum)),peak_max,peak_pos);
                app.Peak_maxes(Acquisition_selector,1) = Peak_maxes;
            end
            
            if app.PPM_axis == 0 % case where x-axis is in MHz
                app.Linewidth(Acquisition_selector,:) = Linewidth;
                app.LinewidthHz_peak.Value = app.Linewidth(Acquisition_selector,:);
                app.Peak_loc(Acquisition_selector,:) = [app.Faxis(Peak_indexes(1))];
            else % case where x-axis is in ppm
                app.Linewidth(Acquisition_selector,:) = Linewidth*app.F0(Acquisition_selector)/(10^6); % Converts this so that it will always save the linewidth in Hz for exporting
                app.LinewidthHz_peak.Value = Linewidth/(10^6); % Displays linewidth in ppm on GUI
                app.Peak_loc(Acquisition_selector,:) = [(app.Faxis(Peak_indexes(1))*app.F0(Acquisition_selector)+app.F0(Acquisition_selector)*10^6)/10^6];
            end
            
            if isnan(app.Peak_maxes(Acquisition_selector,1)/(app.Noise(Acquisition_selector,1))) == 1
                SNR_ratio = 0;
            else
                SNR_ratio = app.Peak_maxes(Acquisition_selector,1)/(app.Noise(Acquisition_selector,1));
            end
            app.SignaltoNoiseRatio.Value = SNR_ratio;
            app.Signal_Display.Value = app.Peak_maxes(Acquisition_selector,1);
            
            if app.Columns1 == -1
                app.Matrix_Spectrum{Acquisition_selector,1} = Final_spectrum;
            else
                app.Spectrum(Acquisition_selector,:)=(Final_spectrum);
            end
            
            app.SNR(Acquisition_selector,1) = app.SignaltoNoiseRatio.Value;
            app.Time_domain_adjusted = ifft(ifftshift(Final_spectrum));
            app.Noise_Display.Value = (app.Noise(Acquisition_selector,1));
            Slider_limits = app.Slider_3.Limits;
            
            if Slider_limits(1) < a1 && Slider_limits(2) > a1
            else
                app.Slider_3.Limits = [-abs(a1) abs(a1)];
                app.First_order_slider_value.Limits = [-abs(a1) abs(a1)];
            end
        end
        
        % Responsible for plotting multiple averages and data on a 3D plot
        % or showing the FID
        function Waterfall(app,loop_amount,FID,Subtraction)
            % loop_amount = number of plots to be plotted on top of one another
            % FID = can be any input (1). The presence of FID will indicate
            % that a plot of a free induction decay should be shown on the app.UIAxes2 graph
            % Subtraction = (1 x Num_pts) vector for plotting

            [A,B] = size(app.Spectrum);
            left_bound = 1;
            right_bound = 1.5;
            %     Spacing = linspace(left_bound,right_bound,A);
            Spacing = 1:1:A;
            position_of_plots = ones(B,1).*Spacing;
            if nargin == 1 % Case for Plotting single noise normalized graph
                
                if app.Columns1 == -1
                    plot(app.UIAxes2, app.Faxis.',(real((app.Matrix_Spectrum{app.Acquisition_select}))/app.Noise(app.Acquisition_select,1)),'color', [0,0,0]+0,'LineWidth',0.1);
                    [maxvalue,~]=max(real((app.Matrix_Spectrum{app.Acquisition_select}))/app.Noise(app.Acquisition_select,1));
                    [minvalue,~]=min(real((app.Matrix_Spectrum{app.Acquisition_select}))/app.Noise(app.Acquisition_select,1));
                else
                    plot(app.UIAxes2, app.Faxis.',(real((app.Spectrum(app.Acquisition_select,:)))/app.Noise(app.Acquisition_select,1)),'color', [0,0,0]+0,'LineWidth',0.1);
                    [maxvalue,~]=max(real((app.Spectrum(app.Acquisition_select,:)))/app.Noise(app.Acquisition_select,1));
                    [minvalue,~]=min(real((app.Spectrum(app.Acquisition_select,:)))/app.Noise(app.Acquisition_select,1));
                end
                
                ylabel(app.UIAxes2,'Noise Normalized Spectral Intensity (A.u.)');
                title(app.UIAxes2,'')
                
                if app.PPM_axis == 0
                    xlabel(app.UIAxes2, 'Frequency (MHz)')
                else
                    xlabel(app.UIAxes2, 'Frequency (ppm)')
                end
                
                hold(app.UIAxes2,'off');
                set (app.UIAxes2, 'xdir', 'reverse' )
                view(app.UIAxes2,0,90)
                ylim(app.UIAxes2,'auto')
                xlim(app.UIAxes2,[app.XlimLowMHzEditField.Value app.XlimHighMHzEditField.Value]);
                app.Cascade_state = 0;
            elseif nargin == 2 % Case for Plotting all graphs on top of each other
                if app.Columns1 == -1
                    app.TextArea.Value = 'Data sets cannot be plotted together due to different vector lengths. The rightmost graph will not be able to plot all of the data sets together.';
                else
                    app.Z = [];
                    
                    for i = 1:loop_amount
                        app.Z(i) = plot3(app.UIAxes2,position_of_plots(:,i),app.Faxis.',(real((app.Spectrum(i,:))))/app.Noise(app.Acquisition_select,1),'color', [0,0,0]+0.3,'LineWidth',0.001);
                        hold(app.UIAxes2,'on');
                    end
                    
                    xlabel(app.UIAxes2,'');
                    zlabel(app.UIAxes2,'Spectral Intensity (A.u.)');
                    title(app.UIAxes2,'Cascade Plot')
                    
                    if app.PPM_axis == 0
                        ylabel(app.UIAxes2, 'Frequency (MHz)')
                    else
                        ylabel(app.UIAxes2, 'Frequency (ppm)')
                    end
                    
                    hold(app.UIAxes2,'off');
                    [maxvalue,~]=max(real((app.Spectrum(app.Acquisition_select,:)))/app.Noise(app.Acquisition_select,1));
                    [minvalue,~]=min(real((app.Spectrum(app.Acquisition_select,:)))/app.Noise(app.Acquisition_select,1));
                    
                    view(app.UIAxes2,272,15)
                    %                     ylim(app.UIAxes2,'auto')
                    ylim(app.UIAxes2,[app.XlimLowMHzEditField.Value app.XlimHighMHzEditField.Value]);
                    xlim(app.UIAxes2,'auto')
                    set (app.UIAxes2, 'xdir', 'normal' )
                end
                app.Cascade_state = 1;
            elseif nargin == 3 % Else, number of inputs = 3, Case where a FID is plotted
                Len = length(app.Time_domain_adjusted);
                at = Len/(app.BW(app.Acquisition_select));
                
                time_vec = 0:at/Len:at-at/Len;

                plot(app.UIAxes2,time_vec,real(app.Time_domain_adjusted),'o-')
                axis(app.UIAxes2,"tight")
                xlabel(app.UIAxes2,'Time (sec)')
                ylabel(app.UIAxes2,'Amplitude')
                set (app.UIAxes2, 'ydir', 'normal' )
                set (app.UIAxes2, 'xdir', 'normal' )
                title(app.UIAxes2,'Free Induction Decay')
                view(app.UIAxes2,0,90)
                ylim(app.UIAxes2,'auto')
                
                set(app.UIAxes2, 'XTickMode', 'auto', 'XTickLabelMode', 'auto')
                app.Cascade_state = 2;
            else % Case where subtraction is desired
                
                spectrum_1 = fftshift(fft(Subtraction));
                plot(app.UIAxes2,app.Faxis,(real(spectrum_1)))
                
                axis(app.UIAxes2,"tight")
                
                if app.PPM_axis == 0
                    xlabel(app.UIAxes2, 'Frequency (MHz)')
                else
                    xlabel(app.UIAxes2, 'Frequency (ppm)')
                end
                
                ylabel(app.UIAxes2,'Amplitude')
                set (app.UIAxes2, 'ydir', 'normal' )
                set (app.UIAxes2, 'xdir', 'normal' )
                title(app.UIAxes2,'FFT of Subtraction')
                view(app.UIAxes2,0,90)
                ylim(app.UIAxes2,'auto')
                xlim(app.UIAxes2,[app.XlimLowMHzEditField.Value app.XlimHighMHzEditField.Value]);
                set(app.UIAxes2, 'XTickMode', 'auto', 'XTickLabelMode', 'auto')
                set (app.UIAxes2, 'xdir', 'reverse' )
                
                app.Cascade_state = 3;
            end
            
        end
        
        % Calculates the linewidth of a peak(s)
        function [Linewidth,Peak_indexes,Peak_maxes] = Linewidth_calculator(app,X,peak_max,peak_pos)
            %UNTITLED2 Summary of this function goes here
            %   Finds the Linewidth of a peak by checking the values point-by-point down the peak given to the function.
            %   X = frequency axis of data in a (1 x Num_pts) vector
            %   pos = peak position in X
            %   app.Faxis is the x-axis vector (1 x Num_pts)
            %   peak_max = given peak maximum, double
            %   peak_pos = location of peak max, double

            Linewidth = [];
            
            for Loop = 1:1 % length(app.Boundary_zone) % This loop will run once unless changes are made to the number of peak selections.
                
                if nargin == 2
                    % Find the peak max and position
                    [peak_max,peak_pos] = max(X(app.Boundary_zone{Loop}));
                    % Identify peak position within x-axis portion provided by
                    % Boundary_zone
                    peak_pos = peak_pos+app.Boundary_zone{Loop}(1)-1;
                    
                elseif  nargin == 4 % nargin is 4
                    
                    Peak_maxes = peak_max;
                    Peak_indexes = peak_pos;
                else
                    errordlg('Improper amount of inputs to Linewidth function.')
                    
                end
                
                half_max = peak_max/2;
                i = 0;
                t = 1;
                y = 0;
                z = 0;
                right_half_max = 0;
                left_half_max = 0;
                while i ~= 1 % run continuously until i == 1
                    if peak_pos-t > 1 || peak_pos+t < length(X) && half_max > 0  %|| peak_pos ~= length(X) || peak_pos ~= 1 % If locations are within 1 and less than the length of the provided vector
                        if z == 0 && peak_pos-t > 1
                            test_left = X(peak_pos-t);  % Identifies peak height of point(s) descending from peak value on the left side for half-height
                        else % z == 0 && peak_pos-t < 1
                            test_left = half_max;
                        end
                        
                        if y == 0 && peak_pos+t < length(X)
                            test_right = X(peak_pos+t); % Identifies peak height of point descending from peak value on the right side for half-height
                        else % y == 0 && peak_pos+t > length(X)
                            test_right = half_max;
                        end
                        
                        if (test_left < half_max) && z == 0  % If test_left point is less than the half-maximum value, then interpolate for left half-max position.
                            x_points = [app.Faxis(peak_pos-t) app.Faxis(peak_pos-t+1)];
                            y_points = [test_left X(peak_pos-t+1)];
                            a = polyfit(x_points,y_points,1);
                            left_half_max = (half_max-a(2))/a(1);
                            z = 1;
                        else
                        end
                        
                        if test_right < half_max && y == 0 % If test_right point is less than the half-maximum value, then interpolate for right half-max position.
                            x_points = [app.Faxis(peak_pos+t-1) app.Faxis(peak_pos+t)];
                            y_points = [X(peak_pos+t-1) test_right];
                            a1 = polyfit(x_points,y_points,1);
                            right_half_max = (half_max-a1(2))/a1(1);
                            y = 1;
                        else
                        end
                        
                        if left_half_max~=0 && right_half_max~=0  % If positions are within bounds, assign values and stop while loop.
                            Linewidth(Loop) = (right_half_max-left_half_max)*10^6;
                            Peak_indexes(Loop) = peak_pos;
                            Peak_maxes(Loop) = peak_max;
                            i = 1;
                            
                        end
                        t = t+1;  % Increase value of t or position away from peak with each iteration of while loop
                    else  % No peak is found within the region
                        i = 1;
                        Loop = length(app.Boundary_zone);
                        %                         errordlg('Selected region is improper for linewidth selection for loaded file(s). Please adjust the phasing to where there is a peak within the selected region.')
                        Linewidth = 0;
                    end
                end
            end
        end
        
        % Allows the selection of a region to get the phase
        function [Boundary_zone] = Region_selection(app,x,y,single,W,non_phase)
            %Region_selection allows the user to find the phase of the peak value by using the getpts to interactively let the user pick a beginning point and
            %end point that encompass the peak of interest. It uses the first and last points clicked to determine the region to find the abs(max_value). It then
            %displays the phase of that point.
            % x = vector of x-axis data (1 x Num_pts)
            % y = vector of amplitude data (1 x Num_pts)
            % single = 0 for two data point selections, 1 for a single click to change the x-axis to ppm
            % W = 1 for selection two peaks, 0 for selecting the noise
            % boundary for correlation calculations (optional)
            % non-phase = the existence of this variable will cause the calculation of a peak phase angle in degrees

            Z = 0;
            app.TextArea.Value = '';
            if single == 0
                while Z == 0
                    if W == 1
                         app.TextArea.Value = 'Select two points surrounding your peak of choice on the left plot';
                    else
                        app.TextArea.Value = 'Select two points denoting the noise boundary for correlation calculations';
                    end
                    app.UIFigure.HandleVisibility = 'callback';
                    [x1,~] = ginput(2);
                    app.UIFigure.HandleVisibility = 'off';
                    
                    
                    % Find the value that is closest to the values within the x vector
                    [~,closestIndex] = min(abs(x1(1)-x));
                    [~,closestIndex2] = min(abs(x1(end)-x));
                    
                    % As long as two points were selected, get their closest index and
                    % assign the value
                    if length(x1) >= 2 && closestIndex ~= closestIndex2
                        closestValue = x(closestIndex);
                        closestValue2 = x(closestIndex2);
                        Z = 1;
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes,closestValue,real(y(closestIndex)),'b.','MarkerSize',12)
                        hold(app.UIAxes,'on');
                        plot(app.UIAxes, closestValue2,real(y(closestIndex2)),'b.','MarkerSize',12)
                        
                        % Rearrange the order of the points so that the smallest one is
                        % first
                        if closestValue2 > closestValue
                            %                         Indexes = [closestValue, closestIndex;closestValue2,closestIndex2];
                            Indexes = [closestValue2, closestIndex2;closestValue,closestIndex];
                        else
                            %                         Indexes = [closestValue2, closestIndex2;closestValue,closestIndex];
                            Indexes = [closestValue, closestIndex;closestValue2,closestIndex2];
                        end
                    end
                end
                app.TextArea.Value = '';
                % Plot the point for the value with the maximum real(value)
                [Max_val,pos] = max(real(y(Indexes(2,2):Indexes(1,2))));
                
                Max_Peak_pos = Indexes(2,2) + pos-1;
                hold(app.UIAxes,'on');
                plot(app.UIAxes,x(Max_Peak_pos),real(y(Max_Peak_pos)),'b.','MarkerSize',12)
                hold(app.UIAxes,'off');
                pause(1);
                % Displays the phase of the peak in degrees
                if exist('non_phase','var') == 1 % If non_phase doesn't exist as an input
                    og_angle_rad = angle(y(Max_Peak_pos));
                    og_angle = og_angle_rad*360/(2*pi);
                    app.Peak_degree.Value = og_angle;
                else
                    Boundary_zone = Indexes(2,2):Indexes(1,2);
                end
            else
                while Z == 0
                    
                    app.TextArea.Value = 'Select one point on the left plot to identify the 0 ppm position';
                    app.UIFigure.HandleVisibility = 'callback';
                    [x1,~] = ginput(1);
                    app.UIFigure.HandleVisibility = 'off';
                    
                    % Find the value that is closest to the values within the x vector
                    [~,closestIndex] = min(abs(x1(1)-x));
                    
                    % As long as two points were selected, get their closest index and
                    % assign the value
                    closestValue = x(closestIndex);
                    Z = 1;
                    Boundary_zone = closestValue;
                end
                app.TextArea.Value = '';
            end
        end
        
        % Corrects the baseline according to Xi et al. "Baseline Correction for NMR Spectroscopic Metabolomics Data Analysis"
        function bd=Baseline_xi(app,b,A,B,s)
            %Input:
            %       b Nx1 the spectrum data
            %       A 1x1 smoothing factor
            %       B 1x1 negative penalty
            %       s 1x1 noise standard deviation
            %Output:
            %       bd Nx1 baseline
            
            L=length(b);
            Bs=-B/s;
            As=-A/s;
            bd=ones(L,1)*median(b); % bd is a single column vector of value of the median of the spectrum
            bd0=b;                  % bd0 is set to spectrum data;
            nm=norm(bd-bd0);        % nm calculates the difference of the spectrum from the median value
            %     nm0=RealMax;            % nm0 is the max of the distance of nm?
            M0=-ones(L,1)/As;       % vector of positive ones multiplied by s/A
            e=ones(L,1);            % e is a vector of ones
            
            D0=spdiags([2*e -8*e 12*e -8*e 2*e],-2:2,L,L);  % Creates a sparse matrix with 2, -8, 12, and 2 as the diagonals
            D0(1,1)=2;  % Reset D0(1,1) to 2
            D0(L,L)=2;  %
            D0(2,1)=-4;
            D0(1,2)=-4;
            D0(L,L-1)=-4;
            D0(L-1,L)=-4;
            D0(2,2)=10;
            D0(L-1,L-1)=10;
            
            I=0;
            while nm>10 & I<30
                %& nm<nm0;
                I=I+1;
                M=M0;
                D=D0;
                bd0=bd;
                %         nm0=nm;
                for i=1:L
                    if bd(i)>b(i)
                        M(i)=M(i)+2*Bs*b(i)/As;
                        D(i,i)=D(i,i)+2*Bs/As;
                    end
                end
                bd=D\M;
                nm=norm(bd0-bd);
            end
        end
        
        % Automatically corrects the phase based on the MinMax article
        function [a,Xc] = MinMaxPhaseCorr(app,X,En,dk,kA)
            % MinMax automatic phase correction of NMR signals
            % X - DFT spectrum of NMR FID signal
            % EN - percentage of spectrum energy used (optional)
            % dk - number of used freq. bins around max (optional)
            % kA - index of DFT bin for the first search (optional)
            % a - estimated angles of [a0,a1]
            % Xc - corrected DFT spectrum
            
            
            if (nargin == 2)
                En = 0.99;
                dk = 100;
                kA = -1;
            end
            
            if (nargin == 3)
                dk = 100;
                kA = -1;
            end
            
            if (nargin == 4)
                kA = -1;
            end
            
            G = 7;
            P = 100;
            N = length(X);
            df = 1/N;
            f = (0:N-1)*df;
            Xabs = abs(X);
            
            % 1st search - a0A computation
            if (kA == -1)
                [~,kA] = max(Xabs);
            end
            
            ind_max = kA-dk:kA+dk;
            f_max = kA*df;
            opt = optimset('TolX',1e-3,'TolFun',1e-3,'MaxFunEvals',1e2,'MaxIter',1e2,'Display','off');
            param = [0];
            a0_max = fminsearch(@(a0_max)...
                CFa0(a0_max,X(ind_max)),param,opt);
            
            % Energy-based DFT bins selection
            if (En < 1)
                [val, ind1] = sort(-Xabs,'ascend');
                val = val.^2;
                Eall = sum(val);
                Ecum = cumsum(val);
                Neng = max(find(En*Eall >= Ecum));
                ind2 = ind1(1:Neng);
                [~, ind3] = sort(f(ind2));
                ind = ind1(ind3);
                % filling gaps in vector of indexes
                ind_flag = zeros(size(X));
                ind_flag(ind) = 1;
                ind_diff = diff(ind);
                ind_diff = find(diff(ind) <= G & ind_diff > 1);
                for k_diff = 1:length(ind_diff)
                    ind_flag(ind(ind_diff(k_diff)):ind(ind_diff(k_diff)+1)) = 1;
                end
                ind = find(ind_flag == 1);
                X_ind = X(ind);
                f_ind = f(ind);
            else
                X_ind = X;
                f_ind = f;
                Neng = N;
            end
            
            % 2nd search - a1 computation
            X_ind = X_ind.*exp(1i*a0_max);
            f_ind = f_ind - f_max;
            Nopty = min(round(length(f_ind)/10),P);
            a1 = fminsearch(@(a1)...
                CFa1(a1,X_ind,f_ind,Nopty),param,opt);
            a0 = a0_max - a1*f_max;
            a = [a0,a1];
            
            % Final spectrum correction
            Xc = X.*exp(1i*(a(1)+a(2)*f));
            
            function error = CFa0(a0_max,X_ind)
                Xtemp = X_ind.*exp(1i*a0_max);
                error = -min(real(Xtemp));
            end
            
            function error = CFa1(a1,X_ind, f_ind,Nopty)
                Xtemp = X_ind.*exp(1i*(a1*f_ind));
                val_Xc = sort(real(Xtemp));
                error = -sum(real(val_Xc(1:Nopty)));
            end
            
        end
        
        % Function for combining newly loaded data for pre-processing
        function Load_processing(app,time_subtraction,subtraction_prev_filenames, selection_change,time_domain,BW,F0,prev_zeropad,Filename,prev_filenames,a0_initial,a1_initial)
            % Load_processing is a function for separating and appropriately combining previously loaded and newly loaded files.
            % time_subtraction = vector of loaded subtraction function data, otherwise it should just be a constant of 0
            % subtraction_prev_filenames = name of subtraction file
            % selection_change ~= 1 if files are loaded with different channel selections, otherwise the channel selection is the same
            % time_domain = newly loaded time_domain data in the matrix form of [acqs x time_domain data points], where each row represents a different acquisition
            % BW = bandwidth of data in Hz (single value)
            % F0 = center frequency of data in MHz (single value)
            % prev_zeropad = previously assigned zero_padding factor value (single value)
            % Filename = associated file name or file names(s) provided in the form of multi-rowed character vector (x,1), where each row represents a different filename
            % a0_initial = column vector in the form of previously assigned a0 phasing
            % a1_initial = column vector in the form of previously assigned a1 phasing
            d1 = uiprogressdlg(app.UIFigure,'Title','Processing, please wait','Indeterminate','on');
            drawnow
            app.TextArea.Value = '';
            [c,d] = size(time_domain);
            different_sizes = 0;
            if isempty(app.Time_domain) == 1
                app.Time_domain_column_list(1) = d;
                app.Time_domain_row_list(1) = c;
            else
                app.Time_domain_column_list(end+1) = d;
                if length(app.Time_domain_column_list) > 1 && range(app.Time_domain_column_list) ~= 0
                    different_sizes = 1;
                else
                end
                app.Time_domain_row_list(end+1,1) = c;
            end
            
            rows = c;
            app.Columns = d;

            app.BW_per_load = [app.BW_per_load BW];
            app.F0_per_load = [app.F0_per_load F0];
            app.Rows_per_load = [app.Rows_per_load rows];
            
            if isempty(app.Time_domain) == 1
                app.Time_domain = time_domain;
                
                %% Case where first file is loaded and averaging setting is greater than 1
                if app.NumberofAverages.Value ~= 1
                    
                    % Preallocate matrices and vectors used within calculations and/or slider settings
                    Averaged_Time_domain = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,app.Columns);
                    app.A0 = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                    app.A1 = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                    app.Linewidth = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                    app.SNR = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                    app.Noise = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                    app.Peak_maxes = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                    app.Peak_loc = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                    app.F0 = ones(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1)*F0;
                    app.BW = ones(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1)*BW;
                    app.Zero_order_slider_value.Value = 0;
                    app.Slider_2.Value = 0;
                    app.First_order_slider_value.Value = 0;
                    app.Slider_3.Value = 0;
                    app.Slider_0_order = 0;
                    app.Slider_1st_order = 0;
                    
                    if app.Zero_padding == 0 % set app.Spectrum to correct size depending on if zero_padding is included
                        app.Spectrum = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,app.Columns);
                    else
                        app.Spectrum = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,app.Columns*(app.Zero_padding+1));
                    end
                    
                    app.Avg_name = string([]);
                    for i = 1:sum(app.Rows_per_load)-app.NumberofAverages.Value+1
                        b = num2str(i);
                        app.Avg_name(i,1) = string(strcat('Channel_',num2str(app.ChannelSelection.Value),'_Averaging_',num2str(app.NumberofAverages.Value),'_Acq',b));
                    end
                    
                    if sum(app.Rows_per_load) > 1 % setting the slider value based on how many files have been uploaded
                        
                        app.Fig_slider_value.Value = 1;
                        
                        app.Acquisition_select = 1;
                        app.Slider_1.Value = 1;
                        
                        app.Slider_1.Visible = 'on';
                        app.Fig_slider_value.Visible = 'on';
                        
                        if sum(app.Rows_per_load)-app.NumberofAverages.Value+1 > 1
                            app.Slider_1.Limits = [1,sum(app.Rows_per_load)-app.NumberofAverages.Value+1];
                            
                        else
                            app.Slider_1.Visible = 'off';
                            app.Fig_slider_value.Visible = 'off';
                            app.Slider_1.Value = 1;
                        end
                        
                    else
                    end
                    
                    % Calculate the averaged result
                    for l = 1:sum(app.Rows_per_load)-app.NumberofAverages.Value+1
                        Averaged_Time_domain(l,:) = mean(app.Time_domain(l:app.NumberofAverages.Value+l-1,:));
                    end
                    app.Averaged_Time_domain = Averaged_Time_domain - time_subtraction;
                    app.Time_domain = app.Time_domain - time_subtraction;
                else
                    app.A0 = zeros(rows,1);
                    app.A1 = zeros(rows,1);
                    app.Linewidth = zeros(rows,1);
                    app.BW = ones(rows,1)*BW;
                    app.F0 = ones(rows,1)*F0;
                    app.Prev_zeropad = prev_zeropad;
                    
                    if app.Zero_padding == 0
                        app.Spectrum = zeros(rows,app.Columns);
                    else
                        app.Spectrum = zeros(rows,app.Columns*(app.Zero_padding+1));
                    end
                    app.Time_domain = app.Time_domain - time_subtraction;
                end
                %%
                
                if exist('a0_initial','var') == 0 % If a file comes without a0 and a1 values, initialize zeros.
                    app.A0_initial = zeros(rows,1);
                    app.A1_initial = zeros(rows,1);
                else  % If a file comes with a0 and a1 values, insert values.
                    app.A0_initial = a0_initial;
                    app.A1_initial = a1_initial;
                end
                
                BW2 = app.BW(app.Acquisition_select)/(10^6);
                
                app.LeftNoiseLimit.Limits = [app.F0(app.Acquisition_select)-(BW2/2) app.F0(app.Acquisition_select)+(BW2/2)];
                app.RightNoiseLimit.Limits = [app.F0(app.Acquisition_select)-(BW2/2) app.F0(app.Acquisition_select)+(BW2/2)];
                app.LeftNoiseLimit.Value = app.F0(app.Acquisition_select)-(BW2/2);
                app.RightNoiseLimit.Value = app.F0(app.Acquisition_select)-(BW2/2)+(BW2/4);
                app.Filename_spectroscopy = Filename;
                app.Prev_filenames = string(prev_filenames);
                if isempty(subtraction_prev_filenames) == 0
                    app.Prev_filenames(:,2) = subtraction_prev_filenames;
                else
                end
            elseif  different_sizes == 0 && range(app.BW_per_load) == 0 && range(app.F0_per_load) == 0 % Previous files had been loaded, concatenate files provided the number of columns is the same

                % Reset Averaging components to 1
                
                app.Averaged_Time_domain = [];
                app.Time_domain = vertcat(app.Time_domain + time_subtraction,time_domain); % add the subtraction amount back in before time domain averaging. Also, so time_subtraction doesn't get subtracted twice.
                
                if app.NumberofAverages.Value ~= 1 && app.NumberofAverages.Value <= sum(app.Rows_per_load)
                    
                    Averaged_Time_domain = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,app.Columns);
                    app.A0 = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                    app.A1 = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                    app.Linewidth = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                    app.Filename_spectroscopy = vertcat(app.Filename_spectroscopy,Filename);
                    app.SNR = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                    app.Noise = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                    app.Peak_maxes = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                    app.Peak_loc = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                    app.F0 = ones(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1)*F0;
                    app.BW = ones(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1)*BW;
                    
                    app.Zero_order_slider_value.Value = 0;
                    app.Slider_2.Value = 0;
                    app.First_order_slider_value.Value = 0;
                    app.Slider_3.Value = 0;
                    
                    app.Slider_0_order = 0;
                    app.Slider_1st_order = 0;
                    
                    if app.Zero_padding == 0 % set app.Spectrum to correct size depending on if zero_padding is included
                        app.Spectrum = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,app.Columns);
                    else
                        app.Spectrum = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,app.Columns*(app.Zero_padding+1));
                    end
                    
                    app.Avg_name = string([]);
                    for i = 1:sum(app.Rows_per_load)-app.NumberofAverages.Value+1
                        b = num2str(i);
                        if selection_change ~= 1
                            app.Avg_name(i,1) = string(strcat('Channel_',num2str(app.Previous_Channel_select),'_&_','Channel_',num2str(app.ChannelSelection.Value),'_Averaging_',num2str(app.NumberofAverages.Value),'_Acq',b));
                        else
                            app.Avg_name(i,1) = string(strcat('Channel_',num2str(app.ChannelSelection.Value),'_Averaging_',num2str(app.NumberofAverages.Value),'_Acq',b));
                        end
                    end
                    
                    if sum(app.Rows_per_load)-app.NumberofAverages.Value+1 > 1
                        app.Slider_1.Limits = [1,sum(app.Rows_per_load)-app.NumberofAverages.Value+1];
                    else
                        app.Slider_1.Visible = 'off';
                        app.Fig_slider_value.Visible = 'off';
                        app.Slider_1.Value = 1;
                    end
                    
                    
                    % Calculate the averaged result
                    for l = 1:sum(app.Rows_per_load)-app.NumberofAverages.Value+1
                        Averaged_Time_domain(l,:) = mean(app.Time_domain(l:app.NumberofAverages.Value+l-1,:));
                    end
                    app.Averaged_Time_domain = Averaged_Time_domain - time_subtraction;
                    app.Time_domain = app.Time_domain - time_subtraction;
                    
                else
                    app.A0 = vertcat(app.A0,zeros(rows,1));
                    app.A1 = vertcat(app.A1,zeros(rows,1));
                    app.Linewidth = vertcat(app.Linewidth,zeros(rows,1));
                    app.SNR = vertcat(app.SNR,zeros(rows,1));
                    app.Noise = vertcat(app.Noise,zeros(rows,1));
                    app.Peak_maxes = vertcat(app.Peak_maxes,zeros(rows,1));
                    app.Peak_loc = vertcat(app.Peak_loc,zeros(rows,1));
                    
                    [~,D] = size(app.Spectrum);
                    app.Spectrum = vertcat(app.Spectrum,zeros(rows,D));
                    [e,~] = size(Filename);
                    for i = 1:e
                        app.Filename_spectroscopy(end+1,1) = Filename(i,1);
                    end
                    BW_temp = [];
                    F0_temp = [];
                    for i = 1:length(app.Rows_per_load)
                        BW_temp = [BW_temp; ones(app.Rows_per_load(i),1)*app.BW_per_load(i)];
                        F0_temp = [F0_temp; ones(app.Rows_per_load(i),1)*app.F0_per_load(i)];
                    end
                    app.BW = BW_temp;
                    app.F0 = F0_temp;
                    app.Time_domain = app.Time_domain - time_subtraction;
                end
                
                app.Prev_zeropad = prev_zeropad;
                
                if exist('a0_initial','var') == 0 % If a file comes without a0 and a1 values, initialize zeros.
                    app.A0_initial = vertcat(app.A0_initial,zeros(rows,1));
                    app.A1_initial = vertcat(app.A1_initial,zeros(rows,1));
                else  % If a file comes with a0 and a1 values, insert values.
                    app.A0_initial = vertcat(app.A0_initial,a0_initial);
                    app.A1_initial = vertcat(app.A1_initial,a1_initial);
                end

                app.Prev_filenames(end+1,1) = string(prev_filenames);
                if isempty(subtraction_prev_filenames) == 0
                    app.Prev_filenames(:,2) = subtraction_prev_filenames;
                else
                end
            else  % Different number of columns in the time_domain variable, bandwidth, or center frequency. Files shouldn't be concatenated
                app.Columns1 = -1;
                app.Count = app.Count+1;
                app.Zero_padding = 0;
                app.NumberofAverages.Value = 1;
                app.Averaged_Time_domain = [];
                app.FileSubtractionMenu.Visible = 'off';
                app.ChannelSelection.Editable = 'off';
                
                if app.Count == 1
                    [e,~] = size(app.Time_domain);
                    
                    % Copy over the previously run files into cell arrays
                    for i = 1:e
                        app.Matrix_Time_domain{i,1} = app.Time_domain(i,:);
                        app.Matrix_Spectrum{i,1} = app.Spectrum(i,:);
                    end
                    
                    % Append new files into cell arrays
                    for i = 1:c
                        app.Matrix_Time_domain{end+1,1} = time_domain(i,:);
                        app.Matrix_Spectrum{end+1,1} = zeros(1,app.Columns);
                    end
                else
                    for i = 1:c
                        app.Matrix_Time_domain{end+1,1} = time_domain(i,:);
                        app.Matrix_Spectrum{end+1,1} = zeros(1,app.Columns);
                    end
                end
                
                app.A0 = vertcat(app.A0,zeros(rows,1));
                app.A1 = vertcat(app.A1,zeros(rows,1));
                app.Linewidth = vertcat(app.Linewidth,zeros(rows,1));
                app.BW = vertcat(app.BW,ones(rows,1)*BW);
                app.F0 = vertcat(app.F0,ones(rows,1)*F0);
                app.Prev_zeropad = prev_zeropad;
                
                if exist('a0_initial','var') == 0 % If a file comes without a0 and a1 values, initialize zeros.
                    app.A0_initial = vertcat(app.A0_initial,zeros(rows,1));
                    app.A1_initial = vertcat(app.A1_initial,zeros(rows,1));
                else  % If a file comes with a0 and a1 values, insert values.
                    app.A0_initial = vertcat(app.A0_initial,a0_initial);
                    app.A1_initial = vertcat(app.A1_initial,a1_initial);
                end
                
                [e,~] = size(Filename);
                for i = 1:e
                    app.Filename_spectroscopy(end+1,1) = Filename(i,1);
                end
                
                app.Prev_filenames(end+1,1) = string(prev_filenames);
                % Remove the option for averaging if the sizes of the
                % columns don't match and the cascade option, as well as the subtraction and cascade plot options.
                app.NumberofAverages.Editable = 'off';
                app.TextArea.Value = 'The averaging and subtraction operations are no longer available due to incompatible file sizes, bandwidths, or different center frequencies.';
                app.CascadePlotCheckBox.Visible = 'off';
                app.FileSubtractionMenu.Visible = 'off';
            end
            
            
            if sum(app.Rows_per_load)-app.NumberofAverages.Value+1 > 1 % setting the slider value based on how many files have been uploaded
                
                app.Slider_1.Limits = [1,sum(app.Rows_per_load)-app.NumberofAverages.Value+1];
                
                %                 MajorTicks = 1:1:sum(app.Rows_per_load);
                %                                 set(app.Slider_1,'MajorTicks',MajorTicks)
                app.Slider_1.Visible = 'on';
                app.Slider_1.Value = 1;
                app.Acquisition_select = 1;
                app.Fig_slider_value.Value = 1;
                app.Fig_slider_value.Visible = 'on';
                app.SlideValueText.Visible = 'on';
                
                
            else
                app.Slider_1.Visible = 'off';
                app.Fig_slider_value.Value = 1;
                app.Slider_1.Value = 1;
                app.Acquisition_select = 1;
                app.Fig_slider_value.Visible = 'off';
                app.SlideValueText.Visible = 'off';
            end
            
            for i = 1:sum(app.Rows_per_load)-app.NumberofAverages.Value+1
                Main_spectroscopy_processing(app,i,1);
            end
            Main_spectroscopy_processing(app);
            
            app.XlimLowMHzEditField.Value = min(app.Faxis);
            app.XlimHighMHzEditField.Value = max(app.Faxis);
            xlim(app.UIAxes,[app.XlimLowMHzEditField.Value app.XlimHighMHzEditField.Value]);
            
            if app.X_axis_state == 0
                app.LowFrequencyNoiseLimitMHzLabel.Text = 'Low Frequency Noise Limit (MHz):';
                app.HighFrequencyNoiseLimitMHzLabel.Text = 'High Frequency Noise Limit (MHz):';
                app.LinewidthHzLabel.Text = 'Linewidth (Hz):';
                xlabel(app.UIAxes,'Frequency (MHz)');
                xlabel(app.UIAxes2,'Frequency (MHz)');
            else
                app.LowFrequencyNoiseLimitMHzLabel.Text = 'Low Frequency Noise Limit (ppm):';
                app.HighFrequencyNoiseLimitMHzLabel.Text = 'High Frequency Noise Limit (ppm):';
                app.LinewidthHzLabel.Text = 'Linewidth (ppm):';
                xlabel(app.UIAxes,'Frequency (ppm)');
                xlabel(app.UIAxes2,'Frequency (ppm)');
            end
            
            if app.CascadePlotCheckBox.Value == 1 && app.Columns1 ~= -1
                % Plot with the cascade style plot
                Waterfall(app,sum(app.Rows_per_load)-app.NumberofAverages.Value+1)
                pause(0.1);
                app.ViewFIDCheckBox.Value = 0;
            else
                app.ZeroAllPhasesCheckBox.Value = 0;
                Waterfall(app)
                app.CascadePlotCheckBox.Value = 0;
            end
            app.Peak_degree.Value = 0;
            close(d1)
        end
        
        % Function for handling data for file subtraction
        function [e1] = Process_for_subtraction(app, time_domain, BW, F0, prev_filenames)
            % Load_for_subtraction is a loading a single acquisition file to be subtracted from the previously loaded files. This will be done in the time domain.            % files.
            % time_domain = newly loaded time_domain data in the matrix form of [1 acq x time_domain data points]
            % BW = bandwidth of data in Hz (single value)
            % F0 = center frequency of data in MHz (single value)
            % prev_filenames = associated file name of loaded file
            % Any files being loaded into this function will have already been checked to ensure they are a single acquisition file
            
            app.TextArea.Value = '';
            [~,d] = size(time_domain);
            %             different_sizes = 0;
            
            if all(app.Time_domain_column_list == d) || all(app.BW_per_load == BW) || all(app.F0_per_load == F0)
                assignin("base","Prev",prev_filenames)
                assignin("base","Prev_app",app.Prev_filenames)
                app.Prev_filenames(:,2) = string(prev_filenames);
                %                 app.Check_Linewidth_checkbox.Value = 0;
                
                if app.NumberofAverages.Value ~= 1  % Check if averaging has been used to get time_domain data
                    app.Averaged_Time_domain = app.Averaged_Time_domain - time_domain;
                else % app.Columns ~= -1, otherwise all time_domain data will be stored in app.Time_domain
                    app.Time_domain = app.Time_domain - time_domain;
                end
                e1 = 0;
                app.Operations = string('Subtraction');
            else
                errordlg('Error: the number of points, bandwidth, or center frequency is incompatibile with the previously loaded files.')
                e1 = 1;
            end
        end
        
        % Function for combining multi-channel data
        function [Combined,Quality] = Combination_methods(app,Spectrum,SNR,Noise,selection,Number_channels,acquisitions,data_pts,Freq_axis,Shift_select,Peak_location,flag)
            % Spectrum = matrix of the spectra (Num_channels x Num_acquisitions x Num_datapts)
            % SNR = matrix of SNR (Number_channels x Num_acquisitions)
            % Noise = matrix of standard deviation of the noise (Number_channels x Num_acquisitions)
            % selection = 1 for Equal Weighting, 2 for SNR weighting, 3 for S/N^2 weighting, 4 for WSVD weighting, 5 for WSVD+Apod weighting, 6 for AOC weighting
            % Number_channels = Double, number of channels
            % acquisitions = Double representing number of acquisitions
            % data_pts = Double representing Num_pts
            % Freq_axis = vector of frequency axis in MHz (1 x Num_pts)
            % Shift_Select = 1 for aligning of max peaks before combination (only an option for Equal and SNR-weighting)
            % Peak_location = matrix of Num_channels x Num_acquisitions of max peak location
            % flag = 0 for noise covariance matrix calculated from data, 1 for load in noise prescan for noise covariance matrix

            Combined = zeros(acquisitions,data_pts);

            if Shift_select == 1
                % Find the index within Freq_axis for each of the Peak max locations
                Location = zeros(Number_channels,acquisitions);
                for i = 1:Number_channels
                    for j = 1:acquisitions
                        [~,Z1] = min(abs(Peak_location(i,j)-Freq_axis));
                        Location(i,j) = Z1;
                    end
                end
                
                % Using the first row as a guide, find the amount of point shifts from the first rows positions
                Shift = Location - Location(1,:);
                
                [max_shift] = max(max(Shift));
                [min_shift] = min(min(Shift));
                if min_shift > 0 % If min is a positive number, set it to 0 as any positive wrap around will be taken care of by max_shift
                    min_shift = 0;
                end
                
                if max_shift < 0 % If max_shift is less than 0, set it to 0 as any negative wrap around will be taken care of by min_shift
                    max_shift = 0;
                end
                
                % Circularly shift the spectra to the left or right depending on the direction of the shift. Replace the variables that are
                % wrapped around with NaN to prevent frequency information from being distorted
                
                for i = 2:Number_channels
                    for j = 1:acquisitions
                        Spectrum(i,j,:) = circshift(Spectrum(i,j,:),-Shift(i,j));
                    end
                end
                
            else
            end
            
            if selection == 1 % Equal Weighting
                Combined = squeeze(sum(Spectrum))/Number_channels;
                Quality = [];
            elseif selection == 2 % SNR weighting with normalization
                Weighted_den = sqrt(sum(SNR.^2,1));
                Combined = squeeze(sum((Spectrum.*SNR)./Weighted_den,1));
                Quality = [];
            elseif selection == 3 % S/N^2 with normalization
                
                [file,path] = uigetfile({'*.mat','MATLAB file (*.mat)';'*.txt','Text Files'},'File Selector','MultiSelect','off');
                F = fullfile(path,file);
                [~,~,gg] = fileparts(file);
                if file ~= 0
                    if strcmp(gg,'.mat')
                        load(F)
                        s_SN2 = conj(Weighting_amplitude_matrix);
                    else
                        Y = readtable(F);
                        Z = table2array(Y);
                        [a,b] = size(Z);
                        if a == Number_channels && b == acquisitions
                            % Note that the conjugate of the weighting factors is taken to counter the phase of the reference 1H peak.
                            s_SN2 = conj(Z);
                        else
                            errordlg('Error: the reference peak weighting factor table is incompatibile to be used for calculating the S/N^2 method. Ensure that the .txt file is a complex matrix of size: Number_channels x 1')
                            return
                        end
                    end
                else
                    errordlg('Error: A file was not selected and the combination could not be completed.')
                    return
                end

                Weighted_den1 = s_SN2./(Noise.^2);
                Weighted_den = sqrt(sum(Weighted_den1.^2,1));
                Combined = squeeze(sum(Spectrum.*Weighted_den1./Weighted_den,1));
                Quality = [];
            elseif selection == 4 % WSVD
                [Noise_Covariance] = Noise_correlation_covariance1(app,Spectrum,0,Freq_axis,flag);
                for i = 1:acquisitions
                    if flag == 0 % Noise covariance calculated from data
                        [Combination_WSVD, Weights, Quality_WSVD]  = WSVD_Combination(app, squeeze(Spectrum(:,i,:)).', squeeze(Noise_Covariance(i,:,:)), Number_channels);
                        
                    else % Noise covariance provided from prescan
                        
                        [Combination_WSVD, ~, Quality_WSVD]  = WSVD_Combination(app, squeeze(Spectrum(:,i,:)).', Noise_Covariance, Number_channels);
                    end
                    Combined(i,:) = Combination_WSVD;
                    Quality(i) = Quality_WSVD;
                end

            elseif selection == 5 % WSV+Apod
                
                [Noise_Covariance] = Noise_correlation_covariance1(app,Spectrum,0,Freq_axis,flag);
                
                input = inputdlg('Enter a number for the linebroadening (in Hz) for the WSVD+Apod method','Enter a number',1, {'1'});
                if isempty(input) == 1
                    errordlg('No number was input. A default value of 0 Hz will be used.')
                    Line_broadening = 0;
                    input = {'0'};
                else
                end
                    input = str2num(cell2mat(input));
                    if isnan(input)
                        errordlg('Only numbers are acceptable. A default value of 0 Hz will be used.')
                        Line_broadening = 0;
                    else
                        Line_broadening = input;
                    end
                    TD = ifft(ifftshift(Spectrum,3),[],3);

                    LB_point = pi*Line_broadening;
                    at = data_pts/(app.BW);
                    Lbaxis = (1:data_pts)*(at/data_pts);
                    Data_with_LB = [];

                    for i = 1:acquisitions
                        for j = 1:Number_channels
                            Data_with_LB(j,i,:) = (exp(-LB_point*Lbaxis).*squeeze(TD(j,i,:)).');
                        end
                        if flag == 0 % Noise covariance calculated from data
                            [~, Weights_WSVD, Quality_WSVD]  = WSVD_Combination(app, squeeze(Data_with_LB(:,i,:)).', squeeze(Noise_Covariance(i,:,:)));
                        else % Noise covariance provided from prescan
                            [~, Weights_WSVD, Quality_WSVD]  = WSVD_Combination(app, squeeze(Data_with_LB(:,i,:)).',Noise_Covariance);
                        end
                        Combination(i,:) = ((Weights_WSVD.')*squeeze(Spectrum(:,i,:)));
                        Quality(i) = Quality_WSVD;
                    end

                    Combined = Combination;
                   
            else % selection == 6 % AOC
                plot(app.UIAxes,Freq_axis,real(squeeze(Spectrum(1,1,:))))
                set (app.UIAxes, 'xdir', 'reverse' )
                [Full_noise_correlation] = Noise_correlation_covariance1(app,Spectrum,1,Freq_axis);
                [Weights_AOC] = AOC_Combination1(app,Spectrum, Number_channels, Full_noise_correlation, acquisitions);
                Combined = squeeze(sum((Weights_AOC).*(Spectrum)));  

                Quality = [];
            end
            
            % Due to the circular shifting of the data, any data points that were shifted from high-to-low or low-to-high will be
            % removed from the combined data, resulting in fewer data points than before combination  
            if Shift_select == 1
                Combined = Combined(:,max_shift+1:end+min_shift);
            else
            end
        end
        

        % Function that processes the multi-channel data combination
        function Decision_Process_for_MultiChannelCombination1(app,event,selection,change_selection)
            % selection: selection == 'Cancel' for not combining data and selection == 'Matlab File' for moving forward with combination
            % change_selection: exists when changing the combination type but not reloading the data is desired.  
            
            if strcmp(selection,'Cancel') == 1
                return
            else
                
                if nargin > 3 % case where changing the combination type is set
                    files = app.Filepath;
                    
                    Num_channels = length(files);
                else % nargin < 3
                    [file,path] = uigetfile({'*.mat','Matlab Files'},'File Selector','MultiSelect','on');
                    if isequal(file,0) && isequal(path,0)
                        errordlg('Please input a file name','Error');
                        return
                    else
                        if ischar(file) == 1 % Single file has been selected. Set loop value Num_channels = 1 and Flag2 = 1 for file type indication
                           Num_channels = 1;
                           Flag2 = 1;
                        else % Multiple files have been selected
                            [~,Num_channels] = size(file);
                            Flag2 = 0;

                            % Reorder the files so that they are in order
                            % from Ch. 1, Ch. 2, ...
                            Num=regexp(file,'\d+','match');
                            Num=char(cellfun(@cell2mat,Num,'uni',false));
                            [~,Index] = sort(str2num(Num));
                            file = file(Index);
                        end
                    end
                end
                
                % If boundaries for correlation matrix has been found previously, save the values and redefine them after the ClearDataButtonPushed
                if isempty(app.Bounds_corr) ~= 1
                    c = app.Bounds_corr;
                    d = app.Boundary2;
                else
                    c = [];
                    d = [];
                end

                % Reset the variables within the structure app to ensure no values are passed from single channel data to
                % multi-channel combination
                ClearDataButtonPushed(app,event)
                                
                app.Bounds_corr = c;
                app.Boundary2 = d;
                
                % Loop through and load in files
                for P = 1:Num_channels
                    if nargin < 4 && Flag2 == 0
                        F = fullfile(path,file{P});
                    elseif nargin < 4 && Flag2 == 1
                        F = fullfile(path,file);
                    else
                        F = files(P);
                    end
                    app.Filepath(end+1,1) = string(F);
                    [~, fName, fExt] = fileparts(F);
                    T = load(F);  %Loaded file is expected to be saved with a struct named Processed (for previously processed data)
                    
                    if isfield(T,'Processed') == 1
                        if P ~= 1
                            [rows,columns] = size(T.Processed.Spectrum);
                            if rows == a1 && columns == b1 && BW == T.Processed.BW_Hz(1) && F0 == T.Processed.F0_MHz(1) && a1 == length(T.Processed.SNR)
                                Spectrum(P,:,:) = T.Processed.Spectrum;
                                prev_filenames = [prev_filenames; strcat(fName,fExt)];
                                SNR(P,:) = T.Processed.SNR.';
                                Real_noise(P,:) = T.Processed.Real_noise.';
                                Peak_location(P,:) = T.Processed.Peak_location_MHz.';
                            else
                                
                                errordlg('The selected files do not have either: the same number of row, columns, bandwidths, center frequencies, or same sized vectors of SNR');
                                return
                            end
                        else
                            [a1,b1] = size(T.Processed.Spectrum);
                            % Preallocate matrices
                            Spectrum = zeros(Num_channels,a1,b1);
                            SNR = zeros(Num_channels,a1);
                            Real_noise = zeros(Num_channels,a1);
                            Peak_location = zeros(Num_channels,a1);
                            
                            Spectrum(P,:,:) = T.Processed.Spectrum;
                            BW = T.Processed.BW_Hz(1);
                            F0 = T.Processed.F0_MHz(1);
                            prev_filenames = strcat(fName,fExt);
                            SNR(P,:) = T.Processed.SNR.';
                            Real_noise(P,:) = T.Processed.Real_noise.';
                            Peak_location(P,:) = T.Processed.Peak_location_MHz.';
                            Freq_axis = T.Processed.Freq_axis;
                        end
                        
                    else %  isfield(T,'Starting') == 1
                        Spectrum = fftshift(fft(T.Starting.Data,[],3),3);
                        [Num_channels,rows,columns] = size(Spectrum);
                        BW = T.Starting.BW;
                        F0 = T.Starting.F0/10^6;
                        prev_filenames = [T.Starting.Filename];
                        Freq_axis = (linspace(-BW/2,BW/2,columns)+F0*10^6)/(10^6);

                        plot(app.UIAxes,Freq_axis,real(squeeze(Spectrum(1,1,:))))
                        xlabel(app.UIAxes,'Frequency (MHz)')
                        ylabel(app.UIAxes,'Spectral Amplitude (a.u.)')
                        set (app.UIAxes, 'xdir', 'reverse' )
                        axis(app.UIAxes,'auto')
                        
                        % Have user define regions for noise and max signal for SNR calculations. Noise selection will be used for noise correlation/covariance region
                        if isempty(app.Bounds_corr) == 1 || isempty(app.Boundary2) == 1
                            [app.Bounds_corr] = Two_X_axis_inputs1(app,Freq_axis);
                            [app.Boundary2] = Region_selection(app,Freq_axis,real(squeeze(Spectrum(1,1,:))),0,1);
                        else
                        end
                        
                        % Calculate the noise from a user defined region and then the subsequent SNR from the max real-value peak within the range.
                        for i = 1:Num_channels
                            for j = 1:rows
                                [M,I] = max(real(Spectrum(i,j,app.Boundary2)));
                                Real_noise(i,j) = std(real(Spectrum(i,j,app.Bounds_corr)));
                                SNR(i,j) = M/Real_noise(i,j);
                                Peak_location(i,j) = Freq_axis(I);
                            end
                        end
                        break
                    end
                end

                app.BW = BW; 

                % Combination section
                app.ExportNoiseCovarianceMatrixWSVDButton.Visible = 'off';
                if exist('comb_selection','var') ~= 1 % if combination selection does not exist, ask for user input
                    selection0 = uiconfirm(app.UIFigure,'Do you want guidance from the combination method decision tree?',...
                        'Confirm Multi-channel Combination selections','Icon','question','Options',{'Yes','No, select combination methods on own'},'DefaultOption',1);
                    assignin("base","selection",selection0)
                    if strcmp(selection0,'Yes')
                        % show noise correlation, then proceed with logic from logic tree
                        
                        plot(app.UIAxes,Freq_axis,real(squeeze(Spectrum(1,1,:))))
                        set (app.UIAxes, 'xdir', 'reverse' )
                        axis(app.UIAxes,'auto')
                        [Correlation] = Noise_correlation_covariance1(app,Spectrum,2,Freq_axis);
                        assignin("base","NO",Correlation)
                        set (app.UIAxes, 'xdir', 'normal' )
                        imagesc(app.UIAxes, real(Correlation))
                        
                        xlim(app.UIAxes,'auto')
                        ylim(app.UIAxes,'auto')
                        axis(app.UIAxes,'tight')
                        xlabel(app.UIAxes,'Channels')
                        ylabel(app.UIAxes,'Channels')
                        title(app.UIAxes,'Noise Correlation of 1st acquisition')
                        colorbar(app.UIAxes)
                        colormap(app.UIAxes,'jet')
                        caxis(app.UIAxes,[0 1])
                       

                         selection5 = uiconfirm(app.UIFigure,sprintf('Is the noise correlated between channels? \n\nOff-diagonal components range in correlation from 0-1 with 1 representing complete correlation between channels. Most off-diagonal values should approximate 0 for a selection of no correlation.'),...
                        'Confirm correlation','Icon','question','Options',{'Yes','No'},'DefaultOption',1);
                

                        if strcmp(selection5, 'Yes') == 1 % Left side of decision tree followed
                            selection5 = uiconfirm(app.UIFigure,'Are scaling coefficients from 1H unsuppressed data available?',...
                                'Confirm 1H scaling coefficients availability','Icon','question','Options',{'Yes','No'},'DefaultOption',1);
                            if strcmp(selection5,'Yes') == 1 % AOC Method selected
    
                                selection1 = 'AOC';
                                flag = 0;
                            else % WSVD methods decision
                                selection5 = uiconfirm(app.UIFigure,'Was the SNR of the individual channel data low?',...
                                    'Confirm SNR of channels','Icon','question','Options',{'Yes','No'},'DefaultOption',1);
                                selection6 = uiconfirm(app.UIFigure,'Do want to calculate the noise covariance from the data or from a separate noise scan?',...
                                    'Confirm noise covariance source','Icon','question','Options',{'Calculate noise covariance from data','Separate noise scan'},'DefaultOption',1);
    
                                if strcmp(selection6,"Calculate noise covariance from data") == 1
                                    flag = 0;
                                else
                                    flag = 1;
                                end
    
                                if strcmp(selection5,'Yes') == 1 % WSVD+Apod Method selected
                                    selection1 = 'WSVD + Apod';
                                else  % WSVD Method
                                    selection1 = 'WSVD';
    
                                end
                            end
                            selection2 = 'No peak shifting combination';
    
                        else % Right side of decision tree followed
                            flag = 0;
                            selection5 = uiconfirm(app.UIFigure,'Are complex scaling coefficients from a refernce peak available?',...
                                'Confirm complex reference peak scaling coefficients availability','Icon','question','Options',{'Yes','No'},'DefaultOption',1);
                            if strcmp(selection5,'Yes') == 1 % S/N^2 Method selected
                                selection1 = 'Signal/Noise^2 w/ norm';
                                selection2 = 'No peak shifting combination';
                            else % SNR or Equal Weighting
                                selection5 = uiconfirm(app.UIFigure,'Was the SNR of the individual channel data low?',...
                                    'Confirm SNR status','Icon','question','Options',{'Yes','No'},'DefaultOption',1);
                                if strcmp(selection5,'Yes') == 1 % SNR Method selected
                                    selection1 = 'SNR Weighting w/ norm';
                                else  % Equal Method
                                    selection1 = 'Equal Weighting';
                                end
                                selection2 = uiconfirm(app.UIFigure,'Select your preference for whether or not the data from different channels should be shifted based on the Peak selected for SNR measurements',...
                                    'Confirm shifting selections','Icon','question','Options',{'No peak shifting combination','Shift main peaks combination'},'DefaultOption',1);
                            end
                        end
                        colorbar(app.UIAxes,'off')
                        caxis(app.UIAxes,'auto')
                        set (app.UIAxes, 'ydir', 'normal' )

                    else % give the user options to choose their preferred method

                            list = {'Equal Weighting','SNR Weighting w/ norm','Signal/Noise^2 w/ norm','WSVD','WSVD + Apod','AOC'};
                            [indx,~] = listdlg('PromptString', {'Select a combination type.', 'Only a single method can be selected at a time'},'SelectionMode','single','ListSize',[300,150],"ListString",list);

                            if indx == 1
                                selection1 = 'Equal Weighting';
                            elseif indx == 2 
                                selection1 = 'SNR Weighting w/ norm';
                            elseif indx == 3
                                selection1 = 'Signal/Noise^2 w/ norm';
                            elseif indx == 4 
                                selection1 = 'WSVD';
                            elseif indx == 5 
                                selection1 = 'WSVD + Apod';
                            elseif indx == 6
                                selection1 = 'AOC';
                            else
                                % cancel was selected.
                                errordlg('A combination method was not chosen. Therefore, the data will be cleared and need to be reloaded.')
                                ClearDataButtonPushed(app,event)
                                return
                            end

                        if strcmp(selection1, 'Equal Weighting') == 1 || strcmp(selection1,'SNR Weighting w/ norm') == 1
                            selection2 = uiconfirm(app.UIFigure,'Select your preference for whether or not the data from different channels should be shifted based on the Peak selected for SNR measurements',...
                                'Confirm shifting selections','Icon','question','Options',{'No peak shifting combination','Shift main peaks combination'},'DefaultOption',1);
                            flag = 0;
                        elseif strcmp(selection1,'Signal/Noise^2 w/ norm') == 1
                            selection2 = 'No peak shifting combination';
                            selection5 = uiconfirm(app.UIFigure,'Are scaling coefficients from a reference peak available?',...
                                    'Confirm complex reference peak scaling coefficients availability','Icon','question','Options',{'Yes','No'},'DefaultOption',1);
                            flag = 0;
                            if strcmp(selection5,'Yes')
                            else
                                errordlg('Scaling coefficients are required for this method. Please calculate the area under the curve of the unsuppressed water peak in another program and then load results into an Num_channel x 1 .mat or .txt file with a header of "Complex_1H_weights"')
                                return
                            end
                        else
                            if strcmp(selection1,'WSVD') == 1 || strcmp(selection1,'WSVD + Apod') == 1
                                selection3 = uiconfirm(app.UIFigure,'Do want to calculate the noise covariance from the data or from a separate noise scan?',...
                                    'Confirm noise covariance source','Icon','question','Options',{'Calculate noise covariance from data','Separate noise scan'},'DefaultOption',1);
                                
                                if strcmp(selection3, 'Calculate noise covariance from data') == 1
                                    flag = 0;
                                    plot(app.UIAxes,Freq_axis,real(squeeze(Spectrum(1,1,:))))
                                    set (app.UIAxes, 'xdir', 'reverse' )
                                    axis(app.UIAxes,'auto');
                                    if isempty(app.Bounds_corr) == 1
                                        [app.Bounds_corr] = Two_X_axis_inputs1(app,Freq_axis);
                                    else % app.Bounds_corr would have already been defined by user
                                    end
                                else % Noise scan needs to be loaded 
                                    flag = 1;
                                end

                            else % This should default to AOC case
                                flag = 0;
                                selection4 = uiconfirm(app.UIFigure,'Have you previously obtained the scaling coefficients from a separate 1H scan?',...
                                    'Confirm presence of 1H scaling coefficients','Icon','question','Options',{'Yes','No'},'DefaultOption',1);
                                if strcmp(selection4, 'Yes') == 1    
                                    plot(app.UIAxes,Freq_axis,real(squeeze(Spectrum(1,1,:))))
                                    set (app.UIAxes, 'xdir', 'reverse' )
                                    axis(app.UIAxes,'auto');
                                    if isempty(app.Bounds_corr) == 1
                                        [app.Bounds_corr] = Two_X_axis_inputs1(app,Freq_axis);
                                    else % app.Bounds_corr would have already been defined by user
                                    end
                                else % No scan has been performed and this part needs to break off without breaking the code.
                                    errordlg('This method requires 1H scaling factors to properly weight the data. Please process the 1H scaling factors before utilizing this technique.')
                                    return 
                                     
                                end
                            end
                            selection2 = 'No peak shifting combination';
                        end  
                    end

                    
                else
                    selection1 = comb_selection;
                    selection2 = shift_selection;
                end
                
                if strcmp(selection1,'Equal Weighting') == 1
                    Comb_select = 1;
                elseif strcmp(selection1,'SNR Weighting w/ norm') == 1
                    Comb_select = 2;
                elseif strcmp(selection1,'Signal/Noise^2 w/ norm') == 1
                    Comb_select = 3;
                elseif strcmp(selection1,'WSVD') == 1
                    Comb_select = 4;
                elseif strcmp(selection1,'WSVD + Apod') == 1
                    Comb_select = 5;
                else %strcmp(selection1,'AOC') == 1
                    Comb_select = 6;
                end
                
                % Straight data combination if selection2 == 1
                if strcmp(selection2,'No peak shifting combination') == 1
                    Shift_select = 0;
                else % Shift to align peak information is desired
                    Shift_select = 1;
                end
                
                % Combined the data within this function based on previous selections
                [Combined,Quality] = Combination_methods(app,Spectrum,SNR,Real_noise,Comb_select,Num_channels,rows,columns,Freq_axis,Shift_select,Peak_location,flag);
                time_domain = ifft(ifftshift(Combined,2),[],2);

                for i = 1:rows
                    d = num2str(i);
                    if Comb_select == 4 || Comb_select == 5
                        Filename(i,1) = string(sprintf('%s_Quality_of_%0.3f_Acq_%d',selection1,Quality(i),i));
                    else
                        Filename(i,1) = string(strcat(selection1,'_',selection2,'_Acq_',d));
                    end
                end
                prev_zeropad = 0;
                Load_processing(app,0,'',0,time_domain,BW,F0,prev_zeropad,Filename,prev_filenames)
                app.CombinationTypeChangeMenu.Visible = 'on';
                app.Operations = [string(selection1),string(selection2)];
                app.NumberofAverages.Editable = 'off';
                
                app.ChannelSelection.Editable = 'off';
                app.TextArea.Value = '';
                app.PreprocessingDataMenu.Visible = 'off';
            
            end
    end
        
        
        function Channel_selection_processing(app,loop)
            % This function processes any changes in the value of the channel selection.
            % Loop = variable used to pass a new channel select value for case of batch processing
            if exist('loop','var') == 1
                app.ChannelSelection.Value = loop;
            end
            if app.ChannelSelection.Value > app.ChannelSelection.Limits(2)
                app.ChannelSelection.Value = app.ChannelSelection.Limits(2);
            else
            end
            
            if isempty(app.Filepath_subtraction) == 1 % No subtraction file has been loaded
                time_subtraction = 0;
                subtraction_prev_filenames = '';
            else
                if length(app.Filepath_subtraction) == 1
                    [~, fName, fExt] = fileparts(app.Filepath_subtraction(1,1));
                    P = load(app.Filepath_subtraction(1,1)); % Loaded file is expected to be saved with a struct named S (for raw data - not processed)
                else
                    [~, fName, fExt] = fileparts(app.Filepath_subtraction(app.ChannelSelection.Value,1));
                    P = load(app.Filepath_subtraction(app.ChannelSelection.Value,1)); % Loaded file is expected to be saved with a struct named S (for raw data - not processed)
                end
                F01 = P.Starting.F0/(10^6);  % Convert F0 to MHz.
                BW1 = P.Starting.BW; % Define the Bandwidth in Hz
                
                % Find the number of channels (a) and number of acquisitions (b)
                [a,b,c] = size(P.Starting.Data);
                
                if b == 1 && a == app.Channels(1) || (a==1 && length(app.Filepath_subtraction) == app.Channels(1)) && app.Columns1 ~= -1 && BW1 == app.BW_per_load(1) && F01 == app.F0_per_load(1) && c == app.Time_domain_column_list(1)
                    
                    if a == 1  % if b == 1, squeeze needs transposing (5120 data_pts x 1 acq to 1 acq x 5120 data_pts)
                        time_subtraction = squeeze(P.Starting.Data(1,:,:)).';
                    else
                        time_subtraction = squeeze(P.Starting.Data(app.ChannelSelection.Value,1,:)).';
                    end
                    
                    subtraction_prev_filenames = strcat(fName,fExt);
                    
                else
                    time_subtraction = 0;
                    subtraction_prev_filenames = '';
                end
            end
            % Clear the data that was previously being used to prevent any problems with loading different channel data
            app.Time_domain = [];
            app.Time_domain_column_list = 0;
            app.Time_domain_row_list = 0;
            app.Columns = 0;
            app.BW_per_load = [];
            app.F0_per_load = [];
            app.Rows_per_load = [];
            app.A0 = 0;
            app.A1 = 0;
            app.Linewidth = 0;
            app.BW = 0;
            app.F0 = 0;
            app.Prev_zeropad = 0;
            app.Spectrum = [];
            app.A0_initial = 0;
            app.A1_initial = 0;
            app.SNR = 0;
            app.Noise = 0;
            app.Peak_maxes = 0;
            app.Peak_loc = 0;
            app.Channels = [];
            app.Acquisition_select = 1;
            app.Slider_1.Value = 1;
            app.Fig_slider_value.Value = 1;
            app.Prev_filenames = [];
            
            [a,~] = size(app.Filepath);
            
            for j = 1:a
                F = app.Filepath(j);
                [~, fName, fExt] = fileparts(F);
                T = load(F);
                
                F0 = T.Starting.F0/(10^6);  % Convert F0 to MHz.
                BW = T.Starting.BW;
                
                % Find the number of channels (a) and number of acquisitions (b)
                [e,f,~] = size(T.Starting.Data);
                app.Channels = [app.Channels; e];
                
                index = app.ChannelSelection.Value;
                Channel_select = index;
                for i = 1:f
                    d = num2str(i);
                    Filename(i,1) = string(strcat(T.Starting.Filename,'_Ch_',num2str(Channel_select),'_','Acq',d));
                end
                prev_zeropad = 0;
                
                if f == 1  % if f == 1, squeeze needs transposing (5120 data_pts x 1 acq to 1 acq x 5120 data_pts)
                    time_domain = squeeze(T.Starting.Data(Channel_select,:,:)).';
                else
                    time_domain = squeeze(T.Starting.Data(Channel_select,:,:));
                end
                
                prev_filenames = strcat(fName,fExt);
                Load_processing(app,time_subtraction,subtraction_prev_filenames,1,time_domain,BW,F0,prev_zeropad,Filename,prev_filenames)
                
                app.Zero_order_slider_value.Value = app.A0(app.Acquisition_select);
                app.First_order_slider_value.Value = app.A1(app.Acquisition_select);
                app.Slider_2.Value = app.A0(app.Acquisition_select);
                app.Slider_3.Value = app.A1(app.Acquisition_select);
                app.Channel_selections = ones(length(app.Channel_selections),1)*Channel_select;
            end
            % Store updated Channel_selection option
            app.Previous_Channel_select = index;
            if exist('loop','var') == 1 % if loop exists, then export the file to a .mat after processing
                
            else % No exporting of data is required
            end
        end
        
        
        function Export_processing(app,file1,path)
            % Export_processing takes in a file name and location and converts the data into a .mat structure for future use with the program for viewing/processing or data combination
            % file = output from uiputfile of filename with extension
            % path = output from uiputfile of memory path for fiel
            if isequal(file1,0) || isequal(path,0)
                errordlg('Please enter a valid file name','Error');
            else
                file = fullfile(path,file1);
                
                % This section goes in and reverts to the time domain
                if app.Columns1 ~= -1  % Case where column data is the same size
                    Spectrum1 = app.Spectrum;
                    [V,W] = size(Spectrum1);
                    time_domain = zeros(V,W);
                    for i = 1:V
                        time_domain(i,:)=ifft(ifftshift((Spectrum1(i,:))));
                    end
                else  % Case where column data are different size. It only selects the latest processed spectra.
                    Spectrum1 = app.Matrix_Spectrum{app.Acquisition_select,1};
                    time_domain = ifft(ifftshift(Spectrum1));
                end

                % Adjusts the a0 and a1 phasing properties depending on
                % if file had previously been phased and imported.
                % If averaging has been used, the vectors a0 and a0_initial won't match up. Redefine the filename array
                if length(app.A0) ~= length(app.A0_initial)
                    a0 = app.A0;
                    a1 = app.A1;
                    Filename = app.Avg_name;
                    F0 = app.F0;
                    BW = app.BW;
                else
                    a0 = app.A0+app.A0_initial;
                    a1 = app.A1+app.A1_initial;
                    Filename = app.Filename_spectroscopy;
                    F0 = app.F0;
                    BW = app.BW;
                end

                % Changes the noise region selection limits to that of MHz no matter what the x-axis had been previously changed by the user
                if app.PPM_axis == 1
                    Noise_region_selection = [(app.LeftNoiseLimit.Value*app.F0(app.Acquisition_select)+app.F0(app.Acquisition_select)*10^6)/10^6 (app.RightNoiseLimit.Value*app.F0(app.Acquisition_select)+app.F0(app.Acquisition_select)*10^6)/10^6];
                elseif app.PPM_axis == 0
                    Noise_region_selection = [app.LeftNoiseLimit.Value app.RightNoiseLimit.Value];
                else
                    Noise_region_selection = [((app.LeftNoiseLimit.Value+app.Zero_ppm)*app.F0(app.Acquisition_select)+app.F0(app.Acquisition_select)*10^6)/10^6 ((app.RightNoiseLimit.Value+app.Zero_ppm)*app.F0(app.Acquisition_select)+app.F0(app.Acquisition_select)*10^6)/10^6];
                end

                % Set the zero-padding factor based on the number of averages, column length or if previous zero-padding had been perfroemd on data
                if app.NumberofAverages.Value ~= 1 || app.Columns1 == -1
                    Zero_pad = app.Zero_padding;
                else
                    Zero_pad = ((app.Zero_padding+1)*(app.Prev_zeropad+1)-1); % This will not be valid if averaging is used.
                end
                
                if isempty(app.Bounds_corr) ~= 1
                    Noise_correlation_region_selection = [app.Faxis(app.Bounds_corr(1)),app.Faxis(app.Bounds_corr(end))];
                else
                    Noise_correlation_region_selection= [];
                end

                if app.Columns1 ~= -1 % If data files are all the same size
                    Processed = struct('Filename',string(Filename),'Spectrum',app.Spectrum,'Time_domain', time_domain,...
                        'Freq_axis',app.Faxis,'F0_MHz',F0,'Linewidth_Hz',app.Linewidth,'Peak_location_MHz',app.Peak_loc,...
                        'Peak_Maxes',app.Peak_maxes,'SNR',app.SNR,'a0',a0,'a1',a1,'Number_averages',app.NumberofAverages.Value,'Line_broadening',app.LineBroadening,'Zero_padding',Zero_pad,...
                        'Baseline_correction',app.Baseline_correction,'Real_noise',app.Noise,'BW_Hz',BW,'Noise_region_limits_MHz',Noise_region_selection,'Source_files',string(app.Prev_filenames),'Channel_selection',app.Channel_selections,'Operations',app.Operations,'Noise_correlation_region_selection_MHz',Noise_correlation_region_selection);
                else % Case where data files are different sizes. Only current selected data file will be exported.
                    Y = movsum(app.Time_domain_row_list,2);
                    [~,index] = min(abs(Y-app.Acquisition_select));
                    Processed = struct('Filename',string(Filename(app.Acquisition_select,1)),'Spectrum',mat2cell(app.Matrix_Spectrum{app.Acquisition_select,1},1),'time_domain', mat2cell(time_domain,1),...
                        'Freq_axis',app.Faxis,'F0_MHz',mat2cell(F0(app.Acquisition_select),1),'Linewidth_Hz',mat2cell(app.Linewidth(app.Acquisition_select),1),'Peak_location_MHz',mat2cell(app.Peak_loc(app.Acquisition_select),1),...
                        'Peak_Maxes',mat2cell(app.Peak_maxes(app.Acquisition_select),1),'SNR',mat2cell(app.SNR(app.Acquisition_select),1),'a0',mat2cell(a0(app.Acquisition_select),1),'a1',mat2cell(a1(app.Acquisition_select),1),'Number_averages',app.NumberofAverages.Value,'Line_broadening',app.LineBroadening,'Zero_padding',Zero_pad,...
                        'Baseline_correction',app.Baseline_correction,'Real_noise',mat2cell(app.Noise(app.Acquisition_select),1),'BW_Hz',mat2cell(BW(app.Acquisition_select),1),'Noise_region_limits_MHz',Noise_region_selection,'Source_files',string(app.Prev_filenames(index)),'Channel_selection',app.Channel_selections(index),'Noise_correlation_region_selection_MHz',Noise_correlation_region_selection);
                end

                save(file,'Processed');
                app.TextArea.Value = 'Data has been exported';
            end
        end

        function w = ImportjMRUI(app,filename, dataLines)
            %IMPORTFILE5 Import data from a text file
            %  W = IMPORTFILE5(FILENAME) reads data from text file FILENAME for the
            %  default selection.  Returns the data as a string array.
            %
            %  W = IMPORTFILE5(FILE, DATALINES) reads data for the specified row
            %  interval(s) of text file FILENAME. Specify DATALINES as a positive
            %  scalar integer or a N-by-2 array of positive scalar integers for
            %  dis-contiguous row intervals.
            %
            %  Example:
            %  w = importfile5("C:\Users\Documents\MATLAB\ta\w.txt", [1, Inf]);
            %
            %  See also READMATRIX.
            %
            % Auto-generated by MATLAB on 29-Sep-2021 17:46:18

            %% Input handling

            % If dataLines is not specified, define defaults
            if nargin < 3
                dataLines = [1, Inf];
            end
            
            %% Set up the Import Options and import the data
            opts = delimitedTextImportOptions("NumVariables", 4);
            
            % Specify range and delimiter
            opts.DataLines = dataLines;
            opts.Delimiter = "\t";
            
            % Specify column names and types
            opts.VariableNames = ["jMRUIDataTextfile", "VarName2", "VarName3", "VarName4"];
            opts.VariableTypes = ["string", "string", "string", "string"];

            % Specify file level properties
            opts.ExtraColumnsRule = "ignore";
            opts.EmptyLineRule = "read";
            opts.ConsecutiveDelimitersRule = "join";

            % Specify variable properties
            opts = setvaropts(opts, ["jMRUIDataTextfile", "VarName2", "VarName3", "VarName4"], "WhitespaceRule", "preserve");
            opts = setvaropts(opts, ["jMRUIDataTextfile", "VarName2", "VarName3", "VarName4"], "EmptyFieldRule", "auto");

            % Import the data
            w = readmatrix(filename, opts);
            C = ismissing(w);
            w = w(C(:,1) == 0,:);
        end

        function [Combination_WSVD, Weights_WSVD, Quality_WSVD]  = WSVD_Combination(app, Data, Noise_Covariance, Number_channels)
            % Data = A x B matrix (A = num_pts, B = channels)
            % Noise_Covariance = B x B matrix of covariance between channels
            % Number_channels = double of number of channels

            [~,Num_channels] = size(Data);
            [X1, D1] = eig(Noise_Covariance);
            W1 = X1*diag(sqrt(0.5)./sqrt(diag(D1)));
            invScaleMatrixFft = inv(W1);
            Whitened_spectra1=Data*W1;

            % Compute optimal reconstruction using SVD
            [u2,s2,v2]=svd(Whitened_spectra1,'econ');
            % SVD quality indicator
            Quality_WSVD = ((s2(1,1)/norm(diag(s2)))*sqrt(Num_channels)-1)/(sqrt(Num_channels)-1);

            % Coil amplitudes
            alpha_1=v2(:,1)'*invScaleMatrixFft;
            phi_1 = norm(alpha_1)*alpha_1(1)/norm(alpha_1(1));
            alpha_2= alpha_1/phi_1;
            
            % Combined results of WSVD
            Combination_WSVD = u2(:,1)*s2(1,1)*phi_1;
            
            % Weights for combination
            Weights_WSVD = 0.5*inv(Noise_Covariance) * alpha_2' * conj(phi_1) * phi_1;
        end
        
        function [Weights_AOC] = AOC_Combination1(app,Spectrum, Number_channels, Full_noise_correlation,acquisitions)
            % Spectrum = N_channel x N_acquisition x N_pts matrix
            % Number_channels = size of first dimension of Spectrum
            % Full_noise_correlation = N_channel x N_channel matrix of complex doubles with the diagonal components representing the
            % covariances of each channel and the off-diagonal components representing the noise correlation between channels
            % acquisitions = N_acquisitions of Spectrum
            
            [file,path] = uigetfile({'*.txt','Text Files'},'File Selector','MultiSelect','off');
            F = fullfile(path,file);
            if file ~= 0
                Y = readtable(F);
                Z = table2array(Y);
                [a,b] = size(Z);
                if a == Number_channels && b == 1
                    % Note that the conjugate of the weighting factors if
                    % applied to counter the phase of the 1H peak. 
                    s_AOC = conj(Z(:,1));
                    for i = 1:acquisitions
                        Weights_AOC_1 = inv(squeeze(Full_noise_correlation(i,:,:)));
                        Weights_AOC(:,i) = Weights_AOC_1*s_AOC;
                    end
                else
                    Weights_AOC = zeros(Number_channels,1);
                    errordlg('Error: the 1H weighting factor table is incompatibile to be used for calculating the AOC method. Ensure that the .txt file is a complex matrix of size: Number_channels x 1')
                end
            else
                errordlg('Error: A file was not selected and the combination could not be completed.')
                Weights_AOC = zeros(Number_channels,1);
            end
        end
        
        function [Correlation] = Noise_correlation_covariance1(app,Spectrum,Decision,Faxis,flag)
            % Spectrum is a Num_channel x Num_acquisitions x Num_datapts matrix
            % Decision = 0 (for WSVD, calculate covariance from data), 1 (for AOC full noise correlation matrix required), else only noise correlation matrix required.
            % Faxis is a 1 x Num_datapts vectore of the frequency axis points
            % Flag = 0 for noise covariance matrix calculated from data, 1 for load in noise prescan for noise covariance matrix
            [a,b,~] = size(Spectrum);
            if Decision == 0 %% WSVD chosen and only noise covariance required
                if flag == 0
                    for i = 1:b
                        Correlation(i,:,:) = cov(squeeze(Spectrum(:,i,app.Bounds_corr)).');
                    end
                else
                    Correlation = LoadNoiseCovariance(app);
                end
                
            elseif Decision == 1 %% AOC was chosen, so full noise-correlation matrix required

                for i = 1:b
                    Corr_sig_complex = corrcoef(squeeze(Spectrum(:,i,app.Bounds_corr)).');
                    Cov_sig = cov(squeeze(Spectrum(:,i,app.Bounds_corr)).');
                    TA = diag(ones(a,1));
                    TE = diag(diag(Cov_sig));
                    Correlation(i,:,:) = (Corr_sig_complex)-TA+TE;

                end

            else  %% The noise correlation is only required for the first acquisition for decision tree for determination of noise correlation.
                if isempty(app.Bounds_corr) == 1
                    [app.Bounds_corr] = Two_X_axis_inputs1(app,Faxis);
                else % app.Bounds_corr would have already been defined by user
                end
                Correlation = corrcoef(real(squeeze(Spectrum(:,1,app.Bounds_corr))).');
            end
        end
        
        function [bounds] = Two_X_axis_inputs1(app,Faxis)
        % This function will repeatedly ask for two input boundaries that are
        % within Faxis. 
        %   Faxis = vector of x-axis points (1 x Num_pts)
        
        prompt = {'Enter a low frequency boundary for the noise region:', 'Enter a high frequency boundary for the noise region:'};
        dlgtitle = 'Input';
        dims = [1 80];
        definput = {num2str(Faxis(1)), num2str(Faxis(end*1/4))};
        Z = 0;
        answer = inputdlg(prompt,dlgtitle,dims,definput);
        while Z == 0
            
            if isempty(answer) == 1 % If cancel is selected, ask again.
                answer = inputdlg(prompt,'You must enter two bounds to proceed',dims,definput);
            else
               for i = 1:length(answer)
                   bounds(i,1) = str2num(answer{i});  
               end
        
               % Check if a non-numeric value is given   
               [~,closestIndex] = min(abs(bounds(1)-Faxis));
               [~,closestIndex2] = min(abs(bounds(2)-Faxis));

               if isnan(bounds(1,1)) == 1 || isnan(bounds(2,1)) == 1
                   Z = 0;
                   answer = inputdlg(prompt,'Only numbers accepted',dims,definput);
               elseif (bounds(1)-Faxis(1) >= 0 && Faxis(end) - bounds(2) >= 0 && bounds(1) < bounds(2)) || (closestIndex == 1 && closestIndex2 == length(Faxis) && bounds(1) < bounds(2)) || (closestIndex == 1 && Faxis(end) - bounds(2) >= 0 && bounds(1) < bounds(2)) || (bounds(1)-Faxis(1) >= 0 && closestIndex2 == length(Faxis) && bounds(1) < bounds(2)) 
                   Z = 1;
        
               else
                   Z = 0;
                   answer = inputdlg(prompt,'You must enter two bounds within the x-axis to proceed',dims,definput);
               end
               
            end
        end
        [~,closestIndex] = min(abs(bounds(1)-Faxis));
        [~,closestIndex2] = min(abs(bounds(2)-Faxis));
        bounds = closestIndex:closestIndex2;
%             bounds = length(Faxis)-400:length(Faxis);
        end

        function Correlation = LoadNoiseCovariance(app)
            % This function loads in a previously determined noise covariance matrix (.mat or .txt) to be used with the WSVD or WSVD+Apod methods
            [file1,path1] = uigetfile({'*.mat','MATLAB file (*.mat)';'*.txt','Text Files'},'File Selector','MultiSelect','off');
                F1 = fullfile(path1,file1);

                if file1 ~= 0
                    
                    [~,~,ext2] = fileparts(file1);

                    if strcmp(ext2,'.mat')
                        load(file1);
                        Correlation = Covariance;
                    elseif strcmp(ext2,'.txt')
                        Y = readtable(F1);
                        Z = table2array(Y);
                        [a,b] = size(Z);
                        if a == b % Check to see if noise covariance matrix is square
                            Correlation = Z;
                        else
                            errordlg('Error: The noise covariance matrix must be a square matrix.')
                            return
                        end
                    else
                        errordlg('Error: Only a .mat file or .txt file will be accepted.')
                        return
                    end
                else
                    errordlg('Error: A noise correlation matrix file was not selected.')
                    return
                end
        end
        
        function ExportJMRUI_for_batch(app,file,path)
            
            if isequal(file,0) || isequal(path,0)
                    errordlg('Please enter a valid file name','Error');
                else
                    d = uiprogressdlg(app.UIFigure,'Title','Processing, please wait','Indeterminate','on');
                    drawnow
                    if app.Columns1 ~= -1  % Case where data are all the same size
                        [a,b] = size(app.Spectrum);
                        Spectrum = app.Spectrum;
                    else  % Case where data are different size. It only selects the latest processed spectra.
                        [a,b] = size(app.Matrix_Spectrum);
                        Spectrum = app.Matrix_Spectrum;
                    end
                    F = fullfile(path,file);
                    [~, fName, fExt] = fileparts(F);
                    Filename = [fName,fExt];
                    num_data_pts = b;
                    num_acquisitions = a;
                    SignalNames1 = join(app.Filename_spectroscopy,';');

                    file = file(1:end-4);
                    BW = app.BW(1);
                    F0 = app.F0(1);
                    base_name_ch = strcat(fName,'_Channel_',num2str(app.ChannelSelection.Value),'.txt');
                    fileID = fopen(fullfile(path,base_name_ch),'w');
                    % Generate Header
                    fprintf(fileID,'jMRUI Data Textfile\r\n');
                    fprintf(fileID,'\r\n');
                    fprintf(fileID,'Filename: %s\r\n',Filename);
                    fprintf(fileID,'\r\n');
                    fprintf(fileID,'PointsInDataset: %d\r\n',num_data_pts);
                    fprintf(fileID,'DatasetsInFile: %d\r\n',num_acquisitions);
                    fprintf(fileID,'SamplingInterval: %.4e\r\n',1/BW*1000);
                    fprintf(fileID,'ZeroOrderPhase: 0E0\r\n');
                    fprintf(fileID,'BeginTime: 0E0\r\n');
                    fprintf(fileID,'TransmitterFrequency: %.4e\r\n',F0*10^6);
                    fprintf(fileID,'MagneticField: 0E0\r\n');
                    fprintf(fileID,'TypeOfNucleus: -1E0\r\n');
                    fprintf(fileID,'NameOfPatient: \r\n');
                    fprintf(fileID,'DateOfExperiment: \r\n');
                    fprintf(fileID,'Spectrometer: \r\n');
                    fprintf(fileID,'AdditionalInfo: \r\n');
                    fprintf(fileID,'SignalNames: %s\r\n',SignalNames1);
                    fprintf(fileID,'\r\n');
                    fprintf(fileID,'\r\n');
                    fprintf(fileID,'Signal\r\n');
                    fprintf(fileID,'sig(real)\t sig(imag)\r\n');
                    %     fprintf('Saving Channel %d\n',channel);
                    
                    fids=ifft(ifftshift(Spectrum,2),[],2);
                    for i = 1:num_acquisitions
                        fprintf(fileID,'Signal %d out of %d in file\r\n',i,num_acquisitions);
                        for row = 1:num_data_pts
                            fprintf(fileID,'%.4e\t %.4e\r\n', real(fids(i,row)), imag(fids(i,row)));
                        end
                    end
                    fclose(fileID);
                    close(d)
                    % Saves it in a matrix form for working in Matlab
                    % save(file1,'Time_domain_data')
              end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CombinationTypeChangeMenu.Visible = 'off';
            app.BatchProcessMenu.Visible = 'off';
        end

        % Button pushed function: ExportjMRUIDataButton
        function ExportjMRUIDataButtonPushed(app, event)
            if length(app.Time_domain) ~= 0
                [file, path] = uiputfile('*.txt');
                
                if isequal(file,0) || isequal(path,0)
                    errordlg('Please enter a valid file name','Error');
                else
                    d = uiprogressdlg(app.UIFigure,'Title','Processing, please wait','Indeterminate','on');
                    drawnow
                    if app.Columns1 ~= -1  % Case where data are all the same size
                        [a,b] = size(app.Spectrum);
                        Spectrum = app.Spectrum;
                    else  % Case where data are different size. It only selects the latest processed spectra.
                        [a,b] = size(app.Matrix_Spectrum);
                        Spectrum = app.Matrix_Spectrum;
                    end
                    F = fullfile(path,file);
                    [~, fName, fExt] = fileparts(F);
                    Filename = [fName,fExt];
                    num_data_pts = b;
                    num_acquisitions = a;
                    SignalNames1 = join(app.Filename_spectroscopy,';');

                    file = file(1:end-4);
                    BW = app.BW(1);
                    F0 = app.F0(1);
                    
                    fileID = fopen(fullfile(path,strcat(file,'.txt')),'w');
                    % Generate Header
                    fprintf(fileID,'jMRUI Data Textfile\r\n');
                    fprintf(fileID,'\r\n');
                    fprintf(fileID,'Filename: %s\r\n',Filename);
                    fprintf(fileID,'\r\n');
                    fprintf(fileID,'PointsInDataset: %d\r\n',num_data_pts);
                    fprintf(fileID,'DatasetsInFile: %d\r\n',num_acquisitions);
                    fprintf(fileID,'SamplingInterval: %.4e\r\n',1/BW*1000);
                    fprintf(fileID,'ZeroOrderPhase: 0E0\r\n');
                    fprintf(fileID,'BeginTime: 0E0\r\n');
                    fprintf(fileID,'TransmitterFrequency: %.4e\r\n',F0*10^6);
                    fprintf(fileID,'MagneticField: 0E0\r\n');
                    fprintf(fileID,'TypeOfNucleus: -1E0\r\n');
                    fprintf(fileID,'NameOfPatient: \r\n');
                    fprintf(fileID,'DateOfExperiment: \r\n');
                    fprintf(fileID,'Spectrometer: \r\n');
                    fprintf(fileID,'AdditionalInfo: \r\n');
                    fprintf(fileID,'SignalNames: %s\r\n',SignalNames1);
                    fprintf(fileID,'\r\n');
                    fprintf(fileID,'\r\n');
                    fprintf(fileID,'Signal\r\n');
                    fprintf(fileID,'sig(real)\t sig(imag)\r\n');
                    %     fprintf('Saving Channel %d\n',channel);
                    
                    fids=ifft(ifftshift(Spectrum,2),[],2);
                    for i = 1:num_acquisitions
                        fprintf(fileID,'Signal %d out of %d in file\r\n',i,num_acquisitions);
                        for row = 1:num_data_pts
                            fprintf(fileID,'%.4e\t %.4e\t %.4e\t %.4e\r\n', real(fids(i,row)), imag(fids(i,row)));
                        end
                    end
                    fclose(fileID);
                    close(d)
                    % Saves it in a matrix form for working in Matlab
                    % save(file1,'Time_domain_data')
                end
            else
                errordlg('Please load an input file name with the appropriate number of averages before exporting files.','Error');
            end
            
            
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            if length(app.Time_domain) ~= 0
                [file, path] = uiputfile('*.mat');
                Export_processing(app,file,path)
            else
                errordlg('Please load a file before trying to export data','Error');
            end
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            % Reset parameters to their initial state
            app.Slider_0_order = 0;
            app.Slider_1st_order = 0;
            app.Automatic0OrderPhaseCheckBox.Value = 0;
            app.BaselineCorrectionCheckBox.Value = 0;
            app.Check_Linewidth_checkbox.Value = 0;
            app.LineBroadeningHz.Value = 0;
            app.LineBroadening = 0;
            % Set Zeropadding = 0;
            app.ZeropaddingFactor.Value = 0;
            app.Zero_padding = 0;
            
            app.Acquisition_select = 1;
            app.Time_domain = [];
            app.Count = 0;
            app.Matrix_Time_domain = [];
            app.Time_domain_column_list = 0;
            app.Time_domain_row_list = 0;
            app.Matrix_Spectrum = [];
            
            
            app.NumberofAverages.Editable = 'on';
            app.CascadePlotCheckBox.Visible = 'on';
            app.FileSubtractionMenu.Visible = 'on';
            app.ChannelSelection.Editable = 'on';
            app.SlideValueText.Visible = 'on';
            app.Fig_slider_value.Visible = 'on';
            app.Slider_1.Visible = 'on';
            app.ChannelSelection.Value = 1;
            app.LoadFilesMenu.Visible = 'on';
            app.PreprocessingDataMenu.Visible = 'on';
            app.NumberofAveragesLabel.Text = 'Number of Averages:';
            app.ChannelSelection.Editable = 'on';
            app.PreprocessingDataMenu.Enable = 'on';
            app.MultichannelCombinationMenu.Enable = 'on';
            app.CombinationTypeChangeMenu.Visible = 'off';
            app.BatchProcessMenu.Visible = 'off';
            app.ExportNoiseCovarianceMatrixWSVDButton.Visible = 'on';
            
            app.Faxis = 0;
            app.F0 = 0;
            
            app.A0 = [];
            app.A1 = [];
            app.Linewidth = [];
            app.Spectrum = [];
            app.Z = [];
            app.Filename_spectroscopy = string([]);
            app.Columns = [];
            app.Columns1 = 0;
            app.Averaged_Time_domain = [];
            app.Boundary_zone = [];
            app.Bounds_corr = [];
            app.Peak_loc = [];
            app.Baseline_correction = 0;
            app.BW = [];
            app.File_loc = [];
            
            app.Noise = [];
            app.Peak_maxes = [];
            app.Prev_zeropad = 0;
            app.A0_initial = [];
            app.A1_initial = [];
            app.SNR = 0;
            app.Prev_filenames = string([]);
            app.Rows_per_load = [];
            app.Cascade_state = 0;
            app.Avg_name = string([]);
            app.X_axis_state = 0;
            app.BW_per_load = [];
            app.F0_per_load = [];
            app.Boundary2 = [];
            
            % Set slider values
            app.Slider_1.Value = 1;
            app.Slider_1.Limits = [1.0 1.1];
            
            app.Slider_2.Value = 0;
            app.Zero_order_slider_value.Value = 0;
            app.Slider_3.Value = 0;
            app.First_order_slider_value.Value = 0;
            
            app.Fig_slider_value.Value = 1;
            app.Peak_degree.Value = 0;
            app.LinewidthHz_peak.Value = 0;
            app.Zero_ppm = 0;
            app.Filepath = string([]);
            app.Channels = [];
            app.Previous_Channel_select = 1;
            app.Channel_selections = [];
            app.Filepath_subtraction = string([]);
            app.Operations = string([]);
            
            % Set number of averages back to 1
            app.NumberofAverages.Value = 1;
            
            % Clearing Plots
            plot(app.UIAxes,1,1)
            title(app.UIAxes,'MRS');
            xlabel(app.UIAxes,'MHz');
            ylabel(app.UIAxes,'A.u.');
            colorbar(app.UIAxes,'off') 
            app.PPM_axis = 0;
            app.LinewidthHzLabel.Text = 'Linewidth (Hz):';
            app.LowFrequencyNoiseLimitMHzLabel.Text = 'Low Frequency Noise Limit (MHz):';
            app.HighFrequencyNoiseLimitMHzLabel.Text = 'High Frequency Noise Limit (MHz):';
            app.XlimLowMHzEditFieldLabel.Text = 'Xlim Low (MHz)';
            app.XlimHighMHzEditFieldLabel.Text = 'Xlim High (MHz)';
            plot(app.UIAxes2,0,0)
            title(app.UIAxes2,'Cascade Plot');
            xlabel(app.UIAxes2,'MHz');
            ylabel(app.UIAxes2,'A.u.');
            
            % Clearing Text Box Area
            app.TextArea.Value = 'Data has been cleared';
            
            % Rest View FID checkbox
            app.ViewFIDCheckBox.Value = 0;
            
            % Clear SNR section variables
            app.SignaltoNoiseRatio.Value = 0;
            app.Signal_Display.Value = 0;
            app.Noise_Display.Value = 0;
            
        end

        % Value changed function: NumberofAverages
        function NumberofAveragesValueChanged(app, event)
            app.TextArea.Value = '';
            app.Peak_degree.Value = 0;
            
            % Preallocate size of matrices. Created a different
            % variable so time_domain data isn't overwritten.
            if isempty(app.Time_domain) == 0 && app.NumberofAverages.Value <= sum(app.Rows_per_load)
                Averaged_Time_domain = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,app.Columns);
                app.A0 = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                app.A1 = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                app.Linewidth = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                
                app.SNR = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                app.Noise = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                app.Peak_maxes = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                app.Peak_loc = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1);
                app.F0 = ones(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1)*app.F0(1);
                app.BW = ones(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,1)*app.BW(1);
                app.Zero_order_slider_value.Value = 0;
                app.Slider_2.Value = 0;
                app.First_order_slider_value.Value = 0;
                app.Slider_3.Value = 0;
                
                app.Slider_0_order = 0;
                app.Slider_1st_order = 0;
                
                if app.Zero_padding == 0 % set app.Spectrum to correct size depending on if zero_padding is included
                    app.Spectrum = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,app.Columns);
                else
                    app.Spectrum = zeros(sum(app.Rows_per_load)-app.NumberofAverages.Value+1,app.Columns*(app.Zero_padding+1));
                end
                
                app.Avg_name = string([]);
                [a,~] = size(app.Filepath);
                for i = 1:sum(app.Rows_per_load)-app.NumberofAverages.Value+1
                    b = num2str(i);
                    
                    if app.Previous_Channel_select == app.ChannelSelection.Value && app.NumberofAverages.Value == 1
                        
                        app.Filename_spectroscopy(i,1) = app.Filename_spectroscopy(i,1);
                        
                    elseif app.Previous_Channel_select ~= app.ChannelSelection.Value && app.NumberofAverages.Value == 1
                        app.Avg_name(i,1) = string(strcat('Channel_',num2str(app.Previous_Channel_select),'_&_','Channel_',num2str(app.ChannelSelection.Value),'_Averaging_',num2str(app.NumberofAverages.Value),'_Acq',b));
                    elseif  app.Previous_Channel_select ~= app.ChannelSelection.Value && app.NumberofAverages.Value > 1 % cases where averages are above 1 and
                        app.Avg_name(i,1) = string(strcat('Averaging_',num2str(app.NumberofAverages.Value),'_Acq',b));
                    else
                        app.Avg_name(i,1) = string(strcat('Channel_',num2str(app.ChannelSelection.Value),'_Averaging_',num2str(app.NumberofAverages.Value),'_Acq',b));
                    end
                end
                
                if sum(app.Rows_per_load) > 1 % setting the slider value based on how many files have been uploaded
                    
                    % 5/30 12:56 PM change to account for case where slider is
                    % greater than value of max for slider
                    app.Fig_slider_value.Value = 1;
                    
                    app.Acquisition_select = 1;
                    app.Slider_1.Value = 1;
                    
                    app.Slider_1.Visible = 'on';
                    app.Fig_slider_value.Visible = 'on';
                    
                    if sum(app.Rows_per_load)-app.NumberofAverages.Value+1 > 1
                        app.Slider_1.Limits = [1,sum(app.Rows_per_load)-app.NumberofAverages.Value+1];
                        app.SlideValueText.Text = 'Acquisition Value';
                    else
                        app.Slider_1.Visible = 'off';
                        app.Fig_slider_value.Visible = 'off';
                        app.Slider_1.Value = 1;
                        app.SlideValueText.Text = '';
                    end
                    
                else
                end
                
                % Calculate the averaged result
                for l = 1:sum(app.Rows_per_load)-app.NumberofAverages.Value+1
                    Averaged_Time_domain(l,:) = mean(app.Time_domain(l:app.NumberofAverages.Value+l-1,:));
                end
                app.Averaged_Time_domain = Averaged_Time_domain;
                
                % Do the calculations for all data.
                for i = 1:sum(app.Rows_per_load)-app.NumberofAverages.Value+1
                    Main_spectroscopy_processing(app,i,1);
                end
                Main_spectroscopy_processing(app);
                
                if app.ViewFIDCheckBox.Value == 0 && app.Columns1 ~=-1 && app.CascadePlotCheckBox.Value == 1 % Plot the cascade plot.
                    Waterfall(app,sum(app.Rows_per_load)-app.NumberofAverages.Value+1)
                elseif app.ViewFIDCheckBox.Value == 1 % Plot the FID
                    Waterfall(app,1,1)
                else % Plot a single noise normalized plot
                    Waterfall(app)
                end
                
                % Update phasing sliders and corresponding box
                app.Zero_order_slider_value.Value = app.A0(app.Acquisition_select);
                app.First_order_slider_value.Value = app.A1(app.Acquisition_select);
                app.Slider_2.Value = app.A0(app.Acquisition_select);
                app.Slider_3.Value = app.A1(app.Acquisition_select);
            else
                x = sprintf('The maximum value for the number of averages in the sliding window average is %d.', sum(app.Rows_per_load));
                errordlg(x)
                app.NumberofAverages.Value = 1;
                %                 app.NumberofAverages.Value = sum(app.Rows_per_load);
            end
        end

        % Value changed function: LineBroadeningHz
        function LineBroadeningHzValueChanged(app, event)
            app.TextArea.Value = '';
            app.LineBroadening = app.LineBroadeningHz.Value;
            
            if isempty(app.Time_domain) == 0
                
                if app.NumberofAverages.Value == 1
                    [A,~] = size(app.Time_domain);
                else
                    [A,~] = size(app.Averaged_Time_domain);
                end
                
                for i = 1:A
                    Main_spectroscopy_processing(app,i,1);
                end
                if app.Check_Linewidth_checkbox.Value == 1 && app.Columns1 == -1
                    app.TextArea.Value = 'Due to the different sizes of the loaded inputs, the linewidth cannot be checked for all spectra. It will only be calculated for the current selected spectra.';
                end
                Main_spectroscopy_processing(app);
                
                if app.ViewFIDCheckBox.Value == 0 && app.Columns1 ~=-1 && app.CascadePlotCheckBox.Value == 1 % Plot the cascade plot.
                    Waterfall(app,A)
                elseif app.ViewFIDCheckBox.Value == 1 % Plot the FID
                    Waterfall(app,1,1)
                else % Plot a single noise normalized plot
                    Waterfall(app)
                end
            else
            end
            
        end

        % Value changed function: ZeropaddingFactor
        function ZeropaddingFactorValueChanged(app, event)
            app.TextArea.Value = '';
            app.Peak_degree.Value = 0;
            app.Zero_padding = app.ZeropaddingFactor.Value;
            app.Check_Linewidth_checkbox.Value = 0;
            app.LinewidthHz_peak.Value = 0;
            app.Linewidth = 0;
            app.Peak_loc = [];
            app.Peak_maxes= [];
            
            if isempty(app.Time_domain) == 0
                if app.NumberofAverages.Value == 1
                    [A,~] = size(app.Time_domain);
                else
                    [A,~] = size(app.Averaged_Time_domain);
                end
                app.Spectrum = zeros(A,app.Columns*(app.Zero_padding+1));
                
                for i = 1:A
                    Main_spectroscopy_processing(app,i,1);
                end
                
                Main_spectroscopy_processing(app);
                
                if app.ViewFIDCheckBox.Value == 0 && app.Columns1 ~=-1 && app.CascadePlotCheckBox.Value == 1 % Plot the cascade plot.
                    Waterfall(app,A)
                elseif app.ViewFIDCheckBox.Value == 1 % Plot the FID
                    Waterfall(app,1,1)
                else % Plot a single noise normalized plot
                    Waterfall(app)
                end
            else
            end
        end

        % Value changed function: ZeroAllPhasesCheckBox
        function ZeroAllPhasesCheckBoxValueChanged(app, event)
            %             value = app.ZeroAllPhasesCheckBox.Value;
            
            app.Peak_degree.Value = 0;
            app.SetPhasingforAllCheckBox.Value = 0;
            app.Automatic0OrderPhaseCheckBox.Value = 0;
            
            if isempty(app.Time_domain) == 0
                
                app.A0(:) = 0;
                app.A1(:) = 0;
                %
                app.Slider_0_order = 0;
                app.Slider_1st_order = 0;
                
                % Set sliders and their numeric markers to 0;
                app.Slider_2.Value = 0;
                app.Zero_order_slider_value.Value = 0;
                app.Slider_3.Value = 0;
                app.First_order_slider_value.Value = 0;
                
                for i = 1:sum(app.Rows_per_load)-app.NumberofAverages.Value+1
                    Main_spectroscopy_processing(app,i);
                end
                Main_spectroscopy_processing(app);
                if app.ViewFIDCheckBox.Value == 0 && app.CascadePlotCheckBox.Value == 0
                    Waterfall(app)
                elseif app.CascadePlotCheckBox.Value == 1
                    Waterfall(app,sum(app.Rows_per_load)-app.NumberofAverages.Value+1)
                else
                    Waterfall(app,1,1)
                end
                
                pause(0.1);
                app.ZeroAllPhasesCheckBox.Value = 0;
                app.TextArea.Value = 'All spectral phasing has been reset to 0.';
            else
                app.ZeroAllPhasesCheckBox.Value = 0;
                app.TextArea.Value = '';
            end
        end

        % Value changed function: CascadePlotCheckBox
        function CascadePlotCheckBoxValueChanged(app, event)
            if isempty(app.Time_domain) == 0 && app.CascadePlotCheckBox.Value == 1
                
                % Plot with the cascade style plot
                Waterfall(app,sum(app.Rows_per_load)-app.NumberofAverages.Value+1)
                
                pause(0.1);
                app.ViewFIDCheckBox.Value = 0;
            elseif isempty(app.Time_domain) == 0 && app.CascadePlotCheckBox.Value == 0
                app.ZeroAllPhasesCheckBox.Value = 0;
                app.TextArea.Value = '';
                Waterfall(app)
            else
            end
        end

        % Value changed function: SetPhasingforAllCheckBox
        function SetPhasingforAllCheckBoxValueChanged(app, event)
            
            app.Peak_degree.Value = 0;
            app.Automatic0OrderPhaseCheckBox.Value = 0;
            
            if  isempty(app.Time_domain) == 0
                if app.SetPhasingforAllCheckBox.Value == 1 && app.NumberofAverages.Value == 1
                    [A,~] = size(app.Time_domain);
                else
                    [A,~] = size(app.Averaged_Time_domain);
                end
                
                app.A0(:) = app.A0(app.Acquisition_select);
                app.A1(:) = app.A1(app.Acquisition_select);
                
                if app.SetPhasingforAllCheckBox.Value == 1
                    for i = 1:A
                        Main_spectroscopy_processing(app,i,1);
                    end
                else
                end
                
                Main_spectroscopy_processing(app);
                if app.ViewFIDCheckBox.Value == 0 && app.CascadePlotCheckBox.Value == 0
                    Waterfall(app)
                elseif app.CascadePlotCheckBox.Value == 1
                    Waterfall(app,sum(app.Rows_per_load)-app.NumberofAverages.Value+1)
                else
                    Waterfall(app,1,1)
                end
                
                app.TextArea.Value = 'Phasing for all spectra have been set according to the current parameters';
            else
                app.TextArea.Value = '';
            end
            app.SetPhasingforAllCheckBox.Value = 0;
        end

        % Value changed function: Automatic0OrderPhaseCheckBox
        function Automatic0OrderPhaseCheckBoxValueChanged(app, event)
            app.TextArea.Value = '';
            app.Peak_degree.Value = 0;
            app.SetPhasingforAllCheckBox.Value = 0;
            
            if isempty(app.Time_domain) == 0
                
                Main_spectroscopy_processing(app);
                if app.ViewFIDCheckBox.Value == 0 && app.CascadePlotCheckBox.Value == 0
                    Waterfall(app)
                elseif app.CascadePlotCheckBox.Value == 1
                    Waterfall(app,sum(app.Rows_per_load)-app.NumberofAverages.Value+1)
                else
                    Waterfall(app,1,1)
                end
                
                % Set the values of the sliders and slider text boxes based on values of the different phasing for the current selection
                app.Slider_0_order = app.A0(app.Acquisition_select);
                app.Slider_1st_order = app.A1(app.Acquisition_select);
                
                app.Slider_2.Value = app.A0(app.Acquisition_select);
                app.Slider_3.Value = app.A1(app.Acquisition_select);
                
                app.Zero_order_slider_value.Value = app.A0(app.Acquisition_select);
                
                % Reset the limits if the limits are too small for the 1st-order phasing
                Slider_limits = app.First_order_slider_value.Limits;
                if Slider_limits(1) < app.Slider_1st_order && Slider_limits(2) > app.Slider_1st_order
                else
                    app.First_order_slider_value.Limits = [-abs(app.Slider_1st_order) abs(app.Slider_1st_order)];
                end
                
                app.First_order_slider_value.Value = app.A1(app.Acquisition_select);
            else
            end
        end

        % Value changed function: BaselineCorrectionCheckBox
        function BaselineCorrectionCheckBoxValueChanged(app, event)
            app.TextArea.Value = '';
            app.Peak_degree.Value = 0;
            app.Baseline_correction = app.BaselineCorrectionCheckBox.Value;
            if isempty(app.Time_domain) == 0
                Main_spectroscopy_processing(app);
                
                if app.ViewFIDCheckBox.Value == 0 && app.CascadePlotCheckBox.Value == 0
                    Waterfall(app)
                elseif app.CascadePlotCheckBox.Value == 1
                    Waterfall(app,sum(app.Rows_per_load)-app.NumberofAverages.Value+1)
                else
                    Waterfall(app,1,1)
                end
            end
            
        end

        % Value changed function: Slider_1
        function Slider_1ValueChanged(app, event)
            app.TextArea.Value = '';
            app.Acquisition_select = round(app.Slider_1.Value);
            app.Slider_1.Value = app.Acquisition_select;
            app.Peak_degree.Value = 0;
            app.Fig_slider_value.Value = app.Acquisition_select;
            
            % If loaded data has different lengths, the linewidth will be
            % reset between viewing of different data
            if app.Columns1 == -1
                app.LinewidthHz_peak.Value = 0;
                app.Check_Linewidth_checkbox.Value = 0;
            elseif app.Check_Linewidth_checkbox.Value == 1
                app.LinewidthHz_peak.Value = app.Linewidth(app.Acquisition_select);
            else
            end
            
            % If data is loaded, 0-order and 1st-order data is grabbed and
            % inputted into slider values
            if isempty(app.Time_domain) == 0
                app.Slider_0_order = app.A0(app.Acquisition_select);
                app.Slider_1st_order = app.A1(app.Acquisition_select);
                app.Zero_order_slider_value.Value = app.A0(app.Acquisition_select);
                app.First_order_slider_value.Value = app.A1(app.Acquisition_select);
                app.Slider_2.Value = app.A0(app.Acquisition_select);
                app.Slider_3.Value = app.A1(app.Acquisition_select);
                
                app.Automatic0OrderPhaseCheckBox.Value = 0;
                
                faxis_prior = app.Faxis;
                
                if app.NumberofAverages.Value ~= 1
                    time_domain_1 = app.Averaged_Time_domain(app.Acquisition_select,:);
                elseif app.Columns1 ~= -1
                    time_domain_1 = app.Time_domain(app.Acquisition_select,:);
                else
                    time_domain_1 = app.Matrix_Time_domain{app.Acquisition_select,1};
                end
                
                x_len = length(time_domain_1)/2;
                if app.PPM_axis == 0  % Case where axis will be shown in MHz
                    app.Faxis = (linspace(-app.BW(app.Acquisition_select)/2,app.BW(app.Acquisition_select)/2,x_len*2)+app.F0(app.Acquisition_select)*10^6)/(10^6);
                elseif app.PPM_axis == 1 % Case where axis will be shown in ppm
                    app.Faxis = linspace(-app.BW(app.Acquisition_select)/2,app.BW(app.Acquisition_select)/2,x_len*2)/(app.F0(app.Acquisition_select));
                else
                    app.Faxis = (linspace(-app.BW(app.Acquisition_select)/2,app.BW(app.Acquisition_select)/2,x_len*2)/(app.F0(app.Acquisition_select)))-app.Zero_ppm;
                end
                
                if isequal(faxis_prior,app.Faxis) == 1
                    % Don't update the xlim axis on UIAxes_31P
                    
                else
                    
                    % The x-axis has been changed and needs the viewing
                    % window range changed on the x-axis.
                    app.XlimLowMHzEditField.Value = min(app.Faxis);
                    app.XlimHighMHzEditField.Value = max(app.Faxis);
                    
                    if app.PPM_axis == 1
                        % Section for ppm
                        app.LeftNoiseLimit.Limits = [-(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select)) (app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select))];
                        app.RightNoiseLimit.Limits = [-(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select)) (app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select))];
                        
                        app.LeftNoiseLimit.Value = -(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select));
                        app.RightNoiseLimit.Value = (-app.BW(app.Acquisition_select)/2+(app.BW(app.Acquisition_select)/4))/(app.F0(app.Acquisition_select));
                        
                    elseif app.PPM_axis == 0
                        % Section for MHz
                        BW2 = app.BW(app.Acquisition_select)/(10^6);
                        app.LeftNoiseLimit.Limits = [app.F0(app.Acquisition_select)-(BW2/2) app.F0(app.Acquisition_select)+(BW2/2)];
                        app.RightNoiseLimit.Limits = [app.F0(app.Acquisition_select)-(BW2/2) app.F0(app.Acquisition_select)+(BW2/2)];
                        
                        app.LeftNoiseLimit.Value = app.F0(app.Acquisition_select)-(BW2/2);
                        app.RightNoiseLimit.Value = app.F0(app.Acquisition_select)-(BW2/4);
                    else
                        app.LeftNoiseLimit.Limits = [(-(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select)))-app.Zero_ppm ((app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select)))-app.Zero_ppm];
                        app.RightNoiseLimit.Limits = [(-(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select)))-app.Zero_ppm ((app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select)))-app.Zero_ppm];
                        
                        app.LeftNoiseLimit.Value = (-(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select)))-app.Zero_ppm;
                        app.RightNoiseLimit.Value = ((-app.BW(app.Acquisition_select)/2+(app.BW(app.Acquisition_select)/4))/(app.F0(app.Acquisition_select)))-app.Zero_ppm;
                    end
                end
                
                Main_spectroscopy_processing(app)
                
                if app.ViewFIDCheckBox.Value == 0 && app.CascadePlotCheckBox.Value == 0
                    Waterfall(app)
                elseif app.ViewFIDCheckBox.Value == 1
                    Waterfall(app,1,1)
                else
                end
            else
            end
            
        end

        % Value changed function: Fig_slider_value
        function Fig_slider_valueValueChanged(app, event)
            app.TextArea.Value = '';
            if round(app.Fig_slider_value.Value) > app.Slider_1.Limits(2)
                app.Fig_slider_value.Value = app.Slider_1.Limits(2);
            elseif round(app.Fig_slider_value.Value) < 1
                app.Fig_slider_value.Value = 1;
            end
            
            app.Slider_1.Value = round(app.Fig_slider_value.Value);
            app.Acquisition_select = app.Slider_1.Value;
            app.Peak_degree.Value = 0;
            app.Fig_slider_value.Value = app.Acquisition_select;
            
            % If loaded data has different lengths, the linewidth will be
            % reset between viewing of different data
            if app.Columns1 == -1
                app.LinewidthHz_peak.Value = 0;
                
                app.Check_Linewidth_checkbox.Value = 0;
            end
            
            if isempty(app.Time_domain) == 0
                app.Slider_0_order = app.A0(app.Acquisition_select);
                app.Slider_1st_order = app.A1(app.Acquisition_select);
                
                app.Zero_order_slider_value.Value = app.A0(app.Acquisition_select);
                app.First_order_slider_value.Value = app.A1(app.Acquisition_select);
                app.Slider_2.Value = app.A0(app.Acquisition_select);
                app.Slider_3.Value = app.A1(app.Acquisition_select);
                
                app.Automatic0OrderPhaseCheckBox.Value = 0;
                faxis_prior = app.Faxis;
                %                 Main_spectroscopy_processing(app);
                if app.NumberofAverages.Value ~= 1
                    time_domain_1 = app.Averaged_Time_domain(app.Acquisition_select,:);
                elseif app.Columns1 ~= -1
                    time_domain_1 = app.Time_domain(app.Acquisition_select,:);
                else
                    time_domain_1 = app.Matrix_Time_domain{app.Acquisition_select,1};
                end
                
                x_len = length(time_domain_1)/2;
                if app.PPM_axis == 0  % Case where axis will be shown in MHz
                    app.Faxis = (linspace(-app.BW(app.Acquisition_select)/2,app.BW(app.Acquisition_select)/2,x_len*2)+app.F0(app.Acquisition_select)*10^6)/(10^6);
                    
                elseif app.PPM_axis == 1 % Case where axis will be shown in ppm
                    app.Faxis = linspace(-app.BW(app.Acquisition_select)/2,app.BW(app.Acquisition_select)/2,x_len*2)/(app.F0(app.Acquisition_select));
                    
                else
                    app.Faxis = (linspace(-app.BW(app.Acquisition_select)/2,app.BW(app.Acquisition_select)/2,x_len*2)/(app.F0(app.Acquisition_select)))-app.Zero_ppm;
                    
                end
                
                if isequal(faxis_prior,app.Faxis) == 1
                    % Don't update the xlim axis on UIAxes_31P
                else
                    
                    % The x-axis has been changed and needs the viewing
                    % window range changed on the x-axis.
                    app.XlimLowMHzEditField.Value = min(app.Faxis);
                    app.XlimHighMHzEditField.Value = max(app.Faxis);
                    
                    if app.PPM_axis == 1
                        % Section for ppm
                        app.LeftNoiseLimit.Limits = [-(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select)) (app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select))];
                        app.RightNoiseLimit.Limits = [-(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select)) (app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select))];
                        
                        app.LeftNoiseLimit.Value = -(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select));
                        app.RightNoiseLimit.Value = (-app.BW(app.Acquisition_select)/2+(app.BW(app.Acquisition_select)/4))/(app.F0(app.Acquisition_select));
                    elseif app.PPM_axis == 0
                        % Section for MHz
                        BW2 = app.BW(app.Acquisition_select)/(10^6);
                        app.LeftNoiseLimit.Limits = [app.F0(app.Acquisition_select)-(BW2/2) app.F0(app.Acquisition_select)+(BW2/2)];
                        app.RightNoiseLimit.Limits = [app.F0(app.Acquisition_select)-(BW2/2) app.F0(app.Acquisition_select)+(BW2/2)];
                        
                        app.LeftNoiseLimit.Value = app.F0(app.Acquisition_select)-(BW2/2);
                        app.RightNoiseLimit.Value = app.F0(app.Acquisition_select)-(BW2/4);
                    else
                        app.LeftNoiseLimit.Limits = [(-(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select)))-app.Zero_ppm ((app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select)))-app.Zero_ppm];
                        app.RightNoiseLimit.Limits = [(-(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select)))-app.Zero_ppm ((app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select)))-app.Zero_ppm];
                        
                        app.LeftNoiseLimit.Value = (-(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select)))-app.Zero_ppm;
                        app.RightNoiseLimit.Value = ((-app.BW(app.Acquisition_select)/2+(app.BW(app.Acquisition_select)/4))/(app.F0(app.Acquisition_select)))-app.Zero_ppm;
                    end
                end
                
                Main_spectroscopy_processing(app)
                xlim(app.UIAxes,[app.XlimLowMHzEditField.Value app.XlimHighMHzEditField.Value]);
                app.LinewidthHz_peak.Value = app.Linewidth(app.Acquisition_select);
                if app.ViewFIDCheckBox.Value == 0 && app.CascadePlotCheckBox.Value == 0
                    Waterfall(app)
                elseif app.ViewFIDCheckBox.Value == 1
                    Waterfall(app,1,1)
                else
                end
            else
            end
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.TextArea.Value = '';
            app.Slider_0_order = round(app.Slider_2.Value,3);
            app.Peak_degree.Value = 0;
            app.SetPhasingforAllCheckBox.Value = 0;
            app.Zero_order_slider_value.Value = app.Slider_0_order;
            app.Linewidth(app.Acquisition_select) = 0;
            app.LinewidthHz_peak.Value = app.Linewidth(app.Acquisition_select);
            if isempty(app.Time_domain) == 0
                Main_spectroscopy_processing(app);
                if app.ViewFIDCheckBox.Value == 0 && app.CascadePlotCheckBox.Value == 0
                    Waterfall(app)
                elseif app.CascadePlotCheckBox.Value == 1
                    Waterfall(app,sum(app.Rows_per_load)-app.NumberofAverages.Value+1)
                else
                    Waterfall(app,1,1)
                end
                
                app.Automatic0OrderPhaseCheckBox.Value = 0;
            else
            end
        end

        % Value changed function: Zero_order_slider_value
        function Zero_order_slider_valueValueChanged(app, event)
            app.TextArea.Value = '';
            app.Peak_degree.Value = 0;
            app.SetPhasingforAllCheckBox.Value = 0;
            app.Slider_0_order = app.Zero_order_slider_value.Value;
            app.Slider_2.Value = app.Zero_order_slider_value.Value;
            
            app.Linewidth(app.Acquisition_select) = 0;
            app.LinewidthHz_peak.Value = app.Linewidth(app.Acquisition_select);
            
            if isempty(app.Time_domain) == 0
                Main_spectroscopy_processing(app);
                if app.ViewFIDCheckBox.Value == 0 && app.CascadePlotCheckBox.Value == 0
                    Waterfall(app)
                elseif app.CascadePlotCheckBox.Value == 1
                    Waterfall(app,sum(app.Rows_per_load)-app.NumberofAverages.Value+1)
                else
                    Waterfall(app,1,1)
                end
                
                app.Automatic0OrderPhaseCheckBox.Value = 0;
            else
            end
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            app.TextArea.Value = '';
            app.First_order_slider_value.Value = round(app.Slider_3.Value,3);
            app.Slider_1st_order = app.First_order_slider_value.Value;
            app.Peak_degree.Value = 0;
            app.SetPhasingforAllCheckBox.Value = 0;
            app.Linewidth(app.Acquisition_select) = 0;
            app.LinewidthHz_peak.Value = app.Linewidth(app.Acquisition_select);
            
            if isempty(app.Time_domain) == 0
                Main_spectroscopy_processing(app);
                if app.ViewFIDCheckBox.Value == 0 && app.CascadePlotCheckBox.Value == 0
                    Waterfall(app)
                elseif app.CascadePlotCheckBox.Value == 1
                    Waterfall(app,sum(app.Rows_per_load)-app.NumberofAverages.Value+1)
                else
                    Waterfall(app,1,1)
                end
                app.Automatic0OrderPhaseCheckBox.Value = 0;
            else
            end
        end

        % Value changed function: First_order_slider_value
        function First_order_slider_valueValueChanged(app, event)
            app.TextArea.Value = '';
            app.Peak_degree.Value = 0;
            app.SetPhasingforAllCheckBox.Value = 0;
            
            if app.First_order_slider_value.Value > app.Slider_3.Limits(2)
                app.Fig_slider_value.Value = app.Slider_1.Limits(2);
            elseif app.First_order_slider_value.Value < app.Slider_3.Limits(1)
                app.Fig_slider_value.Value = app.Slider_1.Limits(1);
            else
            end
            
            app.Slider_1st_order = app.First_order_slider_value.Value;
            app.Slider_3.Value = app.First_order_slider_value.Value;
            app.Linewidth(app.Acquisition_select) = 0;
            app.LinewidthHz_peak.Value = app.Linewidth(app.Acquisition_select);
            
            if isempty(app.Time_domain) == 0
                Main_spectroscopy_processing(app);
                if app.ViewFIDCheckBox.Value == 0 && app.CascadePlotCheckBox.Value == 0
                    Waterfall(app)
                elseif app.CascadePlotCheckBox.Value == 1
                    Waterfall(app,sum(app.Rows_per_load)-app.NumberofAverages.Value+1)
                else
                    Waterfall(app,1,1)
                end
                app.Automatic0OrderPhaseCheckBox.Value = 0;
            else
            end
        end

        % Button pushed function: PeakphaseselectionButton
        function PeakphaseselectionButtonPushed(app, event)
            app.TextArea.Value = '';
            if isempty(app.Time_domain) == 0
                if app.Columns1 == -1
                    Region_selection(app,app.Faxis,app.Matrix_Spectrum{app.Acquisition_select},0,1,1);
                else
                    Region_selection(app,app.Faxis,app.Spectrum(app.Acquisition_select,:),0,1,1);
                end
            else
            end
        end

        % Value changed function: Check_Linewidth_checkbox
        function Check_Linewidth_checkboxValueChanged(app, event)
            app.TextArea.Value = '';
            % app.Check_Linewidth_checkbox.Value;
            app.Peak_degree.Value = 0;
            
            if isempty(app.Time_domain) == 0 && app.Check_Linewidth_checkbox.Value == 1
                if app.NumberofAverages.Value == 1
                    [A,~] = size(app.Time_domain);
                else
                    [A,~] = size(app.Averaged_Time_domain);
                end
                
                
                T = 1; % Number of peak selection(s) 
                app.Linewidth = zeros(A,T);
                
                for j = 1:T
                    if app.Columns1 == -1
                        [Boundary_zone] = Region_selection(app,app.Faxis,(app.Matrix_Spectrum{app.Acquisition_select}),0,1);
                        Boundary_zoning{j} = Boundary_zone;
                    else
                        [Boundary_zone] = Region_selection(app,app.Faxis,(app.Spectrum(app.Acquisition_select,:)),0,1);
                        Boundary_zoning{j} = Boundary_zone;
                    end
                end
                app.Boundary_zone = Boundary_zoning;
                
                if app.Columns1 == -1
                    app.TextArea.Value = 'The linewidth will only be checked for this one spectrum due to the different data sizes of loaded spectrum';
                else
                    for i = 1:A
                        Main_spectroscopy_processing(app,i,1);
                    end
                end
                Main_spectroscopy_processing(app);
                
            elseif isempty(app.Time_domain) == 0
                app.Check_Linewidth_checkbox.Value = 0;
                app.Peak_loc = [];
                app.TextArea.Value = 'The maximum value within the spectra will be used for linewidth and SNR measurements';
                Main_spectroscopy_processing(app);
            else
                app.Check_Linewidth_checkbox.Value = 0;
                app.Peak_loc = [];
            end
            
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = round(event.Value,3);
            app.Slider_0_order = changingValue;
            app.Peak_degree.Value = 0;
            app.SetPhasingforAllCheckBox.Value = 0;
            app.Zero_order_slider_value.Value = app.Slider_0_order;
            if isempty(app.Time_domain) == 0
                Main_spectroscopy_processing(app);
                %                 Waterfall(app)
                app.Automatic0OrderPhaseCheckBox.Value = 0;
            else
            end
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            changingValue = round(event.Value,3);
            
            app.First_order_slider_value.Value = changingValue;
            app.Peak_degree.Value = 0;
            app.SetPhasingforAllCheckBox.Value = 0;
            app.Slider_1st_order = changingValue;
            
            if isempty(app.Time_domain) == 0
                Main_spectroscopy_processing(app);
                %                 Waterfall(app)
                app.Automatic0OrderPhaseCheckBox.Value = 0;
            else
            end
        end

        % Value changed function: ViewFIDCheckBox
        function ViewFIDCheckBoxValueChanged(app, event)
            app.TextArea.Value = '';
            value = app.ViewFIDCheckBox.Value;
            app.Peak_degree.Value = 0;
            
            if isempty(app.Time_domain) == 0 && value == 1
                Main_spectroscopy_processing(app)
                Waterfall(app,1,value)
                app.CascadePlotCheckBox.Value = 0;
                
            elseif isempty(app.Time_domain) == 0 && value == 0
                Main_spectroscopy_processing(app)
                Waterfall(app)
            else
            end
        end

        % Value changed function: LeftNoiseLimit
        function LeftNoiseLimitValueChanged(app, event)
            app.TextArea.Value = '';
            app.Peak_degree.Value = 0;
            % if the noise selection limits are in the correct numerical
            % order (Low frequency < high frequency noise limit) and time_domain
            % is not empty
            if isempty(app.Time_domain) == 0 && app.LeftNoiseLimit.Value < app.RightNoiseLimit.Value && app.RightNoiseLimit.Value < max(app.Faxis) && app.LeftNoiseLimit.Value < max(app.Faxis)
                Main_spectroscopy_processing(app)
                
                if app.ViewFIDCheckBox.Value == 0
                    Waterfall(app)
                else
                    Waterfall(app,1,1)
                end
                % If time_domain is not empty and the Low frequency noise limit has been selected higher than the high frequency noise limit
            elseif isempty(app.Time_domain) == 0 && app.LeftNoiseLimit.Value >= app.RightNoiseLimit.Value
                
                errordlg('The Left Noise Limit must be smaller than the Right Noise Limit');
                BW2 = app.BW(app.Acquisition_select)/(10^6);
                app.LeftNoiseLimit.Value = app.F0(app.Acquisition_select)-(BW2/2);
                app.RightNoiseLimit.Value = app.F0(app.Acquisition_select)-(BW2/2)+(BW2/4);
            else
            end


        end

        % Value changed function: RightNoiseLimit
        function RightNoiseLimitValueChanged(app, event)
            app.TextArea.Value = '';
            app.Peak_degree.Value = 0;
            
            if isempty(app.Time_domain) == 0 && app.LeftNoiseLimit.Value < app.RightNoiseLimit.Value && app.RightNoiseLimit.Value < max(app.Faxis) && app.LeftNoiseLimit.Value < max(app.Faxis)
                Main_spectroscopy_processing(app)
                
                if app.ViewFIDCheckBox.Value == 0
                    Waterfall(app)
                else
                    Waterfall(app,1,1)
                end
            elseif isempty(app.Time_domain) == 0 && app.LeftNoiseLimit.Value >= app.RightNoiseLimit.Value
                errordlg('The Left Noise Limit must be smaller than the Right Noise Limit');
                BW2 = app.BW(app.Acquisition_select)/(10^6);
                
                app.LeftNoiseLimit.Value = app.F0(app.Acquisition_select)-(BW2/2);
                app.RightNoiseLimit.Value = app.F0(app.Acquisition_select)-(BW2/2)+(BW2/4);
            elseif isempty(app.Time_domain) == 0 && app.RightNoiseLimit.Value > max(app.Faxis)
                
                x = sprintf('The Right Noise Limit must be less than %0.2f', max(app.Faxis));
                errordlg(x)
                app.RightNoiseLimit.Value = max(app.Faxis);
            else
            end
        end

        % Value changed function: XlimLowMHzEditField
        function XlimLowMHzEditFieldValueChanged(app, event)
            %             value = app.XlimLowMHzEditField.Value;
            
            if app.XlimLowMHzEditField.Value < app.XlimHighMHzEditField.Value  % If Xlim lower bound is less than the right bound, process.
                
            else % Reset the boundary to faxis minimum value
                app.XlimLowMHzEditField.Value = min(app.Faxis);
                
            end
            
            if isempty(app.Time_domain) == 0
                Main_spectroscopy_processing(app)
                if app.CascadePlotCheckBox.Value == 1 || app.Cascade_state == 1
                    ylim(app.UIAxes2,[app.XlimLowMHzEditField.Value app.XlimHighMHzEditField.Value]);
                    xlim(app.UIAxes2,'auto')
                    %                     xlim(app.UIAxes2,[minvalue - 0.05*maxvalue, maxvalue+0.05*maxvalue])
                elseif app.Cascade_state == 0
                    xlim(app.UIAxes2,[app.XlimLowMHzEditField.Value app.XlimHighMHzEditField.Value]);
                    ylim(app.UIAxes2,'auto')
                    %                     ylim(app.UIAxes2,[minvalue - 0.05*maxvalue, maxvalue+0.05*maxvalue])
                else
                end
            else
                xlim(app.UIAxes,[app.XlimLowMHzEditField.Value app.XlimHighMHzEditField.Value]);
            end
        end

        % Value changed function: XlimHighMHzEditField
        function XlimHighMHzEditFieldValueChanged(app, event)
            %             value = app.XlimHighMHzEditField.Value;
            
            
            if app.XlimHighMHzEditField.Value > app.XlimLowMHzEditField.Value  % If Xlim right bound is greater than the left bound, process.
            else % Reset the boundary to faxis maximum
                app.XlimHighMHzEditField.Value = max(app.Faxis);
                
            end
            
            if isempty(app.Time_domain) == 0
                Main_spectroscopy_processing(app)
                if app.CascadePlotCheckBox.Value == 1 || app.Cascade_state == 1
                    ylim(app.UIAxes2,[app.XlimLowMHzEditField.Value app.XlimHighMHzEditField.Value]);
                    xlim(app.UIAxes2,'auto')
                elseif app.Cascade_state == 0
                    xlim(app.UIAxes2,[app.XlimLowMHzEditField.Value app.XlimHighMHzEditField.Value]);
                    ylim(app.UIAxes2,'auto')
                else
                end
            else
                xlim(app.UIAxes,[app.XlimLowMHzEditField.Value app.XlimHighMHzEditField.Value]);
            end
        end

        % Menu selected function: XAxisinMHzMenu
        function XAxisinMHzMenuSelected(app, event)
            % Set front panel text to that of the appropriate units
            app.LowFrequencyNoiseLimitMHzLabel.Text = 'Low Frequency Noise Limit (MHz):';
            app.HighFrequencyNoiseLimitMHzLabel.Text = 'High Frequency Noise Limit (MHz):';
            app.LinewidthHzLabel.Text = 'Linewidth (Hz):';
            xlabel(app.UIAxes,'Frequency (MHz)');
            xlabel(app.UIAxes2,'Frequency (MHz)');
            app.XlimLowMHzEditFieldLabel.Text = 'Xlim Low (MHz)';
            app.XlimHighMHzEditFieldLabel.Text = 'Xlim High (MHz)';
            
            % Check to see if data has already been loaded in
            if isempty(app.Time_domain) == 0
                app.PPM_axis = 0;
                % Redefine limits for MHz units and set values within those
                % limits
                BW2 = app.BW(app.Acquisition_select)/(10^6);
                app.LeftNoiseLimit.Limits = [app.F0(app.Acquisition_select)-(BW2/2) app.F0(app.Acquisition_select)+(BW2/2)];
                app.RightNoiseLimit.Limits = [app.F0(app.Acquisition_select)-(BW2/2) app.F0(app.Acquisition_select)+(BW2/2)];
                app.LeftNoiseLimit.Value = app.F0(app.Acquisition_select)-(BW2/2);
                app.RightNoiseLimit.Value = app.F0(app.Acquisition_select)-(BW2/4);
                
                % Provide a notice if loaded files have different number of
                % points
                if app.Check_Linewidth_checkbox.Value == 1 && app.Columns1 == -1
                    app.TextArea.Value = 'Due to the different sizes of the loaded inputs, the linewidth cannot be checked for all spectra. It will only be calculated for the current selected spectra.';
                end
                
                Main_spectroscopy_processing(app);
                
                % Change the xlimits and xlimits range values
                if app.X_axis_state == 1 % Changing from 0 ppm centered in the middle to MHz
                    low = (app.XlimLowMHzEditField.Value*app.F0(app.Acquisition_select)+(app.F0(app.Acquisition_select)*10^6))/(10^6);
                    high = (app.XlimHighMHzEditField.Value*app.F0(app.Acquisition_select)+(app.F0(app.Acquisition_select)*10^6))/(10^6);
                    
                    xlim(app.UIAxes,[low high]);
                    app.XlimLowMHzEditField.Value = low;
                    app.XlimHighMHzEditField.Value = high;
                    
                elseif app.X_axis_state == 2 % Changing from 0 ppm user-defined to MHz
                    low = ((app.XlimLowMHzEditField.Value+app.Zero_ppm)*app.F0(app.Acquisition_select)+(app.F0(app.Acquisition_select)*10^6))/(10^6);
                    high = ((app.XlimHighMHzEditField.Value+app.Zero_ppm)*app.F0(app.Acquisition_select)+(app.F0(app.Acquisition_select)*10^6))/(10^6);
                    xlim(app.UIAxes,[low high]);
                    app.XlimLowMHzEditField.Value = low;
                    app.XlimHighMHzEditField.Value = high;
                else
                    low = (app.XlimLowMHzEditField.Value);
                    high = (app.XlimHighMHzEditField.Value);
                    xlim(app.UIAxes,[low high]);
                    app.XlimLowMHzEditField.Value = low;
                    app.XlimHighMHzEditField.Value = high;
                end
                
                % Determine the type of plot for the rightmost graph based
                % on checkbox settings
                if app.ViewFIDCheckBox.Value == 0 && app.CascadePlotCheckBox.Value == 1
                    Waterfall(app,A)
                elseif app.ViewFIDCheckBox.Value == 0 && app.CascadePlotCheckBox.Value == 0
                    Waterfall(app)
                else
                    Waterfall(app,1,1)
                end
                
            else
            end
            app.X_axis_state = 0;
        end

        % Menu selected function: ppmcenteredMenu
        function ppmcenteredMenuSelected(app, event)
            % Set front panel text to that of the appropriate units
            app.LowFrequencyNoiseLimitMHzLabel.Text = 'Low Frequency Noise Limit (ppm):';
            app.HighFrequencyNoiseLimitMHzLabel.Text = 'High Frequency Noise Limit (ppm):';
            app.LinewidthHzLabel.Text = 'Linewidth (ppm):';
            xlabel(app.UIAxes,'Frequency (ppm)');
            xlabel(app.UIAxes2,'Frequency (ppm)');
            app.XlimLowMHzEditFieldLabel.Text = 'Xlim Low (ppm)';
            app.XlimHighMHzEditFieldLabel.Text = 'Xlim High (ppm)';
            
            % Check to see if data has already been loaded in
            if isempty(app.Time_domain) == 0
                app.PPM_axis = 1;
                % Redefine limits for ppm units and set values within those
                % limits
                app.LeftNoiseLimit.Limits = [-(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select)) (app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select))];
                app.RightNoiseLimit.Limits = [-(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select)) (app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select))];
                
                app.LeftNoiseLimit.Value = -(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select));
                app.RightNoiseLimit.Value = (-app.BW(app.Acquisition_select)/2+(app.BW(app.Acquisition_select)/4))/(app.F0(app.Acquisition_select));
                
                % Provide a notice if loaded files have different number of
                % points
                if app.Check_Linewidth_checkbox.Value == 1 && app.Columns1 == -1
                    app.TextArea.Value = 'Due to the different sizes of the loaded inputs, the linewidth cannot be checked for all spectra. It will only be calculated for the current selected spectra.';
                end
                Main_spectroscopy_processing(app);
                
                % Change the xlimits and xlimits range values
                if app.X_axis_state == 0 % Changing from Mhz to 0 ppm centered in the middle
                    low = ((app.XlimLowMHzEditField.Value*10^6)-(app.F0(app.Acquisition_select)*10^6))/(app.F0(app.Acquisition_select));
                    high = ((app.XlimHighMHzEditField.Value*10^6)-(app.F0(app.Acquisition_select)*10^6))/(app.F0(app.Acquisition_select));
                    xlim(app.UIAxes,[low high]);
                    app.XlimLowMHzEditField.Value = low;
                    app.XlimHighMHzEditField.Value = high;
                    
                elseif app.X_axis_state == 2 % Changing from 0 ppm user-defined to 0 ppm centered
                    low = app.XlimLowMHzEditField.Value+app.Zero_ppm;
                    high = app.XlimHighMHzEditField.Value+app.Zero_ppm;
                    xlim(app.UIAxes,[low high]);
                    app.XlimLowMHzEditField.Value = low;
                    app.XlimHighMHzEditField.Value = high;
                else
                    low = app.XlimLowMHzEditField.Value;
                    high = app.XlimHighMHzEditField.Value;
                    xlim(app.UIAxes,[low high]);
                    app.XlimLowMHzEditField.Value = low;
                    app.XlimHighMHzEditField.Value = high;
                end
                
                % Determine the type of plot for the rightmost graph based
                % on checkbox settings
                if app.ViewFIDCheckBox.Value == 0 && app.CascadePlotCheckBox.Value == 1
                    Waterfall(app,A)
                elseif app.ViewFIDCheckBox.Value == 0 && app.CascadePlotCheckBox.Value == 0
                    Waterfall(app)
                else
                    Waterfall(app,1,1)
                end
                
            else
            end
            app.X_axis_state = 1;
        end

        % Menu selected function: Userdefined0ppmMenu
        function Userdefined0ppmMenuSelected(app, event)
            
            % Set front panel text to that of the appropriate units
            app.LowFrequencyNoiseLimitMHzLabel.Text = 'Low Frequency Noise Limit (ppm):';
            app.HighFrequencyNoiseLimitMHzLabel.Text = 'High Frequency Noise Limit (ppm):';
            app.LinewidthHzLabel.Text = 'Linewidth (ppm):';
            xlabel(app.UIAxes,'Frequency (ppm)');
            xlabel(app.UIAxes2,'Frequency (ppm)');
            app.XlimLowMHzEditFieldLabel.Text = 'Xlim Low (ppm)';
            app.XlimHighMHzEditFieldLabel.Text = 'Xlim High (ppm)';
            
            % Check to see if data has already been loaded in
            if isempty(app.Time_domain) == 0
                
                app.PPM_axis = app.X_axis_state;
                
                % Provide a notice if loaded files have different number of
                % points
                if app.Check_Linewidth_checkbox.Value == 1 && app.Columns1 == -1
                    app.TextArea.Value = 'Due to the different sizes of the loaded inputs, the linewidth cannot be checked for all spectra. It will only be calculated for the current selected spectra.';
                end
                
                %                 Main_spectroscopy_processing(app);
                
                
                prev_zero_ppm = app.Zero_ppm;
                if app.Columns1 == -1
                    [app.Zero_ppm] = Region_selection(app,app.Faxis,(app.Matrix_Spectrum{app.Acquisition_select}),1,1);
                else
                    [app.Zero_ppm] = Region_selection(app,app.Faxis,(app.Spectrum(app.Acquisition_select,:)),1,1);
                end
                
                if app.X_axis_state == 0
                    app.Zero_ppm = ((app.Zero_ppm*10^6)-(app.F0(app.Acquisition_select)*10^6))/(app.F0(app.Acquisition_select));
                elseif app.X_axis_state == 2
                    app.Zero_ppm = app.Zero_ppm + prev_zero_ppm;
                end
                
                
                % Redefine limits for ppm units and set values within those
                % limits
                app.LeftNoiseLimit.Limits = [-(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select))-app.Zero_ppm (app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select))-app.Zero_ppm];
                app.RightNoiseLimit.Limits = [-(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select))-app.Zero_ppm (app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select))-app.Zero_ppm];
                
                app.LeftNoiseLimit.Value = -(app.BW(app.Acquisition_select)/2)/(app.F0(app.Acquisition_select))-app.Zero_ppm;
                app.RightNoiseLimit.Value = (-app.BW(app.Acquisition_select)/2+(app.BW(app.Acquisition_select)/4))/(app.F0(app.Acquisition_select))-app.Zero_ppm;
                
                app.PPM_axis = 2;
                Main_spectroscopy_processing(app);
                
                % Change the xlimits and xlimits range values
                if app.X_axis_state == 0 % Changing from Mhz to 0 ppm user-defined
                    low = ((app.XlimLowMHzEditField.Value*10^6)-(app.F0(app.Acquisition_select)*10^6))/(app.F0(app.Acquisition_select)) - app.Zero_ppm;
                    high = ((app.XlimHighMHzEditField.Value*10^6)-(app.F0(app.Acquisition_select)*10^6))/(app.F0(app.Acquisition_select)) - app.Zero_ppm;
                    xlim(app.UIAxes,[low high]);
                    app.XlimLowMHzEditField.Value = low;
                    app.XlimHighMHzEditField.Value = high;
                    
                elseif app.X_axis_state == 1 % Changing from 0 ppm centered to user-defined 0 ppm
                    low = app.XlimLowMHzEditField.Value - app.Zero_ppm;
                    high = app.XlimHighMHzEditField.Value - app.Zero_ppm;
                    xlim(app.UIAxes,[low high]);
                    app.XlimLowMHzEditField.Value = low;
                    app.XlimHighMHzEditField.Value = high;
                else
                end
                
                % Determine the type of plot for the rightmost graph based
                % on checkbox settings
                if app.ViewFIDCheckBox.Value == 0 && app.CascadePlotCheckBox.Value == 1
                    Waterfall(app,A)
                elseif app.ViewFIDCheckBox.Value == 0 && app.CascadePlotCheckBox.Value == 0
                    Waterfall(app)
                else
                    Waterfall(app,1,1)
                end
                
            else
            end
            app.X_axis_state = 2;
        end

        % Menu selected function: MatlabDataMenu
        function MatlabDataMenuSelected(app, event)
            [file,path] = uigetfile({'*.mat','Matlab Files';'*.txt','Text Files'},'File Selector','MultiSelect','on');
            
            if isequal(file,0) && isequal(path,0)
                errordlg('Please input a file name','Error');
                
            else  % File was actually chosen
                
                if ischar(file) == 1 % Single file has been selected
                    Num_channels = 1;
                else
                    [~,Num_channels] = size(file);
                end
                
                for P = 1:Num_channels
                    if Num_channels == 1
                        F = fullfile(path,file);
                    else
                        F = fullfile(path,file{P});
                    end
                    
                    app.Filepath(end+1,1) = string(F);
                    [~, fName, fExt] = fileparts(F);
                    
                    T = load(F); % Loaded file is expected to be saved with a struct named Starting (for raw data - not processed) with fields: Filename, Data (Channel number x Number of acquisitions x Number of points),F0,BW.
                    %  or Processed (for previously processed data exported from program)
                    % Referencing elements will require T.Starting.Fieldname or T.Processed.Fieldname
                    
                    if isfield(T,'Starting') == 1
                        F0 = T.Starting.F0/(10^6);  % Convert F0 to MHz.
                        BW = T.Starting.BW;
                        
                        % Find the number of channels (a) and number of acquisitions (b)
                        [a,b,~] = size(T.Starting.Data);
                        app.Channels = [app.Channels; a];
                        
                        list = {};
                        % Create a list dialog box with selections available from the number of available channels
                        str2 = sprintf('Select a channel for data viewing/processing from: %s%s',fName,fExt);
                        for i = 1:a
                            list{i,1} = strcat('Channel_',num2str(i));
                        end
                        [index,~] = listdlg('PromptString',{str2 ''},'ListSize',[300 300],'SelectionMode','single','ListString',list);
                        
                        if isempty(index) == 0  % Provided an option other than cancel was selected
                            app.Channel_selections = [app.Channel_selections; index];
                            Channel_select = index;
                            for i = 1:b
                                d = num2str(i);
                                Filename(i,1) = string(strcat(T.Starting.Filename,'_Ch_',num2str(Channel_select),'_','Acq',d));
                            end
                            prev_zeropad = 0;
                            
                            if b == 1  % if b == 1, squeeze needs transposing (5120 data_pts x 1 acq to 1 acq x 5120 data_pts)
                                time_domain = squeeze(T.Starting.Data(Channel_select,:,:)).';
                            else
                                time_domain = squeeze(T.Starting.Data(Channel_select,:,:));
                            end
                            
                            prev_filenames = [fName,fExt];
                            V = 1;
                            Load_processing(app,0,'',0,time_domain,BW,F0,prev_zeropad,Filename,prev_filenames)
                            if a > 1
                                app.BatchProcessMenu.Visible = 'on';
                            end
                        else
                            app.Filepath(end,:) = string([]);
                            errordlg('Load was canceled by user.')
                        end
                    elseif isfield(T,'Processed') == 1
                        Filename = string([]);
                        time_domain = [];
                        a0_initial = [];
                        a1_initial = [];
                        Filename(:,1) = (T.Processed.Filename);
                        time_domain(:,:) = T.Processed.Time_domain;
                        a0_initial(:,1) = T.Processed.a0;
                        a1_initial(:,1) = T.Processed.a1;
                        
                        % Assumes that the zero-padding, BW, and F0 will be the same between
                        % the loaded files
                        prev_zeropad = T.Processed.Zero_padding(1);
                        BW = T.Processed.BW_Hz(1);
                        F0 = T.Processed.F0_MHz(1);
                        
                        prev_filenames = [fName,fExt];
                        
                        Load_processing(app,0,'',0,time_domain,BW,F0,prev_zeropad,Filename,prev_filenames,a0_initial,a1_initial)
                        app.ChannelSelection.Editable = 'off';
                    else
                        errordlg('Improper Struct Name','File Error');
                    end
                end
                
                if isfield(T,'Starting') == 1 && isempty(index) == 0
                    % Change the limits of the channel selection option
                    if range(app.Channels) == 0 % check if the number of channels for each loaded file are the same
                        if a == 1
                            app.ChannelSelection.Limits = [1 1.1];
                        else
                            app.ChannelSelection.Limits = [1 a];
                        end
                        app.ChannelSelection.Value = index; % Set the channel selection option to the one that was selected
                    else % range(app.Channels) ~= 0  number of channels for each loaded file are different
                        app.ChannelSelection.Editable = 'off';
                        app.TextArea.Value = 'Channel Selection and the Subtraction Function have been disabled due to the loading of files with different channel counts.';
                        app.FileSubtractionMenu.Visible = 'off'; % Prevent issue with different number of channels and subtraction code
                    end
                end
                
            end
        end

        % Menu selected function: VarianDataMenu
        function VarianDataMenuSelected(app, event)
            file = uigetdir('C:\');
            path = 0;
            app.File_loc = file;
            if isequal(file,0) && isequal(path,0)
                errordlg('Please input a file name','Error');
                
            else  % File was actually chosen
                
                F = fullfile(path,file);
                [~, fName, fExt] = fileparts(F);
                
                if isequal(fExt,'.fid') == 1
                    
                    % Varian spectroscopy data is complex
                    [time_domain,params_fid] = loadfid_Spectroscopy(app);
                    time_domain = time_domain.';
                    
                    Filename = string(fName);
                    F0 = params_fid.sfrq;
                    BW = params_fid.sw;
                    prev_zeropad = 0;
                    app.TextArea.Value = 'Varian Data Loaded';
                    prev_filenames = [fName,fExt];
                    app.Channel_selections = [app.Channel_selections; 1];
                    Load_processing(app,0,'',0,time_domain,BW,F0,prev_zeropad,Filename,prev_filenames)
                    app.ChannelSelection.Editable = 'off';
                    
                else  % if a non-varian data type was selected
                end
            end
        end

        % Menu selected function: TextDataMenu
        function TextDataMenuSelected(app, event)
            
            [file,path] = uigetfile({'*.txt','Text Files'},'File Selector','MultiSelect','on');
            if isequal(file,0) && isequal(path,0)
                errordlg('Please input a file name','Error');
            else  % File was actually chosen
                
                if ischar(file) == 1 % Single file has been selected
                    Num_channels = 1;
                else
                    [~,Num_channels] = size(file);
                end
                
                for P = 1:Num_channels % Loops through the number of selected files
                    if Num_channels == 1
                        F = fullfile(path,file);
                    else
                        F = fullfile(path,file{P});
                    end
                    [~, fName, fExt] = fileparts(F);
                    if isequal(fExt,'.txt') == 1 % if .txt data type was selected
                        d1 = uiprogressdlg(app.UIFigure,'Title','Loading, please wait','Indeterminate','on');
                        drawnow
                        % A JMRUI Text file
                        Y = ImportjMRUI(app,F);
                        
                        % Grab the number of data sets from the table.
                        num_files = str2num(erase(Y{4,1},'DatasetsInFile: '));
                        
                        % Grab the length of each acquisition from the table.
                        data_length = str2num(erase(Y{3,1},'PointsInDataset: '));
                        
                        % Grab the bandwidth (Hz) from the table.
                        BW = round(1/(str2num(erase(Y{5,1},'SamplingInterval: '))/1000));
                        
                        % Grab the transmit frequency from the table.
                        F0 = str2num(erase(Y{8,1},'TransmitterFrequency: '))/10^6;
                        
                        Filename1 = string(fName);
                        for c = 1:num_files
                            e = num2str(c);
                            Filename(c,1) = string(strcat(Filename1,'_','Acq',e));
                        end
                        
                        start_pos = 19;
                        % Grab the data that is the time_domain data
                        assignin("base","data_length",data_length)
                        assignin("base","num_files",num_files)
                        time_domain = zeros(data_length,num_files);
                        assignin("base","Y",Y)
                        for i = 1:num_files
                            if i == 1
                                end1 = start_pos+data_length-1;
                                time_domain(:,i) = (str2double(Y(start_pos:end1,1))+1j*str2double(Y(start_pos:end1,2)));
                            else
                                end2 = end1+2+data_length-1;
                                assignin("base","end2",end2)
                                time_domain(:,i) = (str2double(Y(end1+2:end2,1))+1j*str2double(Y(end1+2:end2,2)));
                                end1 = end2;
                            end
                        end
                        
                        time_domain = time_domain.';
                        close(d1)
                        prev_zeropad = 0;
                        prev_filenames = [fName,fExt];
                        app.Channel_selections = [app.Channel_selections; 1];
                        Load_processing(app,0,'',0,time_domain,BW,F0,prev_zeropad,Filename,prev_filenames)
                        app.ChannelSelection.Editable = 'off';
                    else  % a non-text data type was selected
                    end
                end
            end
        end

        % Menu selected function: FileSubtractionMenu
        function FileSubtractionMenuSelected(app, event)
            app.TextArea.Value = '';
            app.Peak_degree.Value = 0;
            e1 = 0;
            if isempty(app.Time_domain) == 0 % Check to make sure files have been previously loaded
                selection = uiconfirm(app.UIFigure,'Using the subtraction function will change the raw data. Proceed by selecting a File type for performing the subtraction.','Confirm subtraction selection','Icon','question','Options',{'Matlab File','Varian File','Text File','Cancel'},'DefaultOption',1,'CancelOption',4);
                
                if strcmp(selection,'Cancel') == 1
                    return
                else
                    if strcmp(selection,'Matlab File') == 1
                        [file,path] = uigetfile({'*.mat','Matlab Files'},'File Selector','MultiSelect','on');
                        
                        if isequal(file,0) && isequal(path,0)
                            errordlg('Please input a file name','Error');
                            e1 = 1;
                        else  % File was actually chosen
                            if ischar(file) == 1 % Single file has been selected
                                Num_channels = 1;
                                F = fullfile(path,file);
                                app.Filepath_subtraction(end+1,1) = string(F);
                                [~, fName, fExt] = fileparts(app.Filepath_subtraction(1,1));
                            else
                                [~,Num_channels] = size(file);
                                for P = 1:Num_channels
                                    F = fullfile(path,file{P});
                                    app.Filepath_subtraction(end+1,1) = string(F);
                                end
                                F = fullfile(path,file{app.ChannelSelection.Value});
                                [~, fName, fExt] = fileparts(app.Filepath_subtraction(app.ChannelSelection.Value,1));
                                
                            end
                            
                            % A MAT file
                            T = load(F); % Loaded file is expected to be saved with a struct named S (for raw data - not processed) or Processed (for previously processed data)
                            
                            if isfield(T,'Starting') == 1
                                F0 = T.Starting.F0/(10^6);  % Convert F0 to MHz.
                                BW = T.Starting.BW; % Define the Bandwidth in Hz
                                
                                % Find the number of channels (a) and number of acquisitions (b)
                                [a,b,c] = size(T.Starting.Data);
                                
                                if b == 1 && a == app.Channels(1) || (a==1 && Num_channels == app.Channels(1)) && app.Columns1 ~= -1 && BW == app.BW_per_load(1) && F0 == app.F0_per_load(1) && c == app.Time_domain_column_list(1)
                                    
                                    if a == 1  % if b == 1, squeeze needs transposing (5120 data_pts x 1 acq to 1 acq x 5120 data_pts)
                                        time_domain = squeeze(T.Starting.Data(1,:,:)).';
                                    else
                                        time_domain = squeeze(T.Starting.Data(app.ChannelSelection.Value,1,:)).';
                                    end
                                    prev_filenames = strcat(fName,fExt);
                                    
                                    e1 = Process_for_subtraction(app,time_domain,BW,F0,prev_filenames);
                                else
                                    if b ~= 1
                                        errordlg('The subtraction file has multiple acquisitions. The subtraction function can work with multi-channel files but must only have a single acquisition per channel.');
                                    elseif a ~= app.Channels(1)
                                        errordlg('The subtraction file has a different number of channels than the previously loaded data. The subtraction function can work with multi-channel files as long as it matches the loaded files number of channels.');
                                    else
                                        errordlg('Incompatible file sizes, bandwidths, or different center frequencies are preventing the subtraction function from working.');
                                    end
                                    return
%                                     app.Filepath_subtraction = string([]);
                                end
                                
                            elseif isfield(T,'Processed') == 1
                                indicator = 1;
                                [a,~] = size(T.Processed.Filename);
                                if a == 1
                                    Filename(:,1) = string(T.Processed.Filename);
                                    time_domain(:,:) = T.Processed.Time_domain;
                                    
                                    % Assumes that the zero-padding, BW, and F0 will be the same between
                                    % the loaded files
                                    prev_zeropad = T.Processed.Zero_padding(1);
                                    BW = T.Processed.BW_Hz(1);
                                    F0 = T.Processed.F0_MHz(1);
                                    
                                    prev_filenames = strcat(fName,'-subtraction_file',fExt);
                                    e1 = Process_for_subtraction(app,time_domain,BW,F0,prev_filenames);
                                else
                                    errordlg('Subtraction requires that the subtraction file only contains a single acquisition.')
                                    return
                                end
                                
                            else
                                errordlg('Improper Struct Name','File Error');
                                return
                                
                            end
                        end
                        
                    elseif strcmp(selection,'Varian File') == 1
                        file = uigetdir('C:\');
                        path = 0;
                        app.File_loc = file;
                        if isequal(file,0) && isequal(path,0)
                            errordlg('Please input a file name','Error');
                            return
                            
                        else  % File was actually chosen. % Varian loading currently is limited to a single file anyways due to uigetdir. Future version will require checking for a single file with error codes.
                            
                            F = fullfile(path,file);
                            [~, fName, fExt] = fileparts(F);
                            if isequal(fExt,'.fid') == 1
                                
                                % Varian spectroscopy data is complex
                                [time_domain,params_fid] = loadfid_Spectroscopy(app);
                                time_domain = time_domain.';
                                
                                % This solution is for if we need to take half of the data.
                                %  time_domain = time_domain((length(time_domain)/2):end);
                                
                                Filename = string(fName);
                                F0 = params_fid.sfrq;
                                BW = params_fid.sw;
                                app.TextArea.Value = 'Varian Data Loaded';
                                prev_filenames = [fName,'-subtraction_file',fExt];
                                e1 = Process_for_subtraction(app,time_domain,BW,F0,prev_filenames);
                            else  % if a non-varian data type was selected
                                errordlg('A non-Varian data type was selected')
                                return
                                
                            end
                        end
                    else %selection == 'Text File'
                        [file,path] = uigetfile({'*.txt','jMRUI TextFile Format'},'File Selector','Select a .txt file');
                        if isequal(file,0) && isequal(path,0)
                            errordlg('Please input a file name','Error');
                            return
                        else  % File was actually chosen
                            
                            F = fullfile(path,file);
                            
                            [~, fName, fExt] = fileparts(F);
                            if isequal(fExt,'.txt') == 1 % if .txt data type was selected
                                % A JMRUI Text file
                                Y = readtable(F);
                                % Grab the data that is the time_domain data
                                time_domain = ((Y{4:end,1})+1i*Y{4:end,2}).';
                                % Grab the transmit frequency from the table.
                                F0 = Y{3,2}/10^6;
                                Filename = string(fName);
                                prev_zeropad = 0;
                                BW = round(1/(Y{2,2}/1000));
                                prev_filenames = [fName,'-subtraction_file',fExt];
                                e1 = Process_for_subtraction(app,time_domain,BW,F0,prev_filenames);
                            else  % a non-text data type was selected
                                
                            end
                        end
                    end
                    
                end
                
                if e1 == 0
                    % Process all of the files
                    for i = 1:sum(app.Rows_per_load)-app.NumberofAverages.Value+1
                        Main_spectroscopy_processing(app,i);
                    end
                    Main_spectroscopy_processing(app);
                    
                    % Plot the Cascade plot based on front panel settings
                    if app.CascadePlotCheckBox.Value == 1
                        % Plot with the cascade style plot
                        Waterfall(app,sum(app.Rows_per_load)-app.NumberofAverages.Value+1)
                        
                        pause(0.1);
                        app.ViewFIDCheckBox.Value = 0;
                    else
                        app.ZeroAllPhasesCheckBox.Value = 0;
                        Waterfall(app)
                        app.CascadePlotCheckBox.Value = 0;
                    end
                    app.LoadFilesMenu.Visible = 'off';
                    app.FileSubtractionMenu.Visible = 'off';
                    app.Peak_degree.Value = 0;
                    app.NumberofAverages.Editable = 'off';
                    
                else
                    return
                end
                
            else
                app.TextArea.Value = 'Please load a file before using the subtraction function.';
            end
        end

        % Value changed function: ChannelSelection
        function ChannelSelectionValueChanged(app, event)
            
            if isempty(app.Time_domain) == 0
                selection = uiconfirm(app.UIFigure,'Selecting a different channel for viewing will remove any previously processed data. It is recommended that you export any previously processed data before proceeding. Proceed?','Confirm  channel selection change','Icon','question','Options',{'Proceed','Cancel'},'DefaultOption',1,'CancelOption',2);
                
                if strcmp(selection,'Proceed') == 1
                    Channel_selection_processing(app)
                else
                    % Reset Channel_selection to previous value
                    app.ChannelSelection.Value = app.Previous_Channel_select;
                end
            else
                app.ChannelSelection.Value = 1;
                app.TextArea.Value = 'Please load data before adjusting the Channel Selection variable.';
            end
            
        end

        % Menu selected function: MultichannelCombinationMenu
        function MultichannelCombinationMenuSelected(app, event)
            app.TextArea.Value = '';
            selection = uiconfirm(app.UIFigure,'Using the Multi-channel Combination option will erase any currently loaded data. Proceed by selecting a File type for performing the multi-channel combination.',...
                'Confirm Multi-channel Combination selections','Icon','question','Options',{'Matlab File','Cancel'},'DefaultOption',1,'CancelOption',2);
            
            Decision_Process_for_MultiChannelCombination1(app,event,selection)
        end

        % Menu selected function: CombinationTypeChangeMenu
        function CombinationTypeChangeMenuSelected(app, event)
            app.TextArea.Value = '';
            selection = uiconfirm(app.UIFigure,'Changing the combination type will erase any currently loaded settings but will reprocess the currently loaded data according to the new setting. Click "Proceed" to continue.',...
                'Confirm Multi-channel Combination selections','Icon','question','Options',{'Proceed','Cancel'},'DefaultOption',1,'CancelOption',2);
            
            Decision_Process_for_MultiChannelCombination1(app,event,selection,1)
        end

        % Menu selected function: BatchProcessMenu
        function BatchProcessMenuSelected(app, event)
            % Batch processing allows the automatic pre-processing and exportation of individual channel data. It can then be
            % reloaded using the "Multi-channel combination" part of the Load Files menu
            
            selection = uiconfirm(app.UIFigure,'Before proceeding with batch processing of your channel data, please ensure Preprocessing parameters, such as Number of Averages, Line Broadening, Zero-padding Factor, and/or manual/automatic phasing, as well as Spectral Evaluation parameters (Peak selection for SNR/Linewidth and noise region limits) have been set. Any mathematical operations should also be preset. Click "Proceed" to continue or cancel to evaluate parameters before batch processing',...
                'Confirm Multi-channel Combination selections','Icon','question','Options',{'Proceed','Cancel'},'DefaultOption',1,'CancelOption',2);
            if strcmp(selection,'Proceed')
                % Proceed with batch processing
                
                selection1 = uiconfirm(app.UIFigure,'Do you wish to save data as the .mat structure "Processed" or a .txt jMRUI Textfile?',...
                'Confirm Multi-channel Combination selections','Icon','question','Options',{'Processed','jMRUI Textfile'},'DefaultOption',1,'CancelOption',2);

                if strcmp(selection1,'Processed')
                    [file,path] = uiputfile('*.mat','Select output file name and location'); 
                    base_name = erase(file,'.mat');
                else 
                    [file,path] = uiputfile('*.txt','Select output file name and location');
                    [~,f,g] = fileparts(file);
                end

                    d = uiprogressdlg(app.UIFigure,'Title','Processing, please wait','Indeterminate','on');
                    drawnow
                    x = min(app.Channels);
                    file_comb = strings([x,1]);
                                       
                    for i = 1:x
                        Channel_selection_processing(app,i)
                        if strcmp(selection1,'Processed')
                            base_name_ch = strcat(base_name,'_Channel_',num2str(app.ChannelSelection.Value),'.mat');
                            Export_processing(app,base_name_ch,path)
                        else 
%                             if i == 1
%                                     f1 = strcat(f,g);
%                                 else
%                                     f(end) = num2str(i);
%                                     f1 = strcat(f,g);
%                                 end
                                ExportJMRUI_for_batch(app,file,path)
                        end
                    end
                    
            else % Cancel Batch processing
                return
            end
        end

        % Button pushed function: ExportNoiseCovarianceMatrixWSVDButton
        function ExportNoiseCovarianceMatrixWSVDButtonPushed(app, event)
            if isempty(app.Time_domain) == 0
                selection0 = uiconfirm(app.UIFigure,'The noise covariance matrix will be exported for the selected acquisition for all channels for later use with WSVD method. Do you wish to proceed with the export?',...
                    'Confirm Multi-channel Combination selections','Icon','question','Options',{'Yes','No'},'DefaultOption',1);

                if strcmp(selection0,'Yes') == 1 % Continue with noise covariance export for WSVD reloading later

                    x = min(app.Channels);
                    [a,b] = size(app.Spectrum);

                    [bounds] = Two_X_axis_inputs1(app,app.Faxis);

                    for i = 1:x
                        Channel_selection_processing(app,i)
                        Spectrum_selected_acq(i,:) = app.Spectrum(app.Acquisition_select,bounds);
                    end
                    Covariance = cov(Spectrum_selected_acq.');


                    filter = {'*.mat';'*.txt'};
                    [file, path] = uiputfile(filter);
                    complete_file = fullfile(path,file);
                    if file ~= 0
                        [filepath,name,ext] = fileparts(file);

                        if strcmp(ext,'.mat')
                            save(complete_file,"Covariance")

                        elseif strcmp(ext,'.txt')
                            T = table(Covariance);
                            writetable(T,complete_file)


                        else % User didn't select a file type

                        end
                    else
                        errordlg('A file type was not loaded and so no noise covariance matrix was exported.')
                        return
                    end
                else % No export

                end
            else
                errordlg('Please load data before trying to export a noise covariance matrix.')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadFilesMenu = uimenu(app.UIFigure);
            app.LoadFilesMenu.Text = 'Load Files';
            app.PreprocessingDataMenu = uimenu(app.LoadFilesMenu);
            app.PreprocessingDataMenu.Text = 'Preprocessing Data';
            app.MatlabDataMenu = uimenu(app.PreprocessingDataMenu);
            app.MatlabDataMenu.MenuSelectedFcn = createCallbackFcn(app, @MatlabDataMenuSelected, true);
            app.MatlabDataMenu.Text = 'Matlab Data';
            app.VarianDataMenu = uimenu(app.PreprocessingDataMenu);
            app.VarianDataMenu.MenuSelectedFcn = createCallbackFcn(app, @VarianDataMenuSelected, true);
            app.VarianDataMenu.Text = 'Varian Data';
            app.TextDataMenu = uimenu(app.PreprocessingDataMenu);
            app.TextDataMenu.MenuSelectedFcn = createCallbackFcn(app, @TextDataMenuSelected, true);
            app.TextDataMenu.Text = 'Text Data';
            app.MultichannelCombinationMenu = uimenu(app.LoadFilesMenu);
            app.MultichannelCombinationMenu.MenuSelectedFcn = createCallbackFcn(app, @MultichannelCombinationMenuSelected, true);
            app.MultichannelCombinationMenu.Text = 'Multi-channel Combination';
            app.XAxisControlsMenu = uimenu(app.UIFigure);
            app.XAxisControlsMenu.Text = 'X-Axis Controls';
            app.XAxisinMHzMenu = uimenu(app.XAxisControlsMenu);
            app.XAxisinMHzMenu.MenuSelectedFcn = createCallbackFcn(app, @XAxisinMHzMenuSelected, true);
            app.XAxisinMHzMenu.Text = 'X-Axis in MHz';
            app.XAxisinppmMenu = uimenu(app.XAxisControlsMenu);
            app.XAxisinppmMenu.Text = 'X-Axis in ppm';
            app.Userdefined0ppmMenu = uimenu(app.XAxisinppmMenu);
            app.Userdefined0ppmMenu.MenuSelectedFcn = createCallbackFcn(app, @Userdefined0ppmMenuSelected, true);
            app.Userdefined0ppmMenu.Text = 'User-defined 0 ppm';
            app.ppmcenteredMenu = uimenu(app.XAxisinppmMenu);
            app.ppmcenteredMenu.MenuSelectedFcn = createCallbackFcn(app, @ppmcenteredMenuSelected, true);
            app.ppmcenteredMenu.Text = '0 ppm centered';
            app.MathematicalOperationsMenu = uimenu(app.UIFigure);
            app.MathematicalOperationsMenu.Text = 'Mathematical Operations';
            app.FileSubtractionMenu = uimenu(app.MathematicalOperationsMenu);
            app.FileSubtractionMenu.MenuSelectedFcn = createCallbackFcn(app, @FileSubtractionMenuSelected, true);
            app.FileSubtractionMenu.Text = 'File Subtraction';
            app.CombinationTypeChangeMenu = uimenu(app.UIFigure);
            app.CombinationTypeChangeMenu.MenuSelectedFcn = createCallbackFcn(app, @CombinationTypeChangeMenuSelected, true);
            app.CombinationTypeChangeMenu.Text = 'Combination Type Change';
            app.BatchProcessMenu = uimenu(app.UIFigure);
            app.BatchProcessMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchProcessMenuSelected, true);
            app.BatchProcessMenu.Text = 'Batch Process';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SpectroscopyTab = uitab(app.TabGroup);
            app.SpectroscopyTab.Title = 'Spectroscopy';
            app.UIAxes = uiaxes(app.SpectroscopyTab);
            title(app.UIAxes, 'Spectrum')
            xlabel(app.UIAxes, 'Frequency (MHz)')
            ylabel(app.UIAxes, 'Spectral Intensity (A.u.)')
            app.UIAxes2 = uiaxes(app.SpectroscopyTab);
            title(app.UIAxes2, 'Spectra')
            xlabel(app.UIAxes2, 'Frequency (MHz)')
            ylabel(app.UIAxes2, 'Spectral Intensity (A.u)')
            app.PreprocessingPanel = uipanel(app.SpectroscopyTab);
            app.PreprocessingPanel.Title = 'Preprocessing';
            app.ControlsAllLoadedSpectraPanel = uipanel(app.PreprocessingPanel);
            app.ControlsAllLoadedSpectraPanel.Title = 'Controls All Loaded Spectra';
            app.NumberofAveragesLabel = uilabel(app.ControlsAllLoadedSpectraPanel);
            app.NumberofAveragesLabel.Text = 'Number of Averages:';
            app.NumberofAverages = uieditfield(app.ControlsAllLoadedSpectraPanel, 'numeric');
            app.NumberofAverages.Limits = [1 Inf];
            app.NumberofAverages.RoundFractionalValues = 'on';
            app.NumberofAverages.ValueChangedFcn = createCallbackFcn(app, @NumberofAveragesValueChanged, true);
            app.NumberofAverages.Tooltip = {'This function applies a sliding window average of user selected length to the loaded data.'; ' '};
            app.NumberofAverages.Value = 1;
            app.LineBroadeningHzEditFieldLabel = uilabel(app.ControlsAllLoadedSpectraPanel);
            app.LineBroadeningHzEditFieldLabel.Text = 'Line Broadening (Hz):';
            app.LineBroadeningHz = uieditfield(app.ControlsAllLoadedSpectraPanel, 'numeric');
            app.LineBroadeningHz.Limits = [0 Inf];
            app.LineBroadeningHz.ValueChangedFcn = createCallbackFcn(app, @LineBroadeningHzValueChanged, true);
            app.LineBroadeningHz.Tooltip = {'Applies a Lorentzian line broadening of user-defined Hz.'};
            app.ZeropaddingFactorEditFieldLabel = uilabel(app.ControlsAllLoadedSpectraPanel);
            app.ZeropaddingFactorEditFieldLabel.Text = 'Zero-padding Factor: ';
            app.ZeropaddingFactor = uieditfield(app.ControlsAllLoadedSpectraPanel, 'numeric');
            app.ZeropaddingFactor.Limits = [0 Inf];
            app.ZeropaddingFactor.RoundFractionalValues = 'on';
            app.ZeropaddingFactor.ValueChangedFcn = createCallbackFcn(app, @ZeropaddingFactorValueChanged, true);
            app.ZeropaddingFactor.Tooltip = {'The zero-padding factor setting adds zeros to the time domain data according to the user-defined factor (e.g. zero-padding factor = 1 will double the total time domain vector with half of the vector consisting of 0''s. '};
            app.ZeroAllPhasesCheckBox = uicheckbox(app.ControlsAllLoadedSpectraPanel);
            app.ZeroAllPhasesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZeroAllPhasesCheckBoxValueChanged, true);
            app.ZeroAllPhasesCheckBox.Tooltip = {'The 0-order and 1st-order phases will all be set to 0 degrees.'};
            app.ZeroAllPhasesCheckBox.Text = 'Zero All Phases';
            app.SetPhasingforAllCheckBox = uicheckbox(app.ControlsAllLoadedSpectraPanel);
            app.SetPhasingforAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @SetPhasingforAllCheckBoxValueChanged, true);
            app.SetPhasingforAllCheckBox.Tooltip = {'0-order and 1st-order phases for all acquisitions will be set to the current visible phasing settings. '};
            app.SetPhasingforAllCheckBox.Text = 'Set Phasing for All';
            app.BaselineCorrectionCheckBox = uicheckbox(app.ControlsAllLoadedSpectraPanel);
            app.BaselineCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselineCorrectionCheckBoxValueChanged, true);
            app.BaselineCorrectionCheckBox.Tooltip = {'Utilization of the baseline correction function should be used sparingly, as this usually indicates poor experimental settings. '};
            app.BaselineCorrectionCheckBox.Text = 'Baseline Correction';
            app.CascadePlotCheckBox = uicheckbox(app.ControlsAllLoadedSpectraPanel);
            app.CascadePlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @CascadePlotCheckBoxValueChanged, true);
            app.CascadePlotCheckBox.Tooltip = {'When checked, the right graph will show a cascade plot of the loaded acquisitions. Note: processing will be faster with this unchecked. '};
            app.CascadePlotCheckBox.Text = 'Cascade Plot';
            app.OnlyControlsOneSpectraPanel = uipanel(app.PreprocessingPanel);
            app.OnlyControlsOneSpectraPanel.Title = 'Only Controls One Spectra';
            app.Automatic0OrderPhaseCheckBox = uicheckbox(app.OnlyControlsOneSpectraPanel);
            app.Automatic0OrderPhaseCheckBox.ValueChangedFcn = createCallbackFcn(app, @Automatic0OrderPhaseCheckBoxValueChanged, true);
            app.Automatic0OrderPhaseCheckBox.Tooltip = {'When checked, an automatic phasing routine will be applied to the current acquisition. To apply automatic phasing to all acquisitions, additionally click the "Cascade Plot" checkbox.'};
            app.Automatic0OrderPhaseCheckBox.Text = 'Automatic Phasing';
            app.ViewFIDCheckBox = uicheckbox(app.OnlyControlsOneSpectraPanel);
            app.ViewFIDCheckBox.ValueChangedFcn = createCallbackFcn(app, @ViewFIDCheckBoxValueChanged, true);
            app.ViewFIDCheckBox.Tooltip = {'When checked, the right graph will show the Free Induction Decay (FID) over time. '};
            app.ViewFIDCheckBox.Text = 'View FID';
            app.Slider_1 = uislider(app.PreprocessingPanel);
            app.Slider_1.Limits = [1 100];
            app.Slider_1.ValueChangedFcn = createCallbackFcn(app, @Slider_1ValueChanged, true);
            app.Slider_1.MinorTicks = [];
            app.Slider_1.Tooltip = {'Allows viewing of different acquisitions by moving the slider.'};
            app.Slider_1.Value = 1;
            app.SlideValueText = uilabel(app.PreprocessingPanel);
            app.SlideValueText.Text = 'Acquisition Value';
            app.AcquisitionSelectionLabel = uilabel(app.PreprocessingPanel);
            app.AcquisitionSelectionLabel.Text = 'Acquisition Selection';
            app.OrderValuedegLabel = uilabel(app.PreprocessingPanel);
            app.OrderValuedegLabel.Text = '0-Order Value (deg)';
            app.Slider_2 = uislider(app.PreprocessingPanel);
            app.Slider_2.Limits = [-180 180];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.Tooltip = {'Allows manual 0-order phasing by moving the slider.'};
            app.stOrderValuedegLabel = uilabel(app.PreprocessingPanel);
            app.stOrderValuedegLabel.Text = '1st-Order Value (deg)';
            app.Slider_3 = uislider(app.PreprocessingPanel);
            app.Slider_3.Limits = [-2000 2000];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Slider_3.Tooltip = {'Allows manual 1st-order phasing by changing the slider.'};
            app.Fig_slider_value = uieditfield(app.PreprocessingPanel, 'numeric');
            app.Fig_slider_value.Limits = [1 Inf];
            app.Fig_slider_value.RoundFractionalValues = 'on';
            app.Fig_slider_value.ValueChangedFcn = createCallbackFcn(app, @Fig_slider_valueValueChanged, true);
            app.Fig_slider_value.Tooltip = {'Allows viewing of different acquisitions by selecting an acquisition number.'};
            app.Fig_slider_value.Value = 1;
            app.Zero_order_slider_value = uieditfield(app.PreprocessingPanel, 'numeric');
            app.Zero_order_slider_value.Limits = [-180 180];
            app.Zero_order_slider_value.ValueChangedFcn = createCallbackFcn(app, @Zero_order_slider_valueValueChanged, true);
            app.Zero_order_slider_value.Tooltip = {'Allows manual 0-order phasing by changing the value.'};
            app.First_order_slider_value = uieditfield(app.PreprocessingPanel, 'numeric');
            app.First_order_slider_value.Limits = [-2000 2000];
            app.First_order_slider_value.ValueChangedFcn = createCallbackFcn(app, @First_order_slider_valueValueChanged, true);
            app.First_order_slider_value.Tooltip = {'Allows manual 1st-order phasing by changing the value.'};
            app.TextArea = uitextarea(app.PreprocessingPanel);
            app.TextArea.Editable = 'off';
            app.ChannelSelectionEditFieldLabel = uilabel(app.PreprocessingPanel);
            app.ChannelSelectionEditFieldLabel.Text = 'Channel Selection';
            app.ChannelSelection = uieditfield(app.PreprocessingPanel, 'numeric');
            app.ChannelSelection.Limits = [1 1.1];
            app.ChannelSelection.RoundFractionalValues = 'on';
            app.ChannelSelection.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionValueChanged, true);
            app.ChannelSelection.Tooltip = {'Allows changes between different channels for multi-channel data. '};
            app.ChannelSelection.Value = 1;
            app.SpectralEvaluationPanel = uipanel(app.SpectroscopyTab);
            app.SpectralEvaluationPanel.Title = 'Spectral Evaluation';
            app.PeakphaseselectionButton = uibutton(app.SpectralEvaluationPanel, 'push');
            app.PeakphaseselectionButton.ButtonPushedFcn = createCallbackFcn(app, @PeakphaseselectionButtonPushed, true);
            app.PeakphaseselectionButton.Tooltip = {'Allows for a region around a peak to be selected (leftmost graph) for viewing of the phase at the peak.'};
            app.PeakphaseselectionButton.Text = 'Peak phase selection';
            app.Check_Linewidth_checkbox = uicheckbox(app.SpectralEvaluationPanel);
            app.Check_Linewidth_checkbox.ValueChangedFcn = createCallbackFcn(app, @Check_Linewidth_checkboxValueChanged, true);
            app.Check_Linewidth_checkbox.Tooltip = {'Allows a region to be selected around a peak (on leftmost graph) for linewidth and peak signal (SNR) measurements.'};
            app.Check_Linewidth_checkbox.Text = 'Select Peak for Linewidth, SNR measurement';
            app.LinewidthHzLabel = uilabel(app.SpectralEvaluationPanel);
            app.LinewidthHzLabel.Text = 'Linewidth (Hz): ';
            app.LinewidthHz_peak = uieditfield(app.SpectralEvaluationPanel, 'numeric');
            app.LinewidthHz_peak.Editable = 'off';
            app.LinewidthHz_peak.Tooltip = {'Displays the linewidth of the maximum peak in the spectra or peak selected using the "Select Peak for Linewidth, SNR measurement" checkbox. '};
            app.Peak_degree = uieditfield(app.SpectralEvaluationPanel, 'numeric');
            app.Peak_degree.Editable = 'off';
            app.NoiseRegionSelectionSNRPanel = uipanel(app.SpectralEvaluationPanel);
            app.NoiseRegionSelectionSNRPanel.Title = 'Noise Region Selection & SNR';
            app.LowFrequencyNoiseLimitMHzLabel = uilabel(app.NoiseRegionSelectionSNRPanel);
            app.LowFrequencyNoiseLimitMHzLabel.Tag = 'name1';
            app.LowFrequencyNoiseLimitMHzLabel.Text = 'Low Frequency Noise Limit (MHz):';
            app.LeftNoiseLimit = uieditfield(app.NoiseRegionSelectionSNRPanel, 'numeric');
            app.LeftNoiseLimit.Limits = [1 Inf];
            app.LeftNoiseLimit.ValueDisplayFormat = '%4.4f';
            app.LeftNoiseLimit.ValueChangedFcn = createCallbackFcn(app, @LeftNoiseLimitValueChanged, true);
            app.LeftNoiseLimit.Tooltip = {'Sets the low frequency limit for the noise region used in the signal-to-noise ratio calculation. '};
            app.LeftNoiseLimit.Value = 1;
            app.HighFrequencyNoiseLimitMHzLabel = uilabel(app.NoiseRegionSelectionSNRPanel);
            app.HighFrequencyNoiseLimitMHzLabel.Text = 'High Frequency Noise Limit (MHz):';
            app.RightNoiseLimit = uieditfield(app.NoiseRegionSelectionSNRPanel, 'numeric');
            app.RightNoiseLimit.Limits = [2 Inf];
            app.RightNoiseLimit.ValueDisplayFormat = '%4.4f';
            app.RightNoiseLimit.ValueChangedFcn = createCallbackFcn(app, @RightNoiseLimitValueChanged, true);
            app.RightNoiseLimit.Tooltip = {'Sets the high frequency limit for the noise region used in the signal-to-noise ratio'};
            app.RightNoiseLimit.Value = 2;
            app.SignaltoNoiseRatioEditFieldLabel = uilabel(app.NoiseRegionSelectionSNRPanel);
            app.SignaltoNoiseRatioEditFieldLabel.Text = 'Signal-to-Noise Ratio';
            app.SignaltoNoiseRatio = uieditfield(app.NoiseRegionSelectionSNRPanel, 'numeric');
            app.SignaltoNoiseRatio.Editable = 'off';
            app.SignaltoNoiseRatio.Tooltip = {'Displays the signal-to-noise ratio as determined by the maximum peak in the spectra or peak selected by the user divided by standard deviation of the user-defined noise region.'};
            app.SignalLabel = uilabel(app.NoiseRegionSelectionSNRPanel);
            app.SignalLabel.Text = 'Signal';
            app.Signal_Display = uieditfield(app.NoiseRegionSelectionSNRPanel, 'numeric');
            app.Signal_Display.Editable = 'off';
            app.Signal_Display.Tooltip = {'Displays the calculated signal level.'};
            app.NoiseLabel = uilabel(app.NoiseRegionSelectionSNRPanel);
            app.NoiseLabel.Text = 'Noise';
            app.Noise_Display = uieditfield(app.NoiseRegionSelectionSNRPanel, 'numeric');
            app.Noise_Display.Editable = 'off';
            app.Noise_Display.Tooltip = {'Displays the calculated noise level in the user-defined noise region.'};
            app.XlimLowMHzEditFieldLabel = uilabel(app.SpectralEvaluationPanel);
            app.XlimLowMHzEditFieldLabel.Text = 'Xlim Low (MHz)';
            app.XlimLowMHzEditField = uieditfield(app.SpectralEvaluationPanel, 'numeric');
            app.XlimLowMHzEditField.ValueDisplayFormat = '%11.6g';
            app.XlimLowMHzEditField.ValueChangedFcn = createCallbackFcn(app, @XlimLowMHzEditFieldValueChanged, true);
            app.XlimLowMHzEditField.Tooltip = {'Sets the low frequency x-limit on both plots. '};
            app.XlimHighMHzEditFieldLabel = uilabel(app.SpectralEvaluationPanel);
            app.XlimHighMHzEditFieldLabel.Text = 'Xlim High (MHz)';
            app.XlimHighMHzEditField = uieditfield(app.SpectralEvaluationPanel, 'numeric');
            app.XlimHighMHzEditField.ValueDisplayFormat = '%11.6g';
            app.XlimHighMHzEditField.ValueChangedFcn = createCallbackFcn(app, @XlimHighMHzEditFieldValueChanged, true);
            app.XlimHighMHzEditField.Tooltip = {'Sets the high frequency x-limit on both plots. '};
            app.XlimHighMHzEditField.Value = 1;
            app.ExportClearDataPanel = uipanel(app.SpectroscopyTab);
            app.ExportClearDataPanel.Title = 'Export & Clear Data';
            app.ExportDataButton = uibutton(app.ExportClearDataPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Tooltip = {'Exports data in the form of a .mat structure for future viewing/pre-processing or channel combination.'};
            app.ExportDataButton.Text = 'Export Matlab Data';
            app.ClearDataButton = uibutton(app.ExportClearDataPanel, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Tooltip = {'Clears all data and resets the program to its starting state. '};
            app.ClearDataButton.Text = 'Clear Data';
            app.ExportjMRUIDataButton = uibutton(app.ExportClearDataPanel, 'push');
            app.ExportjMRUIDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportjMRUIDataButtonPushed, true);
            app.ExportjMRUIDataButton.Tooltip = {'Outputs the loaded data in a .txt format for quantification with jMRUI.'};
            app.ExportjMRUIDataButton.Text = 'Export jMRUI Data';
            app.ExportNoiseCovarianceMatrixWSVDButton = uibutton(app.ExportClearDataPanel, 'push');
            app.ExportNoiseCovarianceMatrixWSVDButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNoiseCovarianceMatrixWSVDButtonPushed, true);
            app.ExportNoiseCovarianceMatrixWSVDButton.Tooltip = {'Exports the noise covariance matrix for the selected acquisition for use with the WSVD method. '};
            app.ExportNoiseCovarianceMatrixWSVDButton.Text = 'Export Noise Covariance Matrix - WSVD';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = McMRSGUI_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end